classdef CreateModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModelingUIFigure          matlab.ui.Figure
        Tree                      matlab.ui.container.Tree
        SphereNode                matlab.ui.container.TreeNode
        CubeNode                  matlab.ui.container.TreeNode
        CylinderNode              matlab.ui.container.TreeNode
        IrregularNode             matlab.ui.container.TreeNode
        Panel_3                   matlab.ui.container.Panel
        CylinderLabel             matlab.ui.control.Label
        CubeLabel                 matlab.ui.control.Label
        SphereLabel               matlab.ui.control.Label
        IrregularLabel            matlab.ui.control.Label
        AddIrregularButton        matlab.ui.control.Button
        AddCylinerButton          matlab.ui.control.Button
        AddCubeButton             matlab.ui.control.Button
        AddSphereButton           matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        AddaModelLabel            matlab.ui.control.Label
        SettingButton             matlab.ui.control.Button
        ForwardcalculationButton  matlab.ui.control.Button
        ViewButton                matlab.ui.control.Button
        OutputDropDown            matlab.ui.control.DropDown
        OutputButton              matlab.ui.control.Button
        ViewDropDown              matlab.ui.control.DropDown
        UIAxes                    matlab.ui.control.UIAxes
        ContextMenu               matlab.ui.container.ContextMenu
        ModifyMenu                matlab.ui.container.Menu
        DeleteMenu                matlab.ui.container.Menu
    end


    properties (Access = private)
        dialogapp;
        ScopeData=[0 100 0 100 0 100 1 1 1 1];%初始建模范围
        Nx;
        Ny;
        Nz;
        operation=0;

        %模型数据
        SphereNum=0;
        CubeNum=0;
        IrregularNum=0;
        CylinderNum=0;
        SphereModel;
        IrregularModel;
        CubeModel;
        CylinderModel;
        AllModelPositon=[];
        AllModelPositon2=[];
    end

    methods (Access = public)

        function updata_Scope(app,data)
            %更新建模区域
            %用于介绍Source-Setting 弹窗传来的建模区域数据 data

            %更新坐标区
            app.ScopeData=data;
            app.Nx=(app.ScopeData(1,2)-app.ScopeData(1,1))/app.ScopeData(1,7);
            app.Ny=(app.ScopeData(1,4)-app.ScopeData(1,3))/app.ScopeData(1,8);
            app.Nz=(app.ScopeData(1,6)-app.ScopeData(1,5))/app.ScopeData(1,9);
            %XYZ轴范围
            xlim(app.UIAxes,app.ScopeData(1,1:2));
            ylim(app.UIAxes,app.ScopeData(1,3:4));
            zlim(app.UIAxes,app.ScopeData(1,5:6));
            %设置网格
            xt=app.ScopeData(1,1):10:app.ScopeData(1,2);
            yt=app.ScopeData(1,3):10:app.ScopeData(1,4);
            zt=app.ScopeData(1,5):10:app.ScopeData(1,6);
            xticks(app.UIAxes,xt);
            yticks(app.UIAxes,yt);
            zticks(app.UIAxes,zt);
            %设置坐标轴
            if data(1,10)==0
                xlabel(app.UIAxes,'X/m  (North)')
                ylabel(app.UIAxes,'Y/m  (East)')
                zlabel(app.UIAxes,'Depth/m')
            elseif data(1,10)==1
                xlabel(app.UIAxes,'X/km  (North)')
                ylabel(app.UIAxes,'Y/km  (East)')
                zlabel(app.UIAxes,'Depth/km')
            elseif data(1,10)==2
                xlabel(app.UIAxes,'X/hm  (North)')
                ylabel(app.UIAxes,'Y/hm  (East)')
                zlabel(app.UIAxes,'Depth/hm')
            end
            set(app.UIAxes,'ZDir','reverse')
            view(app.UIAxes,3)
            grid(app.UIAxes,3)
            app.operation=0;
            %绘制模型
            DrawModel(app);
        end

        function updata_ModelInf(app,Model)
            %更新模型数据
            %用于接收AddCube\AddSphere\AddCylinder\LayerBuilding 弹窗返回的模型数据Data
            %返回值为模型结构体：
            %Model.State=1,新建了一个模型
            %Model.State=3,修改了一个模型
            %Model.State=2时，表示模型处于创建完成状态，
            %且用户取消了修改
            %Model.State=-1时，模型已被删除
            %Model.State=0时，模型未创建成功
            switch Model.Tag
                case 0
                    %新建球体模型
                    if Model.State==1
                        app.SphereNum=Model.Number;
                        %模型创建完成
                        Model.State=2;
                        app.SphereModel(app.SphereNum)=Model;
                        %绘制球体模型
                        [xs,ys,zs]=Plot_Sphere(Model.Position(1,1),Model.Position(1,2),Model.Position(1,3),Model.Radius(1,1));
                        surf(app.UIAxes,xs,ys,zs,"facecolor",Model.Color)
                        hold(app.UIAxes,"on");
                        %在模型列表中新建模型节点
                        uitreenode(app.SphereNode,"Text",Model.Text,"NodeData",app.SphereNum,"Tag",'Sphere',"ContextMenu",app.ContextMenu);
                    elseif Model.State==3
                        %State=3,修改模式
                        %完成了修改
                        Model.State=2;
                        %更新数据
                        app.SphereModel(Model.Number)=Model;
                        %模型进行了修改，需要重新绘制模型
                        DrawModel(app);
                    end
                case 1
                    %新建立方体模型
                    if Model.State==1
                        app.CubeNum=Model.Number;
                        Model.State=2;
                        app.CubeModel(app.CubeNum)=Model;
                        %根据立方体的八个角点绘制立方体模型
                        fixed=[Model.Position(1,:);Model.Position(2,:);Model.Position(3,:);Model.Position(4,:);Model.Position(5,:);Model.Position(6,:);Model.Position(7,:);Model.Position(8,:)];
                        facet=[1 2 4 3;1 3 7 5;1 2 6 5;2 4 8 6;3 4 8 7;5 6 8 7;];
                        patch(app.UIAxes,'Faces',facet,"Vertices",fixed,'facecolor',Model.Color);
                        hold(app.UIAxes,"on");
                        uitreenode(app.CubeNode,"Text",Model.Text,"NodeData",app.CubeNum,"Tag",'Cube',"ContextMenu",app.ContextMenu);
                    elseif Model.State==3
                        Model.State=2;
                        app.CubeModel(Model.Number)=Model;
                        DrawModel(app);
                    end
                    %新建不规则模型
                case 3
                    if Model.State==1
                        Model.State=2;
                        app.IrregularNum=Model.Number;
                        app.IrregularModel(app.IrregularNum)=Model;
                        Plot_Irregular(app.UIAxes,Model.Position,Model.Position,app.Nx,app.Ny,app.Nz,app.ScopeData,Model.Color)
                        uitreenode(app.IrregularNode,"Text",Model.Text,"NodeData",app.IrregularNum,"Tag",'Irregular',"ContextMenu",app.ContextMenu);
                    elseif Model.State==3
                        Model.State=2;
                        app.IrregularModel(Model.Number)=Model;
                        DrawModel(app);

                    end
                    %新建圆柱体模型
                case 2
                    if Model.State==1
                        app.CylinderNum=app.CylinderNum+1;
                        Model.State=2;
                        app.CylinderModel(app.CylinderNum)=Model;
                        %根据圆柱体走向，绘制模型
                        switch Model.Trend
                            case 'X'
                                [cx,cy,cz]=Plot_Cylinder(Model.Extension(2)-Model.Extension(1),[Model.Extension(1),Model.Position(1),Model.Position(2)],Model.Radius,0);
                            case 'Y'
                                [cx,cy,cz]=Plot_Cylinder(Model.Extension(2)-Model.Extension(1),[Model.Position(1),Model.Extension(1),Model.Position(2)],Model.Radius,1);
                            case 'Z'
                                [cx,cy,cz]=Plot_Cylinder(Model.Extension(2)-Model.Extension(1),[Model.Position(1),Model.Position(2),Model.Extension(1)],Model.Radius,2);
                        end
                        surf(app.UIAxes,cx,cy,cz,'FaceColor',Model.Color);
                        hold(app.UIAxes,"on");
                        fill3(app.UIAxes,cx(1,:),cy(1,:),cz(1,:),Model.Color);
                        hold(app.UIAxes,"on")
                        fill3(app.UIAxes,cx(2,:),cy(2,:),cz(2,:),Model.Color);
                        hold(app.UIAxes,"on")
                        uitreenode(app.CylinderNode,"Text",Model.Text,"NodeData",app.CylinderNum,"Tag",'Cylinder',"ContextMenu",app.ContextMenu);
                    elseif Model.State==3
                        Model.State=2;
                        app.CylinderModel(Model.Number)=Model;
                        DrawModel(app);
                    end
            end
            %展开模型列表
            expand(app.Tree);
            app.operation=0;
        end
    end

    methods (Access = private)

        function DrawModel(app)
            %刷新坐标区并绘制全部模型
            cla(app.UIAxes);
            Box_Axis(app.UIAxes,app.UIAxes.XLim,app.UIAxes.YLim,app.UIAxes.ZLim);
            restarttree(app);
            for i=1:app.SphereNum
                if app.SphereModel(i).State==2
                    [xs,ys,zs]=Plot_Sphere(app.SphereModel(i).Position(1),app.SphereModel(i).Position(2),app.SphereModel(i).Position(3),app.SphereModel(i).Radius);
                    surf(app.UIAxes,xs,ys,zs,"facecolor",app.SphereModel(i).Color)
                    hold(app.UIAxes,"on");
                    %在模型列表中新建模型节点
                    uitreenode(app.SphereNode,"Text",app.SphereModel(i).Text,"NodeData",app.SphereModel(i).Number,"Tag",'Sphere',"ContextMenu",app.ContextMenu);
                end
            end
            for i=1:app.CubeNum
                if app.CubeModel(i).State==2
                    fixed=[app.CubeModel(i).Position(1,:);app.CubeModel(i).Position(2,:);app.CubeModel(i).Position(3,:);app.CubeModel(i).Position(4,:);app.CubeModel(i).Position(5,:);app.CubeModel(i).Position(6,:);app.CubeModel(i).Position(7,:);app.CubeModel(i).Position(8,:)];
                    facet=[1 2 4 3;1 3 7 5;1 2 6 5;2 4 8 6;3 4 8 7;5 6 8 7;];
                    patch(app.UIAxes,'Faces',facet,"Vertices",fixed,'facecolor',app.CubeModel(i).Color);
                    hold(app.UIAxes,"on");
                    uitreenode(app.CubeNode,"Text",app.CubeModel(i).Text,"NodeData",app.CubeModel(i).Number,"Tag",'Cube',"ContextMenu",app.ContextMenu);
                end
            end
            for i=1:app.CylinderNum
                if app.CylinderModel(i).State==2
                    switch app.CylinderModel(i).Trend
                        case 'X'
                            [cx,cy,cz]=Plot_Cylinder(app.CylinderModel(i).Extension(2)-app.CylinderModel(i).Extension(1),[app.CylinderModel(i).Extension(1),app.CylinderModel(i).Position(1),app.CylinderModel(i).Position(2)],app.CylinderModel(i).Radius,0);
                        case 'Y'
                            [cx,cy,cz]=Plot_Cylinder(app.CylinderModel(i).Extension(2)-app.CylinderModel(i).Extension(1),[app.CylinderModel(i).Position(1),app.CylinderModel(i).Extension(1),app.CylinderModel(i).Position(2)],app.CylinderModel(i).Radius,1);
                        case 'Z'
                            [cx,cy,cz]=Plot_Cylinder(app.CylinderModel(i).Extension(2)-app.CylinderModel(i).Extension(1),[app.CylinderModel(i).Position(1),app.CylinderModel(i).Position(2),app.CylinderModel(i).Extension(1)],app.CylinderModel(i).Radius,2);
                    end
                    surf(app.UIAxes,cx,cy,cz,'FaceColor',app.CylinderModel(i).Color);
                    hold(app.UIAxes,"on");
                    fill3(app.UIAxes,cx(1,:),cy(1,:),cz(1,:),app.CylinderModel(i).Color);
                    hold(app.UIAxes,"on")
                    fill3(app.UIAxes,cx(2,:),cy(2,:),cz(2,:),app.CylinderModel(i).Color);
                    hold(app.UIAxes,"on")
                    uitreenode(app.CylinderNode,"Text",app.CylinderModel(i).Text,"NodeData",app.CylinderModel(i).Number,"Tag",'Cylinder',"ContextMenu",app.ContextMenu);
                end
            end
            for i=1:app.IrregularNum
                if app.IrregularModel(i).State==2
                    Plot_Irregular(app.UIAxes,app.IrregularModel(i).Position,app.IrregularModel(i).Position,app.Nx,app.Ny,app.Nz,app.ScopeData,app.IrregularModel(i).Color)
                    uitreenode(app.IrregularNode,"Text",app.IrregularModel(i).Text,"NodeData",app.IrregularModel(i).Number,"Tag",'Irregular',"ContextMenu",app.ContextMenu);
                    hold(app.UIAxes,"on")
                end
            end
            expand(app.Tree);
        end


        function restarttree(app)
            app.Tree = uitree(app.ModelingUIFigure);
            app.Tree.FontName = 'Consolas';
            app.Tree.Position = [874 35 127 545];
            app.SphereNode = uitreenode(app.Tree);
            app.SphereNode.Text = 'Sphere';

            % Create CubeNode
            app.CubeNode = uitreenode(app.Tree);
            app.CubeNode.Text = 'Cube';

            % Create CylinerNode
            app.CylinderNode = uitreenode(app.Tree);
            app.CylinderNode.Text = 'Cylinder';

            % Create IrregularNode
            app.IrregularNode = uitreenode(app.Tree);
            app.IrregularNode.Text = 'Irregular';
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            MoveAppCenter(app.ModelingUIFigure);
            %初始化模型数据类型

            %球体模型
            app.SphereModel.Text='';
            app.SphereModel.Position=[0,0,0];
            app.SphereModel.Radius=0;
            app.SphereModel.ResidualDensity=0;
            app.SphereModel.Color='';
            app.SphereModel.Magnetization=0;
            app.SphereModel.Inclination=0;
            app.SphereModel.Declination=0;
            app.SphereModel.Tag=0;
            app.SphereModel.State=0;
            app.SphereModel.Number=0;
            app.SphereModel.DenDivision=0;
            app.SphereModel.MagDivision=0;
            %立方体模型
            app.CubeModel.Text='';
            app.CubeModel.Position=zeros(8,3);
            app.CubeModel.ResidualDensity=0;
            app.CubeModel.Color='';
            app.CubeModel.Magnetization=0;
            app.CubeModel.Inclination=0;
            app.CubeModel.Declination=0;
            app.CubeModel.Tag=1;
            app.CubeModel.State=0;
            app.CubeModel.Number=0;
            app.CubeModel.DenDivision=0;
            app.CubeModel.MagDivision=0;
            %圆柱体模型
            app.CylinderModel.Radius=0;
            app.CylinderModel.Position=[0 0];
            app.CylinderModel.Trend='';
            app.CylinderModel.Extension=[0 0];
            app.CylinderModel.ResidualDensity=0;
            app.CylinderModel.Color='';
            app.CylinderModel.Magnetization=0;
            app.CylinderModel.Inclination=0;
            app.CylinderModel.Declination=0;
            app.CylinderModel.Tag=2;
            app.CylinderModel.State=0;
            app.CylinderModel.Number=0;
            app.CylinderModel.DenDivision=0;
            app.CylinderModel.MagDivision=0;
            app.CylinderModel.Text='';
            %不规则模型
            app.IrregularModel.Position=[0 0 0];
            app.IrregularModel.Text='';
            app.IrregularModel.ResidualDensity=0;
            app.IrregularModel.Color='';
            app.IrregularModel.Magnetization=0;
            app.IrregularModel.Inclination=0;
            app.IrregularModel.Declination=0;
            app.IrregularModel.Tag=3;
            app.IrregularModel.State=0;
            app.IrregularModel.Number=0;
            app.IrregularModel.DenDivision=0;
            app.IrregularModel.MagDivision=0;

        end

        % Button pushed function: ForwardcalculationButton
        function ForwardcalculationButtonPushed(app, event)

            app.dialogapp=Modelforwarding(app,app.ScopeData(1,1:6), app.ScopeData(1,7), app.ScopeData(1,8), app.ScopeData(1,9), app.Nx, app.Ny, app.Nz, app.ScopeData(1,10), app.IrregularNum, app.IrregularModel, app.CylinderNum, app.CylinderModel, app.CubeNum, app.CubeModel, app.SphereNum, app.SphereModel);
        end

        % Button pushed function: AddSphereButton
        function AddSphereButtonPushed(app, event)
            if app.operation==0
                app.operation=1;
                num=app.SphereNum+1;
                %初始化一个球体模型
                Sphere.Text=['Sphere',num2str(num)];
                Sphere.Position=[0,0,0];
                Sphere.Radius=0;
                Sphere.ResidualDensity=0;
                Sphere.Color='red';
                Sphere.Magnetization=0;
                Sphere.Inclination=0;
                Sphere.Declination=0;
                Sphere.Tag=0;
                Sphere.State=0;
                Sphere.Number=num;
                Sphere.DenDivision=0;
                Sphere.MagDivision=0;
                %将模型传给AddSphere 界面，进行模型设置
                app.dialogapp=SphereParameter(app,Sphere,app.ScopeData);
            end
        end

        % Button pushed function: SettingButton
        function SettingButtonPushed(app, event)
            if app.operation==0
                app.operation=1;
                app.dialogapp=ModelingRange(app,app.ScopeData);
            end
        end

        % Button pushed function: AddCubeButton
        function AddCubeButtonPushed(app, event)
            if app.operation==0
                app.operation=1;
                num=app.CubeNum+1;
                Cube.Text=['Cube',num2str(num)];
                Cube.Position=zeros(8,3);
                Cube.ResidualDensity=0;
                Cube.Color='red';
                Cube.Magnetization=0;
                Cube.Inclination=0;
                Cube.Declination=0;
                Cube.Tag=1;
                Cube.State=0;
                Cube.Number=num;
                Cube.DenDivision=0;
                Cube.MagDivision=0;
                app.dialogapp=CubeParameter(app,Cube,app.ScopeData);
            end
        end

        % Button pushed function: AddCylinerButton
        function AddCylinerButtonPushed(app, event)
            if app.operation==0
                app.operation=1;
                %新建一个圆柱体模型
                Cylinder.Radius=0;
                Cylinder.Trend='X';
                Cylinder.Extension=[0 0];
                num=app.CylinderNum+1;
                Cylinder.Text=['Cylinder',num2str(num)];
                Cylinder.Position=[0 0];
                Cylinder.ResidualDensity=0;
                Cylinder.Color='red';
                Cylinder.Magnetization=0;
                Cylinder.Inclination=0;
                Cylinder.Declination=0;
                Cylinder.Tag=2;
                Cylinder.State=0;
                Cylinder.Number=num;
                Cylinder.DenDivision=0;
                Cylinder.MagDivision=0;

                app.dialogapp=CylinderParameter(app,Cylinder,app.ScopeData);
            end
        end

        % Button pushed function: AddIrregularButton
        function AddIrregularButtonPushed(app, event)
            if app.operation==0

                app.operation=1;
                Irregular.Position=zeros(app.Nx,app.Ny,app.Nz,'logical');
                num=app.IrregularNum+1;
                Irregular.Text=['Irregular',num2str(num)];
                Irregular.ResidualDensity=0;
                Irregular.Color='red';
                Irregular.Magnetization=0;
                Irregular.Inclination=0;
                Irregular.Declination=0;
                Irregular.Tag=3;
                Irregular.State=0;
                Irregular.Number=num;
                Irregular.DenDivision=zeros(app.Nx,app.Ny,app.Nz,'single');
                Irregular.MagDivision=zeros(app.Nx,app.Ny,app.Nz,'single');
                app.dialogapp=LayerBuilding(app,Irregular,app.Nx,app.Ny,app.Nz,app.ScopeData);
            end
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            %导出数据
            %导出数据的类型
            type=app.OutputDropDown.Value;

            switch type
                case "Model"
                    %导出为模型文件，该文件用于正演模块
                    %文件格式为mat,可以用Matlab打开，通过脚本程序更改相关的参数
                    %该文件包含的内容以及变量名：
                    %场源信息：
                    %    场源范围：Scope=[xmin xmax ymin ymax zmin zmax]
                    %    剖分段数：nx,ny,nz
                    %    剖分步长：Dx,Dy,Dz
                    %    单位：Unit (0：m  ;1: km  ;2: hm)
                    %
                    %各类模型数量、模型信息(创建成功的模型的State==2)：
                    %    球体模型：sphereModel(num)        sphereNum
                    %    立方体模型：cubeModel(num)        cubeNum
                    %    圆柱体模型：cylinderModel(num)    cylinderModel
                    %    不规则模型：irregularModel(num)   irrgularModel
                    %num为模型序号
                    %如果需要在脚本中更改模型信息，首先讲讲模型文件（*.mat)
                    %导入(load("*.mat")),然后输入任意模型及序号并输出至控制台，查看相关参数并修改
                    filter = {'*.mat'};
                    [filename,path]=uiputfile(filter,'Save file name','ufilename');
                    filepath=strcat(path,filename);
                    Scope=app.ScopeData(1:6);
                    nx=app.Nx;
                    ny=app.Ny;
                    nz=app.Nz;
                    Dx=app.ScopeData(1,7);
                    Dy=app.ScopeData(1,8);
                    Dz=app.ScopeData(1,9);
                    Unit=app.ScopeData(1,10);
                    if app.SphereNum>0
                        sphereModel=app.SphereModel;
                        sphereNum=app.SphereNum;
                    else
                        sphereModel=[];
                        sphereNum=0;
                    end
                    if app.CubeNum>0
                        cubeModel=app.CubeModel;
                        cubeNum=app.CubeNum;
                    else
                        cubeModel=[];
                        cubeNum=0;
                    end
                    if app.CylinderNum>0
                        cylinderModel=app.CylinderModel;
                        cylinderNum=app.CylinderNum;
                    else
                        cylinderModel=[];
                        cylinderNum=0;
                    end
                    if app.IrregularNum>0
                        irregularModel=app.IrregularModel;
                        irregularNum=app.IrregularNum;
                    else
                        irregularModel=[];
                        irregularNum=0;
                    end
                    save(filepath,"Scope","nx","ny","nz","Dx","Dy","Dz","Unit","irregularNum","irregularModel","cylinderNum","cylinderModel","cubeNum","cubeModel","sphereNum","sphereModel","-mat");
                    app.dialogapp=Error(app,"Saved Successfully!");
                case "Density"
                    %导出为空间密度分布文件
                    %有两类导出模式可以选择
                    filter = {'*.txt';'*.mat';};
                    [filename,path,index]=uiputfile(filter,'Save file name','ufilename');
                    filepath=strcat(path,filename);
                    switch index
                        case 1
                            %一是导出为文本文件，以供其他编程语言编写的代码使用
                            %格式为[x,y,z,density]
                            %[x,y,z]为网格中心点
                            %首行为单位
                            if  app.ScopeData(1,10)==0
                                dataname=["x(m)","y(m)","z(m)","rho(g/cm2)"];
                            elseif  app.ScopeData(1,10)==1
                                dataname=["x(km)","y(km)","z(km)","rho(g/cm2)"];
                            elseif  app.ScopeData(1,10)==2
                                dataname=["x(hm)","y(hm)","z(hm)","rho(g/cm2)"];
                            end

                            fid=fopen(filepath,'w');
                            for i=1:4
                                fprintf(fid,'%s\t',dataname(i));
                            end
                            fprintf(fid,'\r\n');

                            % spacedata=zeros(app.Nx*app.Ny*app.Nz,4);
                            if isempty(app.AllModelPositon)
                                app.AllModelPositon=zeros(app.Nx,app.Ny,app.Nz);
                                for i=1:app.SphereNum
                                    if app.SphereModel(i).State==2&&size(app.SphereModel(i).DenDivision,1)>1
                                        app.AllModelPositon=app.AllModelPositon+app.SphereModel(i).DenDivision;
                                    end
                                end
                                for i=1:app.CubeNum
                                    if app.CubeModel(i).State==2&&size(app.CubeModel(i).DenDivision,1)>1
                                        app.AllModelPositon=app.AllModelPositon+app.CubeModel(i).DenDivision;
                                    end
                                end
                                for i=1:app.IrregularNum
                                    if app.IrregularModel(i).State==2&&size(app.IrregularModel(i).DenDivision,1)>1
                                        app.AllModelPositon=app.AllModelPositon+app.IrregularModel(i).DenDivision;
                                    end
                                end
                                for i=1:app.CylinderNum
                                    if app.CylinderModel(i).State==2&&size(app.CylinderModel(i).DenDivision,1)>1
                                        app.AllModelPositon=app.AllModelPositon+app.CylinderModel(i).DenDivision;
                                    end
                                end
                                %将数据输出到文本文件
                                for ix=1:app.Nx
                                    for iy=1:app.Ny
                                        for iz=1:app.Nz
                                            line=[(ix-0.5)*app.ScopeData(1,7)+app.ScopeData(1,1),(iy-0.5)*app.ScopeData(1,8)+app.ScopeData(1,3),(iz-0.5)*app.ScopeData(1,9)+app.ScopeData(1,5),app.AllModelPositon(ix,iy,iz)];
                                            for j=1:4
                                                fprintf(fid,'%g\t',line(1,j));
                                            end
                                            fprintf(fid,'\r\n');

                                        end
                                    end
                                end
                            else
                                %将数据输出到文本文件
                                for ix=1:app.Nx
                                    for iy=1:app.Ny
                                        for iz=1:app.Nz
                                            line=[(ix-0.5)*app.ScopeData(1,7)+app.ScopeData(1,1),(iy-0.5)*app.ScopeData(1,8)+app.ScopeData(1,3),(iz-0.5)*app.ScopeData(1,9)+app.ScopeData(1,5),app.AllModelPositon(ix,iy,iz)];
                                            for j=1:4
                                                fprintf(fid,'%g\t',line(1,j));
                                            end
                                            fprintf(fid,'\r\n');
                                        end
                                    end
                                end
                            end
                            app.dialogapp=Error(app,"Saved Successfully!");
                        case 2
                            %保存为mat文件，在matlab内打开
                            %modeldensity为一个Nx×Ny×Nz的三维矩阵
                            Dx=app.ScopeData(1,7);
                            Dy=app.ScopeData(1,8);
                            Dz=app.ScopeData(1,9);
                            Unit=app.ScopeData(1,10);
                            if isempty(app.AllModelPositon)
                                app.AllModelPositon=zeros(app.Nx,app.Ny,app.Nz);
                                for i=1:app.SphereNum
                                    if app.SphereModel(i).State==2&&size(app.SphereModel(i).DenDivision,1)>1
                                        app.AllModelPositon=app.AllModelPositon+app.SphereModel(i).DenDivision;
                                    end
                                end
                                for i=1:app.CubeNum
                                    if app.CubeModel(i).State==2&&size(app.CubeModel(i).DenDivision,1)>1
                                        app.AllModelPositon=app.AllModelPositon+app.CubeModel(i).DenDivision;
                                    end
                                end
                                for i=1:app.IrregularNum
                                    if app.IrregularModel(i).State==2&&size(app.IrregularModel(i).DenDivision,1)>1
                                        app.AllModelPositon=app.AllModelPositon+app.IrregularModel(i).DenDivision;
                                    end
                                end
                                for i=1:app.CylinderNum
                                    if app.CylinderModel(i).State==2&&size(app.CylinderModel(i).DenDivision,1)>1
                                        app.AllModelPositon=app.AllModelPositon+app.CylinderModel(i).DenDivision;
                                    end
                                end
                                modeldensity=app.AllModelPositon;
                                save(filepath,"modeldensity","Dx","Dy","Dz","Unit","-mat");
                                app.dialogapp=Error(app,"Saved Successfully!");
                            else
                                modeldensity=app.AllModelPositon;
                                save(filepath,"modeldensity","Dx","Dy","Dz","Unit","-mat");
                                app.dialogapp=Error(app,"Saved Successfully!");
                            end
                    end

                case "Magnetization"
                    %与导出密度文件相似
                    filter = {'*.txt';'*.mat';};
                    [filename,path,index]=uiputfile(filter,'Save file name','ufilename');
                    filepath=strcat(path,filename);
                    switch index
                        case 1
                            %一是导出为文本文件，以供其他编程语言编写的代码使用
                            %格式为[x,y,z,density]
                            %[x,y,z]为网格中心点
                            %首行为单位
                            if  app.ScopeData(1,10)==0
                                dataname=["x(m)","y(m)","z(m)","rho(g/cm2)"];
                            elseif  app.ScopeData(1,10)==1
                                dataname=["x(km)","y(km)","z(km)","rho(g/cm2)"];
                            elseif  app.ScopeData(1,10)==2
                                dataname=["x(hm)","y(hm)","z(hm)","rho(g/cm2)"];
                            end
                            fid=fopen(filepath,'w');
                            for i=1:4
                                fprintf(fid,'%s\t',dataname(i));
                            end
                            fprintf(fid,'\r\n');
                            if isempty(app.AllModelPositon2)
                                app.AllModelPositon2=zeros(app.Nx,app.Ny,app.Nz);
                                for i=1:app.SphereNum
                                    if app.SphereModel(i).State==2&&size(app.SphereModel(i).MagDivision,1)>1
                                        app.AllModelPositon2=app.AllModelPositon2+app.SphereModel(i).MagDivision;
                                    end
                                end
                                for i=1:app.CubeNum
                                    if app.CubeModel(i).State==2&&size(app.CubeModel(i).MagDivision,1)>1
                                        app.AllModelPositon2=app.AllModelPositon2+app.CubeModel(i).MagDivision;
                                    end
                                end
                                for i=1:app.IrregularNum
                                    if app.IrregularModel(i).State==2&&size(app.IrregularModel(i).MagDivision,1)>1
                                        app.AllModelPositon2=app.AllModelPositon2+app.IrregularModel(i).MagDivision;
                                    end
                                end
                                for i=1:app.CylinderNum
                                    if app.CylinderModel(i).State==2&&size(app.CylinderModel(i).DenDivision,1)>1
                                        app.AllModelPositon2=app.AllModelPositon2+app.CylinderModel(i).MagDivision;
                                    end
                                end
                                for ix=1:app.Nx
                                    for iy=1:app.Ny
                                        for iz=1:app.Nz
                                            line=[(ix-0.5)*app.ScopeData(1,7)+app.ScopeData(1,1),(iy-0.5)*app.ScopeData(1,8)+app.ScopeData(1,3),(iz-0.5)*app.ScopeData(1,9)+app.ScopeData(1,5),app.AllModelPositon2(ix,iy,iz)];
                                            for j=1:4
                                                fprintf(fid,'%g\t',line(1,j));
                                            end
                                            fprintf(fid,'\r\n');
                                        end
                                    end
                                end
                            else
                                for ix=1:app.Nx
                                    for iy=1:app.Ny
                                        for iz=1:app.Nz
                                            line=[(ix-0.5)*app.ScopeData(1,7)+app.ScopeData(1,1),(iy-0.5)*app.ScopeData(1,8)+app.ScopeData(1,3),(iz-0.5)*app.ScopeData(1,9)+app.ScopeData(1,5),app.AllModelPositon2(ix,iy,iz)];
                                            for j=1:4
                                                fprintf(fid,'%g\t',line(1,j));
                                            end
                                            fprintf(fid,'\r\n');
                                        end
                                    end
                                end
                            end
                            %将数据输出到文本文件
                            app.dialogapp=Error(app,"Saved Successfully!");
                        case 2
                            %保存为mat文件，在matlab内打开
                            %modeldensity为一个Nx×Ny×Nz的三维矩阵
                            Dx=app.ScopeData(1,7);
                            Dy=app.ScopeData(1,8);
                            Dz=app.ScopeData(1,9);
                            Unit=app.ScopeData(1,10);
                            if isempty(app.AllModelPositon2)
                                app.AllModelPositon2=zeros(app.Nx,app.Ny,app.Nz);
                                for i=1:app.SphereNum
                                    if app.SphereModel(i).State==2&&size(app.SphereModel(i).MagDivision,1)>1
                                        app.AllModelPositon2=app.AllModelPositon2+app.SphereModel(i).MagDivision;
                                    end
                                end
                                for i=1:app.CubeNum
                                    if app.CubeModel(i).State==2&&size(app.CubeModel(i).MagDivision,1)>1
                                        app.AllModelPositon2=app.AllModelPositon2+app.CubeModel(i).MagDivision;
                                    end
                                end
                                for i=1:app.IrregularNum
                                    if app.IrregularModel(i).State==2&&size(app.IrregularModel(i).MagDivision,1)>1
                                        app.AllModelPositon2=app.AllModelPositon2+app.IrregularModel(i).MagDivision;
                                    end
                                end
                                for i=1:app.CylinderNum
                                    if app.CylinderModel(i).State==2&&size(app.CylinderModel(i).MagDivision,1)>1
                                        app.AllModelPositon2=app.AllModelPositon2+app.CylinderModel(i).MagDivision;
                                    end
                                end
                                modelmagnetization=app.AllModelPositon2;
                                save(filepath,"modelmagnetization","Dx","Dy","Dz","Unit","-mat");
                                app.dialogapp=Error(app,"Saved Successfully!");
                            else
                                modelmagnetization=app.AllModelPositon2;
                                save(filepath,"modelmagnetization","Dx","Dy","Dz","Unit","-mat");
                                app.dialogapp=Error(app,"Saved Successfully!");
                            end
                    end

            end
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)

            num=app.Tree.SelectedNodes.NodeData;
            tag=app.Tree.SelectedNodes.Tag;
            switch tag
                case 'Sphere'
                    app.SphereModel(num).State=-1;
                case 'Cube'
                    app.CubeModel(num).State=-1;
                case 'Cylinder'
                    app.CylinderModel(num).State=-1;
                case 'Irregular'
                    app.IrregularModel(num).State=-1;

            end
            DrawModel(app);
        end

        % Menu selected function: ModifyMenu
        function ModifyMenuSelected(app, event)

            num=app.Tree.SelectedNodes.NodeData;
            tag=app.Tree.SelectedNodes.Tag;
            switch tag
                case 'Sphere'
                    app.dialogapp=SphereParameter(app,app.SphereModel(num),app.ScopeData);
                case 'Cube'
                    app.dialogapp=CubeParameter(app,app.CubeModel(num),app.ScopeData);
                case 'Cylinder'
                    app.dialogapp=CylinderParameter(app,app.CylinderModel(num),app.ScopeData);
                case 'Irregular'
                    app.dialogapp=LayerBuilding(app,app.IrregularModel(num),app.Nx,app.Ny,app.Nz,app.ScopeData);
            end
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            value = app.ViewDropDown.Value;
            switch value
                case "X Profile"
                    view(app.UIAxes,[0 0]);
                case "Y Profile"
                    view(app.UIAxes,[90 0]);
                case "Z Profile"
                    view(app.UIAxes,2);
                case "Space"
                    view(app.UIAxes,3);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModelingUIFigure = uifigure('Visible', 'off');
            app.ModelingUIFigure.Name = 'Modeling';
            app.ModelingUIFigure.Icon = 'main-newmodel.png';
            app.UIAxes = uiaxes(app.ModelingUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Depth')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 100];
            app.Panel_2 = uipanel(app.ModelingUIFigure);
            app.ViewDropDown = uidropdown(app.Panel_2);
            app.ViewDropDown.Items = {'Space', 'X Profile', 'Y Profile', 'Z Profile'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'Space';
            app.OutputButton = uibutton(app.Panel_2, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Icon = 'Output.png';
            app.OutputButton.Text = 'Output';
            app.OutputDropDown = uidropdown(app.Panel_2);
            app.OutputDropDown.Items = {'Model', 'Density', 'Magnetization'};
            app.OutputDropDown.Value = 'Model';
            app.ViewButton = uibutton(app.Panel_2, 'push');
            app.ViewButton.Icon = 'View.png';
            app.ViewButton.Text = 'View';
            app.ForwardcalculationButton = uibutton(app.Panel_2, 'push');
            app.ForwardcalculationButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardcalculationButtonPushed, true);
            app.ForwardcalculationButton.Text = 'Forward calculation';
            app.SettingButton = uibutton(app.Panel_2, 'push');
            app.SettingButton.ButtonPushedFcn = createCallbackFcn(app, @SettingButtonPushed, true);
            app.SettingButton.Icon = 'Setting.png';
            app.SettingButton.Text = 'Setting';
            app.AddaModelLabel = uilabel(app.Panel_2);
            app.AddaModelLabel.Text = 'Add a Model';
            app.Panel_3 = uipanel(app.ModelingUIFigure);
            app.AddSphereButton = uibutton(app.Panel_3, 'push');
            app.AddSphereButton.ButtonPushedFcn = createCallbackFcn(app, @AddSphereButtonPushed, true);
            app.AddSphereButton.Icon = 'sphere.png';
            app.AddSphereButton.Text = '';
            app.AddCubeButton = uibutton(app.Panel_3, 'push');
            app.AddCubeButton.ButtonPushedFcn = createCallbackFcn(app, @AddCubeButtonPushed, true);
            app.AddCubeButton.Icon = 'Cube.png';
            app.AddCubeButton.Text = '';
            app.AddCylinerButton = uibutton(app.Panel_3, 'push');
            app.AddCylinerButton.ButtonPushedFcn = createCallbackFcn(app, @AddCylinerButtonPushed, true);
            app.AddCylinerButton.Icon = 'Cyliner.png';
            app.AddCylinerButton.Text = '';
            app.AddIrregularButton = uibutton(app.Panel_3, 'push');
            app.AddIrregularButton.ButtonPushedFcn = createCallbackFcn(app, @AddIrregularButtonPushed, true);
            app.AddIrregularButton.Icon = 'Irregular.png';
            app.AddIrregularButton.Text = '';
            app.IrregularLabel = uilabel(app.Panel_3);
            app.IrregularLabel.Text = 'Irregular';
            app.SphereLabel = uilabel(app.Panel_3);
            app.SphereLabel.Text = 'Sphere';
            app.CubeLabel = uilabel(app.Panel_3);
            app.CubeLabel.Text = 'Cube';
            app.CylinderLabel = uilabel(app.Panel_3);
            app.CylinderLabel.Text = 'Cylinder';
            app.Tree = uitree(app.ModelingUIFigure);
            app.SphereNode = uitreenode(app.Tree);
            app.SphereNode.Text = 'Sphere';
            app.CubeNode = uitreenode(app.Tree);
            app.CubeNode.Text = 'Cube';
            app.CylinderNode = uitreenode(app.Tree);
            app.CylinderNode.Text = 'Cylinder';
            app.IrregularNode = uitreenode(app.Tree);
            app.IrregularNode.Text = 'Irregular';
            app.ContextMenu = uicontextmenu(app.ModelingUIFigure);
            app.ModifyMenu = uimenu(app.ContextMenu);
            app.ModifyMenu.MenuSelectedFcn = createCallbackFcn(app, @ModifyMenuSelected, true);
            app.ModifyMenu.Text = 'Modify';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.IrregularNode.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ModelingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreateModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModelingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModelingUIFigure)
        end
    end
end