classdef CubeParameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddCubeUIFigure               matlab.ui.Figure
        gcmLabel                      matlab.ui.control.Label
        NameEditField                 matlab.ui.control.EditField
        NameEditFieldLabel            matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        ColorDropDownLabel            matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        IntelligentInputCheckBox      matlab.ui.control.CheckBox
        CancelButton                  matlab.ui.control.Button
        OkButton                      matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        P8EditField                   matlab.ui.control.EditField
        P8EditFieldLabel              matlab.ui.control.Label
        P7EditField                   matlab.ui.control.EditField
        P7EditFieldLabel              matlab.ui.control.Label
        P6EditField                   matlab.ui.control.EditField
        P6EditFieldLabel              matlab.ui.control.Label
        P4EditField                   matlab.ui.control.EditField
        P4EditFieldLabel              matlab.ui.control.Label
        P3EditField                   matlab.ui.control.EditField
        P3EditFieldLabel              matlab.ui.control.Label
        P2EditField                   matlab.ui.control.EditField
        P2EditFieldLabel              matlab.ui.control.Label
        P5EditField                   matlab.ui.control.EditField
        P5EditFieldLabel              matlab.ui.control.Label
        P1EditField                   matlab.ui.control.EditField
        P1EditFieldLabel              matlab.ui.control.Label
        MagneticDeclinationLabel      matlab.ui.control.Label
        MagneticDeclinationEditField  matlab.ui.control.NumericEditField
        MagneticInclinationEditField  matlab.ui.control.NumericEditField
        MagneticInclinationLabel      matlab.ui.control.Label
        MagnetizationEditField        matlab.ui.control.NumericEditField
        MagnetizationEditFieldLabel   matlab.ui.control.Label
        ResidualDensityEditField      matlab.ui.control.NumericEditField
        ResidualDensityLabel          matlab.ui.control.Label
        AmLabel                       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        data;
        ScopeData;
        Callingapp;
        point=zeros(8,4);
        dialogapp;
    end

    methods (Access = private)

        function plotCube(app)
            %用于绘制立方体轮廓图
            %在输入第二个顶点后，会将两个顶点连接
            %app.point是一个8×4的矩阵，前三列为顶点坐标，
            %第四列为判断该顶点是否已经输入1为已经输入，0为未输入
            cla(app.UIAxes);
            Box_Axis(app.UIAxes,app.ScopeData(1,1:2),app.ScopeData(1,3:4),app.ScopeData(1,5:6));
            if app.point(1,4)==1
                plot3(app.UIAxes,app.point(1,1),app.point(1,2),app.point(1,3),'Color','r',"MarkerSize",10,"Marker",".");
                hold(app.UIAxes,"on")
            end

            if app.point(2,4)==1
                plot3(app.UIAxes,app.point(2,1),app.point(2,2),app.point(2,3),'Color','r',"MarkerSize",10,"Marker",".");
            end

            if app.point(3,4)==1
                plot3(app.UIAxes,app.point(3,1),app.point(3,2),app.point(3,3),'Color','r',"MarkerSize",10,"Marker",".");
                hold(app.UIAxes,"on")
            end
            if app.point(4,4)==1
                plot3(app.UIAxes,app.point(4,1),app.point(4,2),app.point(4,3),'Color','r',"MarkerSize",10,"Marker",".");
                hold(app.UIAxes,"on")
            end
            if app.point(5,4)==1
                plot3(app.UIAxes,app.point(5,1),app.point(5,2),app.point(5,3),'Color','r',"MarkerSize",10,"Marker",".");
                hold(app.UIAxes,"on")
            end
            if app.point(6,4)==1
                plot3(app.UIAxes,app.point(6,1),app.point(6,2),app.point(6,3),'Color','r',"MarkerSize",10,"Marker",".");
                hold(app.UIAxes,"on")
            end
            if app.point(7,4)==1
                plot3(app.UIAxes,app.point(7,1),app.point(7,2),app.point(7,3),'Color','r',"MarkerSize",10,"Marker",".");
                hold(app.UIAxes,"on")
            end
            if app.point(8,4)==1
                plot3(app.UIAxes,app.point(8,1),app.point(8,2),app.point(8,3),'Color','r',"MarkerSize",10,"Marker",".");
                hold(app.UIAxes,"on")
            end

            if app.point(1,4)==1&&app.point(2,4)==1
                line(app.UIAxes,[app.point(1,1) app.point(2,1)],[app.point(1,2) app.point(2,2)],[app.point(1,3) app.point(2,3)],'Color','b',"LineWidth",1.5);
                hold(app.UIAxes,"on")

            end
            if app.point(1,4)==1&&app.point(3,4)==1
                line(app.UIAxes,[app.point(1,1) app.point(3,1)],[app.point(1,2) app.point(3,2)],[app.point(1,3) app.point(3,3)],'Color','b',"LineWidth",1.5);
                hold(app.UIAxes,"on")

            end
            if app.point(1,4)==1&&app.point(5,4)==1
                line(app.UIAxes,[app.point(1,1) app.point(5,1)],[app.point(1,2) app.point(5,2)],[app.point(1,3) app.point(5,3)],'Color','b',"LineWidth",1.5)
            end
            if app.point(6,4)==1&&app.point(5,4)==1
                line(app.UIAxes,[app.point(6,1) app.point(5,1)],[app.point(6,2) app.point(5,2)],[app.point(6,3) app.point(5,3)],'Color','b',"LineWidth",1.5);
                hold(app.UIAxes,"on")
            end
            if app.point(6,4)==1&&app.point(2,4)==1
                line(app.UIAxes,[app.point(6,1) app.point(2,1)],[app.point(6,2) app.point(2,2)],[app.point(6,3) app.point(2,3)],'Color','b',"LineWidth",1.5);
                hold(app.UIAxes,"on")
            end
            if app.point(6,4)==1&&app.point(8,4)==1
                line(app.UIAxes,[app.point(6,1) app.point(8,1)],[app.point(6,2) app.point(8,2)],[app.point(6,3) app.point(8,3)],'Color','b',"LineWidth",1.5);
            end
            if app.point(7,4)==1&&app.point(8,4)==1
                line(app.UIAxes,[app.point(7,1) app.point(8,1)],[app.point(7,2) app.point(8,2)],[app.point(7,3) app.point(8,3)],'Color','b',"LineWidth",1.5);
                hold(app.UIAxes,"on")
            end
            if app.point(3,4)==1&&app.point(4,4)==1
                line(app.UIAxes,[app.point(3,1) app.point(4,1)],[app.point(3,2) app.point(4,2)],[app.point(3,3) app.point(4,3)],'Color','b',"LineWidth",1.5);
                hold(app.UIAxes,"on")
            end
            if app.point(7,4)==1&&app.point(5,4)==1
                line(app.UIAxes,[app.point(7,1) app.point(5,1)],[app.point(7,2) app.point(5,2)],[app.point(7,3) app.point(5,3)],'Color','b',"LineWidth",1.5);
                hold(app.UIAxes,"on")
            end
            if app.point(4,4)==1&&app.point(8,4)==1
                line(app.UIAxes,[app.point(4,1) app.point(8,1)],[app.point(4,2) app.point(8,2)],[app.point(4,3) app.point(8,3)],'Color','b',"LineWidth",1.5);
                hold(app.UIAxes,"on")
            end
            if app.point(4,4)==1&&app.point(2,4)==1
                line(app.UIAxes,[app.point(4,1) app.point(2,1)],[app.point(4,2) app.point(2,2)],[app.point(4,3) app.point(2,3)],'Color','b',"LineWidth",1.5);
                hold(app.UIAxes,"on")
            end
            if app.point(3,4)==1&&app.point(7,4)==1
                line(app.UIAxes,[app.point(3,1) app.point(7,1)],[app.point(3,2) app.point(7,2)],[app.point(3,3) app.point(7,3)],'Color','b',"LineWidth",1.5);
                hold(app.UIAxes,"on")
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingapp, data, scope)
            %窗口居中
            MoveAppCenter(app.AddCubeUIFigure);
            %导入模型数据及界面初始化
            app.data=data;
            app.Callingapp=callingapp;
            app.ScopeData=scope;
            app.MagneticInclinationEditField.Value=data.Inclination;
            app.MagneticDeclinationEditField.Value=data.Declination;
            app.ResidualDensityEditField.Value=data.ResidualDensity;
            app.MagnetizationEditField.Value=data.Magnetization;
            app.NameEditField.Value=data.Text;
            app.ColorDropDown.Value=data.Color;
            app.P1EditField.Value=['[',num2str(data.Position(1,:)),']'];
            app.P2EditField.Value=['[',num2str(data.Position(2,:)),']'];
            app.P3EditField.Value=['[',num2str(data.Position(3,:)),']'];
            app.P4EditField.Value=['[',num2str(data.Position(4,:)),']'];
            app.P5EditField.Value=['[',num2str(data.Position(5,:)),']'];
            app.P6EditField.Value=['[',num2str(data.Position(6,:)),']'];
            app.P7EditField.Value=['[',num2str(data.Position(7,:)),']'];
            app.P8EditField.Value=['[',num2str(data.Position(8,:)),']'];


            %坐标区初始化
            cla(app.UIAxes);
            a=app.ScopeData(1,1:2);
            b=app.ScopeData(1,3:4);
            c=app.ScopeData(1,5:6);
            xlim(app.UIAxes,a);%XYZ轴范围
            ylim(app.UIAxes,b);
            zlim(app.UIAxes,c);
            xgrid=(a(2)-a(1))/10;
            ygrid=(b(2)-b(1))/10;
            zgrid=(c(2)-c(1))/10;
            xticks(app.UIAxes,a(1):xgrid:a(2));
            yticks(app.UIAxes,b(1):ygrid:b(2));
            zticks(app.UIAxes,c(1):zgrid:c(2));
            XT=(a(1):xgrid:a(2));
            YT=(b(1):ygrid:b(2));
            ZT=(c(1):zgrid:c(2));
            for i=1:1:11
                xTL{i}=num2str(XT(i));
                yTL{i}=num2str(YT(i));
                zTL{i}=num2str(ZT(i));
            end
            xticklabels(app.UIAxes,xTL')
            yticklabels(app.UIAxes,yTL')
            zticklabels(app.UIAxes,zTL')
            if scope(1,10)==0
                xlabel(app.UIAxes,'X/m  (North)')
                ylabel(app.UIAxes,'Y/m  (East)')
                zlabel(app.UIAxes,'Depth/m')
            elseif scope(1,10)==1
                xlabel(app.UIAxes,'X/km  (North)')
                ylabel(app.UIAxes,'Y/km  (East)')
                zlabel(app.UIAxes,'Depth/km')
            elseif scope(1,10)==2
                xlabel(app.UIAxes,'X/hm  (North)')
                ylabel(app.UIAxes,'Y/hm  (East)')
                zlabel(app.UIAxes,'Depth/hm')
            end
            if data.State==2
                app.point(:,1:3)=data.Position;
                app.point(:,4)=1;
                app.IntelligentInputCheckBox.Value=false;
                plotCube(app);
            elseif data.State==0
                app.IntelligentInputCheckBox.Value=true;
            end
            view(app.UIAxes,3)
            grid(app.UIAxes,3)
            set(app.UIAxes,"ZDir","reverse")
            Box_Axis(app.UIAxes,a,b,c);
        end

        % Close request function: AddCubeUIFigure
        function AddCubeUIFigureCloseRequest(app, event)
            %直接关闭界面，取消当前操作，作用同点击Cancel按钮
            try
                updata_ModelInf(app.Callingapp,app.data);
                delete(app)
            catch
                delete(app);
            end
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            %获取模型信息
            %至少需要输入一项物理参数
            if app.ResidualDensityEditField.Value~=0||app.MagnetizationEditField.Value~=0
                %获取物理参数
                app.data.Inclination=app.MagneticInclinationEditField.Value;
                app.data.Declination=app.MagneticDeclinationEditField.Value;
                app.data.ResidualDensity=app.ResidualDensityEditField.Value;
                app.data.Magnetization=app.MagnetizationEditField.Value;
                app.data.Color=app.ColorDropDown.Value;
                app.data.Text=app.NameEditField.Value;
                %获取位置参数
                app.data.Position=app.point(:,1:3);
                if app.data.Magnetization~=0
                    mx=app.point(:,1);
                    my=app.point(:,2);
                    mz=app.point(:,3);
                    Nx=(app.ScopeData(1,2)-app.ScopeData(1,1))/app.ScopeData(1,7);
                    Ny=(app.ScopeData(1,4)-app.ScopeData(1,3))/app.ScopeData(1,8);
                    Nz=(app.ScopeData(1,6)-app.ScopeData(1,5))/app.ScopeData(1,9);
                    app.data.MagDivision=CubeSubdivision(mx,my,mz,app.ScopeData(1,7),app.ScopeData(1,8),app.ScopeData(1,9),Nx,Ny,Nz,app.data.Magnetization);
                end
                if app.data.ResidualDensity~=0
                    mx=app.point(:,1);
                    my=app.point(:,2);
                    mz=app.point(:,3);
                    Nx=(app.ScopeData(1,2)-app.ScopeData(1,1))/app.ScopeData(1,7);
                    Ny=(app.ScopeData(1,4)-app.ScopeData(1,3))/app.ScopeData(1,8);
                    Nz=(app.ScopeData(1,6)-app.ScopeData(1,5))/app.ScopeData(1,9);
                    app.data.DenDivision=CubeSubdivision(mx,my,mz,app.ScopeData(1,7),app.ScopeData(1,8),app.ScopeData(1,9),Nx,Ny,Nz,app.data.ResidualDensity);
                   
                end
                app.data.State=app.data.State+1;
                %将模型信息返回给建模模块
                updata_ModelInf(app.Callingapp,app.data);
                delete(app);
            else
                text="You have not set the Residual Density or Magnetic Magnetization.";
                app.dialogapp=Error(app,text);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %取消当前操作，返回建模界面
            updata_ModelInf(app.Callingapp,app.data);
            delete(app);
        end

        % Value changed function: P1EditField
        function P1EditFieldValueChanged(app, event)
            %获取1号顶点坐标
            app.point(1,1:3)=str2num(app.P1EditField.Value);
            app.point(1,4)=1;
            %如果选择了IntelligentInputCheckBox，系统会根据输入的顶点自己补充
            %其他顶点坐标
            if app.IntelligentInputCheckBox.Value
                %2、3、4号顶点与1号顶点同x坐标
                app.point(2,1)=app.point(1,1);
                app.point(3,1)=app.point(1,1);
                app.point(4,1)=app.point(1,1);
                app.P2EditField.Value=['[',num2str(app.point(2,1:3)),']'];
                app.P3EditField.Value=['[',num2str(app.point(3,1:3)),']'];
                app.P4EditField.Value=['[',num2str(app.point(4,1:3)),']'];

                %3、5、7号顶点与1号顶点同z坐标
                app.point(3,3)=app.point(1,3);
                app.point(5,3)=app.point(1,3);
                app.point(7,3)=app.point(1,3);
                app.P3EditField.Value=['[',num2str(app.point(3,1:3)),']'];
                app.P5EditField.Value=['[',num2str(app.point(5,1:3)),']'];
                app.P7EditField.Value=['[',num2str(app.point(7,1:3)),']'];

                %2、5、6号顶点与1号顶点同y坐标
                app.point(2,2)=app.point(1,2);
                app.point(5,2)=app.point(1,2);
                app.point(6,2)=app.point(1,2);
                app.P2EditField.Value=['[',num2str(app.point(2,1:3)),']'];
                app.P5EditField.Value=['[',num2str(app.point(5,1:3)),']'];
                app.P6EditField.Value=['[',num2str(app.point(6,1:3)),']'];
                app.Label.Text="Input the z coordinate of P2";
            end
            plotCube(app);
        end

        % Value changed function: P5EditField
        function P5EditFieldValueChanged(app, event)
            app.point(5,1:3)=str2num(app.P5EditField.Value);
            app.point(5,4)=1;
            if app.IntelligentInputCheckBox.Value

                %6、7、8号顶点与5号顶点同x坐标
                app.point(6,1)=app.point(5,1);
                app.point(7,1)=app.point(5,1);
                app.point(8,1)=app.point(5,1);
                app.P6EditField.Value=['[',num2str(app.point(6,1:3)),']'];
                app.P7EditField.Value=['[',num2str(app.point(7,1:3)),']'];
                app.P8EditField.Value=['[',num2str(app.point(8,1:3)),']'];
                app.Label.Text="Cancel intelligent input and further create";
                app.point(:,4)=1;
            end
            plotCube(app);
        end

        % Value changed function: P2EditField
        function P2EditFieldValueChanged(app, event)
            app.point(2,1:3)=str2num(app.P2EditField.Value);
            app.point(2,4)=1;
            if app.IntelligentInputCheckBox.Value

                %4、6、8号顶点与2号顶点同z坐标
                app.point(4,3)=app.point(2,3);
                app.point(6,3)=app.point(2,3);
                app.point(8,3)=app.point(2,3);
                app.P4EditField.Value=['[',num2str(app.point(4,1:3)),']'];
                app.P6EditField.Value=['[',num2str(app.point(6,1:3)),']'];
                app.P8EditField.Value=['[',num2str(app.point(8,1:3)),']'];
                app.Label.Text="Input the y coordinate of P3";
            end
            plotCube(app);
        end

        % Value changed function: P6EditField
        function P6EditFieldValueChanged(app, event)
            app.point(6,1:3)=str2num(app.P6EditField.Value);
            app.point(6,4)=1;
            plotCube(app);
        end

        % Value changed function: P3EditField
        function P3EditFieldValueChanged(app, event)
            app.point(3,1:3)=str2num(app.P3EditField.Value);
            app.point(3,4)=1;
            plotCube(app);
            if app.IntelligentInputCheckBox.Value
                %4、7、8号顶点与3号顶点同y坐标
                app.point(4,2)=app.point(3,2);
                app.point(7,2)=app.point(3,2);
                app.point(8,2)=app.point(3,2);
                app.P4EditField.Value=['[',num2str(app.point(4,1:3)),']'];
                app.P7EditField.Value=['[',num2str(app.point(7,1:3)),']'];
                app.P8EditField.Value=['[',num2str(app.point(8,1:3)),']'];
                app.Label.Text="Input the x coordinate of P5";
            end
        end

        % Value changed function: P4EditField
        function P4EditFieldValueChanged(app, event)
            app.point(4,1:3)=str2num(app.P4EditField.Value);
            app.point(4,4)=1;
            plotCube(app);
        end

        % Value changed function: P7EditField
        function P7EditFieldValueChanged(app, event)
            app.point(7,1:3)=str2num(app.P7EditField.Value);
            app.point(7,4)=1;
            plotCube(app);
        end

        % Value changed function: P8EditField
        function P8EditFieldValueChanged(app, event)
            app.point(8,1:3)=str2num(app.P8EditField.Value);
            app.point(8,4)=1;
            plotCube(app);
        end

        % Value changed function: IntelligentInputCheckBox
        function IntelligentInputCheckBoxValueChanged(app, event)
            if  app.IntelligentInputCheckBox.Value
                app.Label.Text="Input the coordinate of P1";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddCubeUIFigure = uifigure('Visible', 'off');
            app.AddCubeUIFigure.Name = 'Add Cube';
            app.AddCubeUIFigure.Icon = 'Cube.png';
            app.AddCubeUIFigure.CloseRequestFcn = createCallbackFcn(app, @AddCubeUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.AddCubeUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AmLabel = uilabel(app.AddCubeUIFigure);
            app.AmLabel.Text = 'A/m';
            app.ResidualDensityLabel = uilabel(app.AddCubeUIFigure);
            app.ResidualDensityLabel.Text = 'Residual Density';
            app.ResidualDensityEditField = uieditfield(app.AddCubeUIFigure, 'numeric');
            app.MagnetizationEditFieldLabel = uilabel(app.AddCubeUIFigure);
            app.MagnetizationEditFieldLabel.Text = 'Magnetization';
            app.MagnetizationEditField = uieditfield(app.AddCubeUIFigure, 'numeric');
            app.MagneticInclinationLabel = uilabel(app.AddCubeUIFigure);
            app.MagneticInclinationLabel.Text = 'Magnetic Inclination（°）';
            app.MagneticInclinationEditField = uieditfield(app.AddCubeUIFigure, 'numeric');
            app.MagneticDeclinationEditField = uieditfield(app.AddCubeUIFigure, 'numeric');
            app.MagneticDeclinationLabel = uilabel(app.AddCubeUIFigure);
            app.MagneticDeclinationLabel.Text = 'Magnetic Declination（°）';
            app.P1EditFieldLabel = uilabel(app.AddCubeUIFigure);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1EditField = uieditfield(app.AddCubeUIFigure, 'text');
            app.P1EditField.ValueChangedFcn = createCallbackFcn(app, @P1EditFieldValueChanged, true);
            app.P5EditFieldLabel = uilabel(app.AddCubeUIFigure);
            app.P5EditFieldLabel.Text = 'P5';
            app.P5EditField = uieditfield(app.AddCubeUIFigure, 'text');
            app.P5EditField.ValueChangedFcn = createCallbackFcn(app, @P5EditFieldValueChanged, true);
            app.P2EditFieldLabel = uilabel(app.AddCubeUIFigure);
            app.P2EditFieldLabel.Text = 'P2';
            app.P2EditField = uieditfield(app.AddCubeUIFigure, 'text');
            app.P2EditField.ValueChangedFcn = createCallbackFcn(app, @P2EditFieldValueChanged, true);
            app.P3EditFieldLabel = uilabel(app.AddCubeUIFigure);
            app.P3EditFieldLabel.Text = 'P3';
            app.P3EditField = uieditfield(app.AddCubeUIFigure, 'text');
            app.P3EditField.ValueChangedFcn = createCallbackFcn(app, @P3EditFieldValueChanged, true);
            app.P4EditFieldLabel = uilabel(app.AddCubeUIFigure);
            app.P4EditFieldLabel.Text = 'P4';
            app.P4EditField = uieditfield(app.AddCubeUIFigure, 'text');
            app.P4EditField.ValueChangedFcn = createCallbackFcn(app, @P4EditFieldValueChanged, true);
            app.P6EditFieldLabel = uilabel(app.AddCubeUIFigure);
            app.P6EditFieldLabel.Text = 'P6';
            app.P6EditField = uieditfield(app.AddCubeUIFigure, 'text');
            app.P6EditField.ValueChangedFcn = createCallbackFcn(app, @P6EditFieldValueChanged, true);
            app.P7EditFieldLabel = uilabel(app.AddCubeUIFigure);
            app.P7EditFieldLabel.Text = 'P7';
            app.P7EditField = uieditfield(app.AddCubeUIFigure, 'text');
            app.P7EditField.ValueChangedFcn = createCallbackFcn(app, @P7EditFieldValueChanged, true);
            app.P8EditFieldLabel = uilabel(app.AddCubeUIFigure);
            app.P8EditFieldLabel.Text = 'P8';
            app.P8EditField = uieditfield(app.AddCubeUIFigure, 'text');
            app.P8EditField.ValueChangedFcn = createCallbackFcn(app, @P8EditFieldValueChanged, true);
            app.Image = uiimage(app.AddCubeUIFigure);
            app.Image.ImageSource = 'CubeFixed.png';
            app.OkButton = uibutton(app.AddCubeUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.AddCubeUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.IntelligentInputCheckBox = uicheckbox(app.AddCubeUIFigure);
            app.IntelligentInputCheckBox.ValueChangedFcn = createCallbackFcn(app, @IntelligentInputCheckBoxValueChanged, true);
            app.IntelligentInputCheckBox.Text = 'Intelligent Input:';
            app.Label = uilabel(app.AddCubeUIFigure);
            app.Label.Text = '';
            app.ColorDropDownLabel = uilabel(app.AddCubeUIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.AddCubeUIFigure);
            app.ColorDropDown.Items = {'red', 'green', 'blue', 'yellow', 'magenta', 'white', 'cyan', 'black'};
            app.ColorDropDown.Value = 'red';
            app.NameEditFieldLabel = uilabel(app.AddCubeUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.AddCubeUIFigure, 'text');
            app.gcmLabel = uilabel(app.AddCubeUIFigure);
            app.gcmLabel.Text = 'g/cm³';
            % Show the figure after all components are created
            app.AddCubeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CubeParameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddCubeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddCubeUIFigure)
        end
    end
end