classdef CylinderParameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddCylinerUIFigure             matlab.ui.Figure
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        ColorDropDown                  matlab.ui.control.DropDown
        ColorDropDownLabel             matlab.ui.control.Label
        ExtensionEditField             matlab.ui.control.EditField
        ExtensionEditFieldLabel        matlab.ui.control.Label
        TrendDropDown                  matlab.ui.control.DropDown
        TrendDropDownLabel             matlab.ui.control.Label
        MagneticDeclinationLabel       matlab.ui.control.Label
        MagneticDeclinationEditField   matlab.ui.control.NumericEditField
        MagneticInclinationEditField   matlab.ui.control.NumericEditField
        MagneticInclinationLabel       matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        RadiusEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        P1EditField                    matlab.ui.control.EditField
        P1EditFieldLabel               matlab.ui.control.Label
        MagnetizationEditField         matlab.ui.control.NumericEditField
        MagnetizationEditFieldLabel    matlab.ui.control.Label
        ResidualDensityEditField       matlab.ui.control.NumericEditField
        ResidualDensityEditFieldLabel  matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        OkButton                       matlab.ui.control.Button
        AmLabel                        matlab.ui.control.Label
        gcmLabel                       matlab.ui.control.Label
    end


    properties (Access = private)
        Callingapp;
        data;
        odata;
        scope;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingapp, data, scope)
            MoveAppCenter(app.AddCylinerUIFigure);
            app.Callingapp=callingapp;
            app.data=data;
            app.odata=data;
            app.scope=scope;
            %导入初始模型信息
            %圆柱体走向
            app.TrendDropDown.Value=data.Trend;
            %圆柱体轴线范围
            app.ExtensionEditField.Value=['[',num2str(data.Extension),']'];
            %剖面圆圆心
            app.P1EditField.Value=['[',num2str(data.Position),']'];
            %剖面圆半径
            app.RadiusEditField.Value=data.Radius;

            app.ColorDropDown.Value=data.Color;
            app.NameEditField.Value=data.Text;
            %物理参数
            app.MagneticInclinationEditField.Value=data.Inclination;
            app.MagneticDeclinationEditField.Value=data.Declination;
            app.ResidualDensityEditField.Value=data.ResidualDensity;
            app.MagnetizationEditField.Value=data.Magnetization;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            %点击"Ok"按钮将数据传回建模模块
            if app.MagnetizationEditField.Value~=0||app.ResidualDensityEditField.Value~=0
                %至少输入一项物理参数
                %获取数据
                app.data.Inclination=app.MagneticInclinationEditField.Value;
                app.data.Declination=app.MagneticDeclinationEditField.Value;
                app.data.ResidualDensity=app.ResidualDensityEditField.Value;
                app.data.Magnetization=app.MagnetizationEditField.Value;
                app.data.Text=app.NameEditField.Value;
                app.data.Color=app.ColorDropDown.Value;
                app.data.State=app.data.State+1;
                if app.data.Magnetization~=0
                    Nx=(app.scope(1,2)-app.scope(1,1))/app.scope(1,7);
                    Ny=(app.scope(1,4)-app.scope(1,3))/app.scope(1,8);
                    Nz=(app.scope(1,6)-app.scope(1,5))/app.scope(1,9);
                    switch app.data.Trend
                        case 'X'
                            type=0;
                        case 'Y'
                            type=1;
                        case 'Z'
                            type=2;
                    end
                    app.data.MagDivision=CylinderSubdivision(app.data.Position(1,1),app.data.Position(1,2),app.data.Radius,app.data.Extension(1,1),app.data.Extension(1,2),app.data.Magnetization,app.scope(1,7),app.scope(1,8),app.scope(1,9),Nx,Ny,Nz,type);
                end
                if app.data.ResidualDensity~=0
                    Nx=(app.scope(1,2)-app.scope(1,1))/app.scope(1,7);
                    Ny=(app.scope(1,4)-app.scope(1,3))/app.scope(1,8);
                    Nz=(app.scope(1,6)-app.scope(1,5))/app.scope(1,9);
                    switch app.data.Trend
                        case 'X'
                            type=0;
                        case 'Y'
                            type=1;
                        case 'Z'
                            type=2;
                    end
                    app.data.DenDivision=CylinderSubdivision(app.data.Position(1,1),app.data.Position(1,2),app.data.Radius,app.data.Extension(1,1),app.data.Extension(1,2),app.data.ResidualDensity,app.scope(1,7),app.scope(1,8),app.scope(1,9),Nx,Ny,Nz,type);
                    a=app.data.DenDivision;
                    save("a.mat","a");
                end

                updata_ModelInf(app.Callingapp,app.data);
                delete(app);
            else
                text="You have not set the Residual Density or Magnetic Susceptibility.";
                app.diglogapp=Error(app,text);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %点击取消按钮，将原始数据返回建模界面
            updata_ModelInf(app.Callingapp,app.odata);
            delete(app);
        end

        % Close request function: AddCylinerUIFigure
        function AddCylinerUIFigureCloseRequest(app, event)
            %直接关闭窗口，作用同点击取消按钮
            try
                updata_ModelInf(app.Callingapp,app.odata);
                delete(app)
            catch
                delete(app);
            end

        end

        % Value changed function: ExtensionEditField
        function ExtensionEditFieldValueChanged(app, event)
            %判断使用者输入的圆柱体模型延伸范围是否在建模区域内

            Extension = str2num(app.ExtensionEditField.Value);
            if app.TrendDropDown.Value=='X'
                if Extension(1,1)<app.scope(1,1)||Extension(1,2)>app.scope(1,2)

                    Text='Model out of the Range';
                    app.dialogapp=Error(app,Text);
                else
                    app.data.Extension=Extension;
                end
            elseif app.TrendDropDown.Value=='Y'
                if Extension(1,1)<app.scope(1,3)||Extension(1,2)>app.scope(1,4)
                    Text='Model out of the Range';
                    app.dialogapp=Error(app,Text);
                else
                    app.data.Extension=Extension;
                end
            else
                if Extension(1,1)<app.scope(1,5)||Extension(1,2)>app.scope(1,6)
                    Text='Model out of the Range';
                    app.dialogapp=Error(app,Text);
                else
                    app.data.Extension=Extension;
                end
            end

        end

        % Value changed function: TrendDropDown
        function TrendDropDownValueChanged(app, event)
            app.data.Trend=app.TrendDropDown.Value;
        end

        % Value changed function: P1EditField
        function P1EditFieldValueChanged(app, event)
            %判断用户输入的截面圆心位置是否处于建模区域内
            Position= str2num(app.P1EditField.Value);
            if app.TrendDropDown.Value=='X'
                if  Position(1,1)<app.scope(1,3)|| Position(1,1)>app.scope(1,4)|| Position(1,2)<app.scope(1,5)|| Position(1,2)>app.scope(1,6)
                    Text='Model out of the Range';
                    app.dialogapp=Error(app,Text);
                else
                    app.data.Position=Position;
                end
            elseif app.TrendDropDown.Value=='Y'
                if Position(1,1)<app.scope(1,1)|| Position(1,1)>app.scope(1,2)|| Position(1,2)<app.scope(1,5)|| Position(1,2)>app.scope(1,6)
                    Text='Model out of the Range';
                    app.dialogapp=Error(app,Text);
                else
                    app.data.Position=Position;
                end
            else
                if Position(1,1)<app.scope(1,1)|| Position(1,1)>app.scope(1,2)|| Position(1,2)<app.scope(1,3)|| Position(1,2)>app.scope(1,4)
                    Text='Model out of the Range';
                    app.dialogapp=Error(app,Text);
                else
                    app.data.Position=Position;
                end
            end

        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            Radius = app.RadiusEditField.Value;
            Position= str2num(app.P1EditField.Value);
            if app.TrendDropDown.Value=='X'
                if  Position(1,1)-Radius<app.scope(1,3)|| Position(1,1)+Radius>app.scope(1,4)|| Position(1,2)-Radius<app.scope(1,5)|| Position(1,2)+Radius>app.scope(1,6)
                    Text='Model out of the Range';
                    app.dialogapp=Error(app,Text);
                else
                    app.data.Radius=Radius;
                end
            elseif app.TrendDropDown.Value=='Y'
                if Position(1,1)-Radius<app.scope(1,1)|| Position(1,1)+Radius>app.scope(1,2)|| Position(1,2)-Radius<app.scope(1,5)|| Position(1,2)+Radius>app.scope(1,6)
                    Text='Model out of the Range';
                    app.dialogapp=Error(app,Text);
                else
                    app.data.Radius=Radius;
                end
            else
                if Position(1,1)-Radius<app.scope(1,1)|| Position(1,1)+Radius>app.scope(1,2)|| Position(1,2)-Radius<app.scope(1,3)|| Position(1,2)+Radius>app.scope(1,4)
                    Text='Model out of the Range';
                    app.dialogapp=Error(app,Text);
                else
                    app.data.Radius=Radius;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddCylinerUIFigure = uifigure('Visible', 'off');
            app.AddCylinerUIFigure.Name = 'Add Cyliner';
            app.AddCylinerUIFigure.Icon = 'Cyliner.png';
            app.AddCylinerUIFigure.CloseRequestFcn = createCallbackFcn(app, @AddCylinerUIFigureCloseRequest, true);
            app.gcmLabel = uilabel(app.AddCylinerUIFigure);
            app.gcmLabel.Text = 'g/cm³';
            app.AmLabel = uilabel(app.AddCylinerUIFigure);
            app.AmLabel.Text = 'A/m';
            app.OkButton = uibutton(app.AddCylinerUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.AddCylinerUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ResidualDensityEditFieldLabel = uilabel(app.AddCylinerUIFigure);
            app.ResidualDensityEditFieldLabel.Text = 'Residual Density';
            app.ResidualDensityEditField = uieditfield(app.AddCylinerUIFigure, 'numeric');
            app.MagnetizationEditFieldLabel = uilabel(app.AddCylinerUIFigure);
            app.MagnetizationEditFieldLabel.Text = 'Magnetization';
            app.MagnetizationEditField = uieditfield(app.AddCylinerUIFigure, 'numeric');
            app.P1EditFieldLabel = uilabel(app.AddCylinerUIFigure);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1EditField = uieditfield(app.AddCylinerUIFigure, 'text');
            app.P1EditField.ValueChangedFcn = createCallbackFcn(app, @P1EditFieldValueChanged, true);
            app.RadiusEditFieldLabel = uilabel(app.AddCylinerUIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.AddCylinerUIFigure, 'numeric');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.Image = uiimage(app.AddCylinerUIFigure);
            app.Image.ImageSource = 'Cylinerprofile.png';
            app.MagneticInclinationLabel = uilabel(app.AddCylinerUIFigure);
            app.MagneticInclinationLabel.Text = 'Magnetic Inclination (°）';
            app.MagneticInclinationEditField = uieditfield(app.AddCylinerUIFigure, 'numeric');
            app.MagneticDeclinationEditField = uieditfield(app.AddCylinerUIFigure, 'numeric');
            app.MagneticDeclinationLabel = uilabel(app.AddCylinerUIFigure);
            app.MagneticDeclinationLabel.Text = 'Magnetic Declination（°）';
            app.TrendDropDownLabel = uilabel(app.AddCylinerUIFigure);
            app.TrendDropDownLabel.Text = 'Trend';
            app.TrendDropDown = uidropdown(app.AddCylinerUIFigure);
            app.TrendDropDown.Items = {'X', 'Y', 'Z'};
            app.TrendDropDown.ValueChangedFcn = createCallbackFcn(app, @TrendDropDownValueChanged, true);
            app.TrendDropDown.Value = 'X';
            app.ExtensionEditFieldLabel = uilabel(app.AddCylinerUIFigure);
            app.ExtensionEditFieldLabel.Text = 'Extension';
            app.ExtensionEditField = uieditfield(app.AddCylinerUIFigure, 'text');
            app.ExtensionEditField.ValueChangedFcn = createCallbackFcn(app, @ExtensionEditFieldValueChanged, true);
            app.ColorDropDownLabel = uilabel(app.AddCylinerUIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.AddCylinerUIFigure);
            app.ColorDropDown.Items = {'red', 'green', 'blue', 'yellow', 'magenta', 'white', 'cyan', 'black'};
            app.ColorDropDown.Value = 'red';
            app.NameEditFieldLabel = uilabel(app.AddCylinerUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.AddCylinerUIFigure, 'text');
            % Show the figure after all components are created
            app.AddCylinerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CylinderParameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddCylinerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddCylinerUIFigure)
        end
    end
end