classdef DrewSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DrawSetUIFigure         matlab.ui.Figure
        ColorBarDropDown        matlab.ui.control.DropDown
        ColorBarDropDownLabel   matlab.ui.control.Label
        CancelButton            matlab.ui.control.Button
        OKButton                matlab.ui.control.Button
        ShowTextDropDown        matlab.ui.control.DropDown
        ShowTextDropDownLabel   matlab.ui.control.Label
        LevelEditField          matlab.ui.control.NumericEditField
        LevelEditFieldLabel     matlab.ui.control.Label
        LineWidthEditField      matlab.ui.control.NumericEditField
        LineWidthLabel          matlab.ui.control.Label
        LineColorDropDown       matlab.ui.control.DropDown
        LineColorDropDownLabel  matlab.ui.control.Label
        LineStyleDropDown       matlab.ui.control.DropDown
        LineStyleDropDownLabel  matlab.ui.control.Label
        ColormapDropDown        matlab.ui.control.DropDown
        ColormapDropDownLabel   matlab.ui.control.Label
    end


    properties (Access = private)
        Callingapp;
        DrawProperties;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Callingapp, DrawProperties)
            MoveAppCenter(app.DrawSetUIFigure);
            %储存正演模块传来的信息
            app.Callingapp=Callingapp;
            app.DrawProperties=DrawProperties;

            %界面初始化
            app.ColormapDropDown.Value=DrawProperties.colormap;
            switch DrawProperties.linestyle
                case '-'
                    app.LineStyleDropDown.Value='Solid';
                case '--'
                    app.LineStyleDropDown.Value='Dashed';
                case ':'
                    app.LineStyleDropDown.Value='Dotted';
                case '-.'
                    app.LineStyleDropDown.Value='Dash-dot';
            end

            app.LineColorDropDown.Value=DrawProperties.linecolor;
            app.ShowTextDropDown.Value=DrawProperties.showtext;
            app.LevelEditField.Value=DrawProperties.level;
            app.LineWidthEditField.Value=DrawProperties.linewidth;
            app.ColorBarDropDown.Value=DrawProperties.colorbar;

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %将更改后的绘图属性传回正演界面
            app.DrawProperties.colormap=app.ColormapDropDown.Value;
            switch app.DrawProperties.linestyle
                case 'Solid'
                    app.DrawProperties.linestyle='-';
                case 'Dashed'
                    app.DrawProperties.linestyle='--';
                case 'Dotted'
                    app.DrawProperties.linestyle=':';
                case 'Dash-dot'
                    app.DrawProperties.linestyle='-.';
            end
            app.DrawProperties.linecolor=app.LineColorDropDown.Value;
            app.DrawProperties.showtext=app.ShowTextDropDown.Value;
            app.DrawProperties.level=app.LevelEditField.Value;
            app.DrawProperties.linewidth=app.LineWidthEditField.Value;
            app.DrawProperties.colorbar=app.ColorBarDropDown.Value;
            GetDrawSetting(app.Callingapp,app.DrawProperties,1)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %取消当前操作，返回 0 至正演模块
            GetDrawSetting(app.Callingapp,0,0)
            delete(app)
        end

        % Close request function: DrawSetUIFigure
        function DrawSetUIFigureCloseRequest(app, event)
            %取消当前操作，返回 0 至正演模块
            GetDrawSetting(app.Callingapp,0,0)
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DrawSetUIFigure = uifigure('Visible', 'off');
            app.DrawSetUIFigure.Name = 'DrawSet';
            app.DrawSetUIFigure.Icon = 'Setting.png';
            app.DrawSetUIFigure.CloseRequestFcn = createCallbackFcn(app, @DrawSetUIFigureCloseRequest, true);
            app.ColormapDropDownLabel = uilabel(app.DrawSetUIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.DrawSetUIFigure);
            app.ColormapDropDown.Items = {'jet', 'parula', 'turbo', 'hsv	', 'pink', 'copper', 'summer', 'spring', 'autumn', 'winter', 'cool', 'hot'};
            app.ColormapDropDown.Value = 'jet';
            app.LineStyleDropDownLabel = uilabel(app.DrawSetUIFigure);
            app.LineStyleDropDownLabel.Text = 'Line Style';
            app.LineStyleDropDown = uidropdown(app.DrawSetUIFigure);
            app.LineStyleDropDown.Items = {'Solid', 'Dashed', 'Dotted', 'Dash-dot'};
            app.LineStyleDropDown.Value = 'Solid';
            app.LineColorDropDownLabel = uilabel(app.DrawSetUIFigure);
            app.LineColorDropDownLabel.Text = 'Line Color';
            app.LineColorDropDown = uidropdown(app.DrawSetUIFigure);
            app.LineColorDropDown.Items = {'black', 'none', 'red', 'green', 'blue', 'yellow', 'magenta', 'white', 'cyan'};
            app.LineColorDropDown.Value = 'black';
            app.LineWidthLabel = uilabel(app.DrawSetUIFigure);
            app.LineWidthLabel.Text = 'Line Width';
            app.LineWidthEditField = uieditfield(app.DrawSetUIFigure, 'numeric');
            app.LineWidthEditField.Limits = [0 Inf];
            app.LineWidthEditField.Value = 0.5;
            app.LevelEditFieldLabel = uilabel(app.DrawSetUIFigure);
            app.LevelEditFieldLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.DrawSetUIFigure, 'numeric');
            app.LevelEditField.Limits = [2 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.Value = 12;
            app.ShowTextDropDownLabel = uilabel(app.DrawSetUIFigure);
            app.ShowTextDropDownLabel.Text = 'Show Text';
            app.ShowTextDropDown = uidropdown(app.DrawSetUIFigure);
            app.ShowTextDropDown.Items = {'off', 'on'};
            app.ShowTextDropDown.Value = 'off';
            app.OKButton = uibutton(app.DrawSetUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.DrawSetUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ColorBarDropDownLabel = uilabel(app.DrawSetUIFigure);
            app.ColorBarDropDownLabel.Text = 'Color Bar';
            app.ColorBarDropDown = uidropdown(app.DrawSetUIFigure);
            app.ColorBarDropDown.Items = {'on', 'off'};
            app.ColorBarDropDown.Value = 'on';
            % Show the figure after all components are created
            app.DrawSetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DrewSet(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DrawSetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DrawSetUIFigure)
        end
    end
end