classdef ForwardData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ForwardDataUIFigure  matlab.ui.Figure
        OutputButton         matlab.ui.control.Button
        UITable              matlab.ui.control.Table
    end


    properties (Access = private)
        Scope;
        dialogapp;
        dx;
        dy;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataname, data)
            MoveAppCenter(app.ForwardDataUIFigure);
            app.UITable.ColumnName=dataname;
            app.UITable.Data=data;
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)

            [filename,path]=uiputfile('*.txt','Save file name','ufilename');
            filepath=strcat(path,filename);
            fid=fopen(filepath,'wt');
            [a,b]=size(app.UITable.Data);
            for i=1:b
                fprintf(fid,'%s\t',cell2mat(app.UITable.ColumnName(i)));
            end
            fprintf(fid,'\r\n');
            for i=1:a
                for j=1:b
                    fprintf(fid,'%g\t',app.UITable.Data(i,j));
                end
                fprintf(fid,'\r\n');
            end
            fclose(fid);
            app.dialogapp=Error(app,"Saved Successfully!");
        end

        % Close request function: ForwardDataUIFigure
        function ForwardDataUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ForwardDataUIFigure = uifigure('Visible', 'off');
            app.ForwardDataUIFigure.Name = 'Forward Data';
            app.ForwardDataUIFigure.Icon = 'dataselect .png';
            app.ForwardDataUIFigure.CloseRequestFcn = createCallbackFcn(app, @ForwardDataUIFigureCloseRequest, true);
            app.UITable = uitable(app.ForwardDataUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.OutputButton = uibutton(app.ForwardDataUIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            % Show the figure after all components are created
            app.ForwardDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForwardData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ForwardDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ForwardDataUIFigure)
        end
    end
end