classdef GraFor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GRAFWDUIFigure               matlab.ui.Figure
        GaussianNoiseEditField       matlab.ui.control.NumericEditField
        GaussianNoiseEditFieldLabel  matlab.ui.control.Label
        ObservationHeightEditField   matlab.ui.control.NumericEditField
        ObservationHeightEditFieldLabel  matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        AllCheckBox                  matlab.ui.control.CheckBox
        SelectCategoryLabel          matlab.ui.control.Label
        VyyCheckBox                  matlab.ui.control.CheckBox
        VxyCheckBox                  matlab.ui.control.CheckBox
        VxzCheckBox                  matlab.ui.control.CheckBox
        VyzCheckBox                  matlab.ui.control.CheckBox
        VxxCheckBox                  matlab.ui.control.CheckBox
        VzzCheckBox                  matlab.ui.control.CheckBox
        TensorCheckBox               matlab.ui.control.CheckBox
        gCheckBox                    matlab.ui.control.CheckBox
        CancelButton                 matlab.ui.control.Button
        OkButton                     matlab.ui.control.Button
    end


    properties (Access = private)
        Callingapp;
        Items;
    end

    methods (Access = private)

        function CheckBoxChanged(app,items)
            if sum(items)==7
                app.AllCheckBox.Value=true;
            else
                app.AllCheckBox.Value=false;
            end
            if sum(items(2:7))==6
                app.TensorCheckBox.Value=true;
            else
                app.TensorCheckBox.Value=false;
            end
            if items(1,1)
                app.gCheckBox.Value=true;
            else
                app.gCheckBox.Value=false;
            end
            if items(1,2)
                app.VxxCheckBox.Value=true;
            else
                app.VxxCheckBox.Value=false;
            end
            if items(1,3)
                app.VxyCheckBox.Value=true;
            else
                app.VxyCheckBox.Value=false;
            end
            if items(1,4)
                app.VxzCheckBox.Value=true;
            else
                app.VxzCheckBox.Value=false;
            end
            if items(1,5)
                app.VyyCheckBox.Value=true;
            else
                app.VyyCheckBox.Value=false;
            end
            if items(1,6)
                app.VyzCheckBox.Value=true;
            else
                app.VyzCheckBox.Value=false;
            end
            if items(1,7)
                app.VzzCheckBox.Value=true;
            else
                app.VzzCheckBox.Value=false;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingapp, height, gauss, items)
            MoveAppCenter(app.GRAFWDUIFigure);
            app.ObservationHeightEditField.Value=height;
            app.GaussianNoiseEditField.Value=gauss;
            app.Items=items;
            CheckBoxChanged(app,items)
            app.Callingapp=callingapp;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)

            GetGraFWDPara(app.Callingapp,app.Items,app.ObservationHeightEditField.Value,app.GaussianNoiseEditField.Value,1)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            GetGraFWDPara(app.Callingapp,0,0,0,0);
            delete(app)
        end

        % Close request function: GRAFWDUIFigure
        function GRAFWDUIFigureCloseRequest(app, event)
            try
                GetGraFWDPara(app.Callingapp,0,0,0,0);
                delete(app)
            catch
                delete(app)
            end
        end

        % Value changed function: AllCheckBox
        function AllCheckBoxValueChanged(app, event)
            if app.AllCheckBox.Value
                app.Items(1,1:7)=[1 1 1 1 1 1 1];
                CheckBoxChanged(app,app.Items);
            else
                app.Items(1,1:7)=[0 0 0 0 0 0 0];
                CheckBoxChanged(app,app.Items);
            end

        end

        % Value changed function: gCheckBox
        function gCheckBoxValueChanged(app, event)
            if app.gCheckBox.Value
                app.Items(1,1)=1;
            else
                app.Items(1,1)=0;
            end

        end

        % Value changed function: TensorCheckBox
        function TensorCheckBoxValueChanged(app, event)
            if app.TensorCheckBox.Value
                app.Items(1,2:7)=[1 1 1 1 1 1];
                CheckBoxChanged(app,app.Items);
            else
                app.Items(1,2:7)=[0 0 0 0 0 0];
                CheckBoxChanged(app,app.Items);
            end

        end

        % Value changed function: VxxCheckBox
        function VxxCheckBoxValueChanged(app, event)
            if app.VxxCheckBox.Value
                app.Items(1,2)=1;
            else
                app.Items(1,2)=0;
            end

        end

        % Value changed function: VxyCheckBox
        function VxyCheckBoxValueChanged(app, event)
            if app.VxyCheckBox.Value
                app.Items(1,3)=1;
            else
                app.Items(1,3)=0;
            end

        end

        % Value changed function: VxzCheckBox
        function VxzCheckBoxValueChanged(app, event)
            if app.VxzCheckBox.Value
                app.Items(1,4)=1;
            else
                app.Items(1,4)=0;
            end

        end

        % Value changed function: VyyCheckBox
        function VyyCheckBoxValueChanged(app, event)
            if app.VyyCheckBox.Value
                app.Items(1,5)=1;
            else
                app.Items(1,5)=0;
            end

        end

        % Value changed function: VyzCheckBox
        function VyzCheckBoxValueChanged(app, event)
            if app.VyzCheckBox.Value
                app.Items(1,6)=1;
            else
                app.Items(1,6)=0;
            end

        end

        % Value changed function: VzzCheckBox
        function VzzCheckBoxValueChanged(app, event)
            if app.VzzCheckBox.Value
                app.Items(1,7)=1;
            else
                app.Items(1,7)=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GRAFWDUIFigure = uifigure('Visible', 'off');
            app.GRAFWDUIFigure.Name = 'GRA FWD';
            app.GRAFWDUIFigure.Icon = 'Setting.png';
            app.GRAFWDUIFigure.CloseRequestFcn = createCallbackFcn(app, @GRAFWDUIFigureCloseRequest, true);
            app.OkButton = uibutton(app.GRAFWDUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.GRAFWDUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GRAFWDUIFigure);
            app.gCheckBox = uicheckbox(app.Panel);
            app.gCheckBox.ValueChangedFcn = createCallbackFcn(app, @gCheckBoxValueChanged, true);
            app.gCheckBox.Text = '∆g';
            app.TensorCheckBox = uicheckbox(app.Panel);
            app.TensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @TensorCheckBoxValueChanged, true);
            app.TensorCheckBox.Text = 'Tensor';
            app.VzzCheckBox = uicheckbox(app.Panel);
            app.VzzCheckBox.ValueChangedFcn = createCallbackFcn(app, @VzzCheckBoxValueChanged, true);
            app.VzzCheckBox.Text = 'Vzz';
            app.VxxCheckBox = uicheckbox(app.Panel);
            app.VxxCheckBox.ValueChangedFcn = createCallbackFcn(app, @VxxCheckBoxValueChanged, true);
            app.VxxCheckBox.Text = 'Vxx';
            app.VyzCheckBox = uicheckbox(app.Panel);
            app.VyzCheckBox.ValueChangedFcn = createCallbackFcn(app, @VyzCheckBoxValueChanged, true);
            app.VyzCheckBox.Text = 'Vyz';
            app.VxzCheckBox = uicheckbox(app.Panel);
            app.VxzCheckBox.ValueChangedFcn = createCallbackFcn(app, @VxzCheckBoxValueChanged, true);
            app.VxzCheckBox.Text = 'Vxz';
            app.VxyCheckBox = uicheckbox(app.Panel);
            app.VxyCheckBox.ValueChangedFcn = createCallbackFcn(app, @VxyCheckBoxValueChanged, true);
            app.VxyCheckBox.Text = 'Vxy';
            app.VyyCheckBox = uicheckbox(app.Panel);
            app.VyyCheckBox.ValueChangedFcn = createCallbackFcn(app, @VyyCheckBoxValueChanged, true);
            app.VyyCheckBox.Text = 'Vyy';
            app.SelectCategoryLabel = uilabel(app.Panel);
            app.SelectCategoryLabel.Text = 'Select Category:';
            app.AllCheckBox = uicheckbox(app.Panel);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCheckBoxValueChanged, true);
            app.AllCheckBox.Text = 'All';
            app.ObservationHeightEditFieldLabel = uilabel(app.GRAFWDUIFigure);
            app.ObservationHeightEditFieldLabel.Text = 'Observation Height';
            app.ObservationHeightEditField = uieditfield(app.GRAFWDUIFigure, 'numeric');
            app.GaussianNoiseEditFieldLabel = uilabel(app.GRAFWDUIFigure);
            app.GaussianNoiseEditFieldLabel.Text = 'Gaussian Noise';
            app.GaussianNoiseEditField = uieditfield(app.GRAFWDUIFigure, 'numeric');
            % Show the figure after all components are created
            app.GRAFWDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraFor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GRAFWDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GRAFWDUIFigure)
        end
    end
end