classdef LayerBuilding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LayerBuildingUIFigure         matlab.ui.Figure
        PasteButton                   matlab.ui.control.Button
        CurrentStateLabel             matlab.ui.control.Label
        CurrentStateEditField         matlab.ui.control.EditField
        ClearButton                   matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        OkButton                      matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        MagnetizationEditField        matlab.ui.control.NumericEditField
        MagnetizationEditFieldLabel   matlab.ui.control.Label
        ResidualDensityEditField      matlab.ui.control.NumericEditField
        ResidualDensityLabel          matlab.ui.control.Label
        AmLabel                       matlab.ui.control.Label
        gcmLabel                      matlab.ui.control.Label
        MagneticDeclinationEditField  matlab.ui.control.NumericEditField
        MagneticDeclinationLabel      matlab.ui.control.Label
        MagneticInclinationEditField  matlab.ui.control.NumericEditField
        MagneticInclinationLabel      matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        NameEditField                 matlab.ui.control.EditField
        NameEditFieldLabel            matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        ColorDropDownLabel            matlab.ui.control.Label
        LayerSpinner                  matlab.ui.control.Spinner
        LayerSpinnerLabel             matlab.ui.control.Label
        AddModeDropDown               matlab.ui.control.DropDown
        AddModeDropDownLabel          matlab.ui.control.Label
        ProfileDropDown               matlab.ui.control.DropDown
        ProfileDropDownLabel          matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        RefreshMenu                   matlab.ui.container.Menu
    end


    properties (Access = private)
        Callingapp;
        data;
        space;
        drawsco;
        ProfilePos;
        buildprofile=1;
        buildmethod=4;%1:circular 2:rectangle 3:custom 4:point
        clickpoint=zeros(2,2);
        startdraw=0;%1:start draw 0:stop draw
        customareapos;
        newormodify;%0:new model 1:modify model
        profilecahe;
        unitnumber;
        uplayer=[];
        stop;
        diglogapp;
        origdata;
        Position3D
        Nx;
        Ny;
        Nz;
        ResidualDensity;
        Magnetization;
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingapp, data, Nx, Ny, Nz, space)
            MoveAppCenter(app.LayerBuildingUIFigure);
            app.Position3D=data.Position;
            app.ResidualDensity=data.DenDivision;            %导入数据
            app.Magnetization=data.MagDivision;
            app.origdata=data;                                   %储存原始数据
            app.space=space;
            app.Callingapp=callingapp;
            app.Nx=Nx;
            app.Ny=Ny;
            app.Nz=Nz;
            app.MagneticInclinationEditField.Value=data.Inclination;
            app.MagneticDeclinationEditField.Value=data.Declination;
            app.ResidualDensityEditField.Value=0;
            app.ColorDropDown.Value=data.Color;
            app.NameEditField.Value=data.Text;
            app.MagnetizationEditField.Value=0;

            %设置剖面坐标，默认为Z剖面
            xticks(app.UIAxes,0:1:Nx)
            yticks(app.UIAxes,0:1:Ny)
            xlim(app.UIAxes,[0 Nx]);
            ylim(app.UIAxes,[0 Ny]);
            XT=app.space(1,1):app.space(1,7):app.space(1,2);
            YT=app.space(1,3):app.space(1,8):app.space(1,4);
            ZT=app.space(1,5):app.space(1,9):app.space(1,6);
            %每五个刻度标注一次
            for i=1:1:Nx+1
                if rem(i-1,5)~=0
                    xTL{i}=" ";
                else
                    xTL{i}=num2str(XT(i));
                end
            end
            for i=1:1:Ny+1
                if rem(i-1,5)~=0
                    yTL{i}=" ";
                else
                    yTL{i}=num2str(YT(i));
                end
            end
            xticklabels(app.UIAxes,xTL')
            yticklabels(app.UIAxes,yTL')
            if space(1,10)==0
                xlabel(app.UIAxes,'X/m  (North)')
                ylabel(app.UIAxes,'Y/m  (East)')

                xlabel(app.UIAxes2,'X/m  (North)')
                ylabel(app.UIAxes2,'Y/m  (East)')
                zlabel(app.UIAxes2,'Depth/m')
            elseif space(1,10)==1
                xlabel(app.UIAxes,'X/km  (North)')
                ylabel(app.UIAxes,'Y/km  (East)')
                xlabel(app.UIAxes2,'X/km  (North)')
                ylabel(app.UIAxes2,'Y/km  (East)')
                zlabel(app.UIAxes2,'Depth/km')
            else
                xlabel(app.UIAxes,'X/hm  (North)')
                ylabel(app.UIAxes,'Y/hm  (East)')
                xlabel(app.UIAxes2,'X/hm  (North)')
                ylabel(app.UIAxes2,'Y/hm  (East)')
                zlabel(app.UIAxes2,'Depth/hm')
            end
            box(app.UIAxes,"on");
            grid(app.UIAxes,"on");

            %初始化三维坐标
            Box_Axis(app.UIAxes2,[0 Nx],[0 Ny],[0 Nz]);
            xticks(app.UIAxes2,0:1:Nx);
            yticks(app.UIAxes2,0:1:Ny);
            zticks(app.UIAxes2,0:1:Nz);
            xlim(app.UIAxes2,[0 Nx]);
            ylim(app.UIAxes2,[0 Ny]);
            zlim(app.UIAxes2,[0 Nz]);
            %每十个刻度标注一次
            for i=1:1:Nx+1
                if rem(i-1,10)~=0
                    xTL2{i}=" ";
                else
                    xTL2{i}=num2str(XT(i));
                end
            end
            for i=1:1:Ny+1
                if rem(i-1,10)~=0
                    yTL2{i}=" ";
                else
                    yTL2{i}=num2str(YT(i));
                end
            end
            for i=1:1:Nz+1
                if rem(i-1,10)~=0
                    zTL2{i}=" ";
                else
                    zTL2{i}=num2str(ZT(i));
                end
            end
            xticklabels(app.UIAxes2,xTL2')
            yticklabels(app.UIAxes2,yTL2')
            zticklabels(app.UIAxes2,zTL2')
            set(app.UIAxes2,'ZDir','reverse')
            view(app.UIAxes2,3);
            app.drawsco=[0 Nx 0 Ny 0 Nz 1 1 1];
            if data.State==2
                Plot_Irregular(app.UIAxes2,app.Position3D,app.Position3D,app.Nx,app.Ny,app.Nz,app.drawsco,app.ColorDropDown.Value);
            end
        end

        % Window button down function: LayerBuildingUIFigure
        function LayerBuildingUIFigureWindowButtonDown(app, event)
            pt=get(app.UIAxes,"currentpoint");
            a=app.UIAxes.XLim;
            b=app.UIAxes.YLim;
            %首先确定点击的点是否位于坐标区内
            if pt(1,1)>=a(1)&&pt(1,1)<=a(2)&&pt(1,2)>=b(1)&&pt(1,2)<=b(2)
                app.CurrentStateLabel.Text='Current Grid';
                app.CurrentStateEditField.Value=['[',num2str(fix(pt(1,1:2))),']'];
                %判断模式
                %如果是单击模式，则判断点击的点是否已经存在小单元体
                %如果未存在，新建一个单元体
                %如果存在，删除一个单元体
                if app.buildmethod==4

                    flag=0;
                    % Judge whether the coordinate already exists
                    for i=1:size(app.ProfilePos,1)
                        if app.ProfilePos(i,1)==fix(pt(1,1))&&app.ProfilePos(i,2)==fix(pt(1,2))
                            app.ProfilePos(i,:)=[];
                            flag=1;
                            break;
                        end
                    end
                    if flag==0
                        app.ProfilePos(size(app.ProfilePos,1)+1,1:2)=fix(pt(1,1:2));
                    end
                    %重新绘制该剖面上所有的单元体
                    cla(app.UIAxes);
                    for i=1:size(app.ProfilePos,1)
                        rectangle(app.UIAxes,"Position",[app.ProfilePos(i,1),app.ProfilePos(i,2),1,1],"FaceColor",'b');
                        hold(app.UIAxes,"on");
                    end
                else
                    %如果不是单击模式，则按下鼠标时，认为是使用者开始绘制异常体轮廓
                    %储存开始坐标在clickpoint内
                    %如果是freehand模式，则需要将所有坐标储存在customareapos内
                    %uplayer 储存当前的坐标作为副本，当切换到下一层时，可作为参考
                    app.startdraw=1;
                    app.clickpoint(1,1:2)=pt(1,1:2);
                    app.customareapos=fix(pt(1,1:2));
                    app.uplayer(1,1:2)=pt(1,1:2);
                end
            end
        end

        % Window button up function: LayerBuildingUIFigure
        function LayerBuildingUIFigureWindowButtonUp(app, event)
            %鼠标抬起，如果处于绘制阶段，则表示绘制结束
            %判断绘制模式，并自动填充
            if app.startdraw==1
                %绘制结束
                app.startdraw=0;
                cla(app.UIAxes)
                switch app.buildmethod
                    case 1
                        %圆形区域半径
                        pt2=fix(get(app.UIAxes,"currentpoint"));
                        pt1=fix(app.clickpoint(1,1:2));
                        r=fix(sqrt((pt1(1,1)-pt2(1,1))^2+(pt1(1,2)-pt2(1,2))^2));
                        %判断处于圆形区域内的网格，储存
                        for i=(pt1(1,1))-r:1:(pt1(1,1))+r
                            for j=(pt1(1,2))-r:1:(pt1(1,2))+r
                                if sqrt(((pt1(1,1))-i)^2+((pt1(1,2))-j)^2)<=r

                                    app.ProfilePos(size(app.ProfilePos,1)+1,1:2)=[i,j];
                                end
                            end
                        end
                        app.stop=[pt1;pt2(1,1:2)];
                    case 2
                        %矩形区域
                        pt2=fix(get(app.UIAxes,"currentpoint"));
                        pt1=fix(app.clickpoint(1,1:2));
                        if pt1(1,1)>pt2(1,1)
                            xmin=pt2(1,1);
                            xmax=pt1(1,1);
                        else
                            xmin=pt1(1,1);
                            xmax=pt2(1,1);
                        end
                        if pt1(1,2)>pt2(1,2)
                            ymin=pt2(1,2);
                            ymax=pt1(1,2);
                        else
                            ymin=pt1(1,2);
                            ymax=pt2(1,2);
                        end
                        for i=xmin:1:xmax
                            for j=ymin:1:ymax
                                app.ProfilePos(size(app.ProfilePos,1)+1,1:2)=[i,j];
                            end
                        end
                        app.stop=[xmin,xmax;ymin,ymax];
                    case 3
                        %不规则区域
                        a=min(app.customareapos(:,2));
                        b=max(app.customareapos(:,2));

                        for i=a:1:b
                            
                            index=find(app.customareapos(:,2)==i);
                            xarray=zeros(length(index),1);
                            for k=1:length(index)
                                xarray(k)=app.customareapos(index(k),1);
                            end
                            xmax=max(xarray);
                            xmin=min(xarray);
                            for j=xmin:1:xmax
                                app.ProfilePos(size(app.ProfilePos,1)+1,1:2)=[j,i];
                            end
                        end
                        app.stop=app.uplayer;
                        app.uplayer=[];
                end
                %显示自动填充的区域
                for i=1:size(app.ProfilePos,1)
                    rectangle(app.UIAxes,"Position",[app.ProfilePos(i,1),app.ProfilePos(i,2),1,1],"FaceColor",'b');
                    hold(app.UIAxes,"on")
                end

            end


        end

        % Window button motion function: LayerBuildingUIFigure
        function LayerBuildingUIFigureWindowButtonMotion(app, event)
            %按住鼠标移动
            %startdraw==1时，说明用户正在绘制异常体轮廓

            if app.startdraw==1
                pt=get(app.UIAxes,"currentpoint");
                %判断用户采用的绘制模式
                switch app.buildmethod
                    case 1
                        %用户采用圆形模式
                        cla(app.UIAxes)
                        %在CurrentState显示用户绘制的圆形区域的半径
                        %在坐标区显示用户绘制的圆形轮框
                        radius=sqrt((pt(1,1)-app.clickpoint(1,1))^2+(pt(1,2)-app.clickpoint(1,2))^2);
                        rectangle(app.UIAxes,'Position',[app.clickpoint(1,1)-radius,app.clickpoint(1,2)-radius,2*radius,2*radius],'Curvature',[1 1],"EdgeColor",'b',"LineWidth",1);
                        app.CurrentStateLabel.Text='Radius';
                        app.CurrentStateEditField.Value=num2str(radius);
                    case 2
                        %用户采用矩形模式
                        cla(app.UIAxes)
                        a=min(app.clickpoint(1,1),pt(1,1));
                        b=min(app.clickpoint(1,2),pt(1,2));
                        c=abs(app.clickpoint(1,1)-pt(1,1));
                        d=abs(app.clickpoint(1,2)-pt(1,2));
                        %在坐标区绘制矩形轮廓
                        rectangle(app.UIAxes,"Position",[a,b,c,d],"EdgeColor",'b',"LineWidth",1);
                        hold(app.UIAxes,"on");
                        %绘制参考线，辅助用户绘图
                        %会有点碍眼？
                        %改用长度标注的方式
                        %                         plot(app.UIAxes,[pt(1,1),pt(1,1)],[0,pt(1,2)],"LineWidth",0.5,"Color",'r',"LineStyle","--");
                        %                         plot(app.UIAxes,[0,pt(1,1)],[pt(1,2),pt(1,2)],"LineWidth",0.5,"Color",'r',"LineStyle","--");
                        %在CurrentState显示当前矩形的长与宽
                        app.CurrentStateLabel.Text='[Length Width]:';
                        app.CurrentStateEditField.Value=['[',num2str([c,d]),']'];
                    case 3
                        %用户采用freehand模式
                        app.clickpoint(2,1:2)=pt(1,1:2);
                        %将每一点坐标储存到uplayer数组中做备份
                        app.uplayer(end+1,1:2)=pt(1,1:2);
                        %在坐标区显示绘制的轮廓
                        line(app.UIAxes,app.clickpoint(:,1)',app.clickpoint(:,2)','LineWidth',1,'color','b')
                        app.clickpoint(1,1:2)=app.clickpoint(2,1:2);
                        %显示当前坐标
                        app.CurrentStateLabel.Text='Current Grid';
                        app.CurrentStateEditField.Value=['[',num2str(round(pt(1,1:2))),']'];
                        %由于电脑性能不同，在移动鼠标时，获取的当前点坐标数量不同
                        %对于性能比较差的电脑，在快速移动鼠标时，可能隔几个网格才获取一个当前坐标
                        %而进行小立方体的填充时，需要范围内每一行，每一列至少需要一个点坐标
                        %因此需要进行插值
                        %对于性能好的电脑，鼠标扫过一个网格是，会获取多个当前点坐标，只需要保存一个
                        flag=0;
                        %判断当前点是否已经储存
                        for i=1:size(app.customareapos,1)
                            if app.customareapos(i,1)==round(pt(1,1))&&app.customareapos(i,2)==round(pt(1,2))
                                flag=1;
                            end
                        end
                        %将未储存的点存入customareapos
                        %对稀疏区域进行插值
                        if flag==0
                            fronty=app.customareapos(end,2);
                            xl=(fix(pt(1,1))-app.customareapos(end,1))/abs((fix(pt(1,2))-fronty));
                            count=0;
                            if fix(pt(1,2))>fronty
                                for i=fronty+1:1:fix(pt(1,2))
                                    count=count+1;
                                    app.customareapos(end+1,1:2)=[app.customareapos(end,1)+round(count*xl),i];
                                end
                            elseif fix(pt(1,2))<fronty
                                for i=fix(pt(1,2)):1:fronty-1
                                    app.customareapos(end+1,1:2)=[fix(pt(1,1))+round(count*xl),i];
                                    count=count+1;
                                end
                            else
                                app.customareapos(end+1,1:2)=fix(pt(1,1:2));
                            end

                        end

                end
            end
        end

        % Value changed function: AddModeDropDown
        function AddModeDropDownValueChanged(app, event)
            %判断绘制模式
            value = app.AddModeDropDown.Value;
            switch value
                case 'Circular area'
                    app.buildmethod=1;
                case 'Rectangle area'
                    app.buildmethod=2;
                case 'Freehand'
                    app.buildmethod=3;
                case 'Single Point'
                    app.buildmethod=4;
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            %用户主动清屏
            cla(app.UIAxes);
            app.ProfilePos=[];
        end

        % Value changed function: ProfileDropDown
        function ProfileDropDownValueChanged(app, event)
            value = app.ProfileDropDown.Value;
            if isempty(app.ProfilePos)
                %切换坐标时，我们需要用户先将当前剖面清空
                switch value
                    case 'Z Profile'
                        app.buildprofile=1;
                        xticks(app.UIAxes,0:1:app.Nx)
                        yticks(app.UIAxes,0:1:app.Ny)
                        xlim(app.UIAxes,[0 app.Nx]);
                        ylim(app.UIAxes,[0 app.Ny]);
                        XT=app.space(1,1):app.space(1,7):app.space(1,2);
                        YT=app.space(1,3):app.space(1,8):app.space(1,4);
                        %每五个刻度标注一次
                        for i=1:1:app.Nx+1
                            if rem(i-1,5)~=0
                                xTL{i}=" ";
                            else
                                xTL{i}=num2str(XT(i));
                            end
                        end
                        for i=1:1:app.Ny+1
                            if rem(i-1,5)~=0
                                yTL{i}=" ";
                            else
                                yTL{i}=num2str(YT(i));
                            end
                        end
                        xticklabels(app.UIAxes,xTL')
                        yticklabels(app.UIAxes,yTL')
                        if app.space(1,10)==0
                            xlabel(app.UIAxes,'X/m  (North)')
                            ylabel(app.UIAxes,'Y/m  (East)')
                        elseif app.space(1,10)==1
                            xlabel(app.UIAxes,'X/km  (North)')
                            ylabel(app.UIAxes,'Y/km  (East)')
                        else
                            xlabel(app.UIAxes,'X/hm  (North)')
                            ylabel(app.UIAxes,'Y/hm  (East)')
                        end
                    case 'X Profile'
                        app.buildprofile=2;
                        xticks(app.UIAxes,0:1:app.Ny)
                        yticks(app.UIAxes,0:1:app.Nz)
                        xlim(app.UIAxes,[0 app.Ny]);
                        ylim(app.UIAxes,[0 app.Nz]);
                     
                        YT=app.space(1,5):app.space(1,9):app.space(1,6);
                        XT=app.space(1,3):app.space(1,8):app.space(1,4);
                        for i=1:1:app.Ny+1
                            if rem(i-1,5)~=0
                                xTL{i}=" ";
                            else
                                xTL{i}=num2str(XT(i));
                            end
                        end
                        for i=1:1:app.Nz+1
                            if rem(i-1,5)~=0
                                yTL{i}=" ";
                            else
                                yTL{i}=num2str(YT(i));
                            end
                        end
                        xticklabels(app.UIAxes,xTL')
                        yticklabels(app.UIAxes,yTL')
                        if app.space(1,10)==0
                            xlabel(app.UIAxes,'Y/m  (East)')
                            ylabel(app.UIAxes,'Depth/m')
                        elseif app.space(1,10)==1
                            xlabel(app.UIAxes,'Y/km  (East)')
                            ylabel(app.UIAxes,'Depth/km')
                        else
                            xlabel(app.UIAxes,'Y/hm  (East)')
                            ylabel(app.UIAxes,'Depth/hm')
                        end
                        set(app.UIAxes,'YDir','reverse')
                    case 'Y Profile'
                        app.buildprofile=3;
                        xticks(app.UIAxes,0:1:app.Nx)
                        yticks(app.UIAxes,0:1:app.Nz)
                        xlim(app.UIAxes,[0 app.Nx]);
                        ylim(app.UIAxes,[0 app.Nz]);
                        XT= (app.space(1,1):app.space(1,7):app.space(1,2));
                        YT=(app.space(1,5):app.space(1,9):app.space(1,6));
                        for i=1:1:app.Nx+1
                            if rem(i-1,5)~=0
                                xTL{i}=" ";
                            else
                                xTL{i}=num2str(XT(i));
                            end
                        end
                        for i=1:1:app.Nz+1
                            if rem(i-1,5)~=0
                                yTL{i}=" ";
                            else
                                yTL{i}=num2str(YT(i));
                            end
                        end
                        xticklabels(app.UIAxes,xTL')
                        yticklabels(app.UIAxes,yTL')
                        if app.space(1,10)==0
                            xlabel(app.UIAxes,'X/m  (North)')
                            ylabel(app.UIAxes,'Depth/m')
                        elseif app.space(1,10)==1
                            xlabel(app.UIAxes,'X/km  (North)')
                            ylabel(app.UIAxes,'Depth/km')
                        else
                            xlabel(app.UIAxes,'X/hm  (North)')
                            ylabel(app.UIAxes,'Depth/hm')
                        end
                        set(app.UIAxes,'YDir','reverse')
                end
            else
                text="There is still data has not been saved.Changing the creation method will clear the data.If you want to give up this part of data,please click the 'clear' betton.";
                app.diglogapp=Error(app,text);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %保存剖面数据
            layer=app.LayerSpinner.Value;
            switch app.buildprofile
                %判断剖面
                %将剖面数据存入三维矩阵
                case 1
                    if app.MagnetizationEditField.Value~=0||app.ResidualDensityEditField.Value~=0
                        if app.ResidualDensityEditField.Value~=0
                            for i=1:size(app.ProfilePos,1)
                                if app.ProfilePos(i,1)>app.space(1,1)&&app.ProfilePos(i,1)<app.space(1,2)&&app.ProfilePos(i,2)>app.space(1,3)&&app.ProfilePos(i,2)<app.space(1,4)                                 
                                    app.Position3D(app.ProfilePos(i,1)+1,app.ProfilePos(i,2)+1,layer)=1;
                                    app.ResidualDensity(app.ProfilePos(i,1)+1,app.ProfilePos(i,2)+1,layer)=app.ResidualDensityEditField.Value;
                                end
                            end
                        end
                        if app.MagnetizationEditField.Value~=0
                            for i=1:size(app.ProfilePos,1)
                                if app.ProfilePos(i,1)>app.space(1,1)&&app.ProfilePos(i,1)<app.space(1,2)&&app.ProfilePos(i,2)>app.space(1,3)&&app.ProfilePos(i,2)<app.space(1,4)
                                    app.Position3D(app.ProfilePos(i,1)+1,app.ProfilePos(i,2)+1,layer)=1;
                                    app.Magnetization(app.ProfilePos(i,1)+1,app.ProfilePos(i,2)+1,layer)=app.MagnetizationEditField.Value;
                                end
                            end
                        end
                        cla(app.UIAxes);
                        %缓存当前剖面数据
                        app.profilecahe=app.ProfilePos;
                        app.ProfilePos=[];
                    else
                        text="You have not set the Residual Density or Magnetization in this layer.";
                        app.diglogapp=Error(app,text);
                    end

                case 2
                    if app.MagnetizationEditField.Value~=0||app.ResidualDensityEditField.Value~=0
                        if app.ResidualDensityEditField.Value~=0
                            for i=1:size(app.ProfilePos,1)
                                if app.ProfilePos(i,1)>app.space(1,3)&&app.ProfilePos(i,1)<app.space(1,4)&&app.ProfilePos(i,2)>app.space(1,5)&&app.ProfilePos(i,2)<app.space(1,6)
                                    app.Position3D(layer,app.ProfilePos(i,1)+1,app.ProfilePos(i,2)+1)=1;
                                    app.ResidualDensity(layer,app.ProfilePos(i,1)+1,app.ProfilePos(i,2)+1)=app.ResidualDensityEditField.Value;
                                end
                            end
                        end
                        if app.MagnetizationEditField.Value~=0
                            for i=1:size(app.ProfilePos,1)
                                if app.ProfilePos(i,1)>app.space(1,3)&&app.ProfilePos(i,1)<app.space(1,4)&&app.ProfilePos(i,2)>app.space(1,5)&&app.ProfilePos(i,2)<app.space(1,6)
                                    app.Position3D(layer,app.ProfilePos(i,1)+1,app.ProfilePos(i,2)+1)=1;
                                    app.Magnetization(layer,app.ProfilePos(i,1)+1,app.ProfilePos(i,2)+1)=app.MagnetizationEditField.Value;
                                end
                            end
                        end
                        cla(app.UIAxes);
                        %缓存当前剖面数据
                        app.profilecahe=app.ProfilePos;
                        app.ProfilePos=[];
                    else
                        text="You have not set the Residual Density or Magnetization in this layer.";
                        app.diglogapp=Error(app,text);
                    end
                case 3
                    if app.MagnetizationEditField.Value~=0||app.ResidualDensityEditField.Value~=0
                        if app.ResidualDensityEditField.Value~=0
                            for i=1:size(app.ProfilePos,1)
                                if app.ProfilePos(i,1)>app.space(1,1)&&app.ProfilePos(i,1)<app.space(1,2)&&app.ProfilePos(i,2)>app.space(1,5)&&app.ProfilePos(i,2)<app.space(1,6)
                                    app.Position3D(app.ProfilePos(i,1)+1,layer,app.ProfilePos(i,2)+1)=1;
                                    app.ResidualDensity(app.ProfilePos(i,1)+1,layer,app.ProfilePos(i,2)+1)=app.ResidualDensityEditField.Value;
                                end
                            end
                        end
                        if app.MagnetizationEditField.Value~=0
                            for i=1:size(app.ProfilePos,1)
                                if app.ProfilePos(i,1)>app.space(1,1)&&app.ProfilePos(i,1)<app.space(1,2)&&app.ProfilePos(i,2)>app.space(1,5)&&app.ProfilePos(i,2)<app.space(1,6)
                                    app.Position3D(app.ProfilePos(i,1)+1,layer,app.ProfilePos(i,2)+1)=1;
                                app.Magnetization(app.ProfilePos(i,1)+1,layer,app.ProfilePos(i,2)+1)=app.MagnetizationEditField.Value;
                                end
                            end
                        end
                        cla(app.UIAxes);
                        %缓存当前剖面数据
                        app.profilecahe=app.ProfilePos;
                        app.ProfilePos=[];
                    else
                        text="You have not set the Residual Density or Magnetization in this layer.";
                        app.diglogapp=Error(app,text);
                    end

            end


        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            if any(any(any(app.ResidualDensity)))
                app.origdata.DenDivision=app.ResidualDensity;
                app.origdata.ResidualDensity=1;
            end
            if any(any(any(app.Magnetization)))
                app.origdata.MagDivision=app.Magnetization;
                app.origdata.Magnetization=1;
            end
            if app.origdata.Magnetization==1||app.origdata.ResidualDensity==1

                app.origdata.Position=app.Position3D;
                app.origdata.Declination=app.MagneticDeclinationEditField.Value;
                app.origdata.Inclination=app.MagneticInclinationEditField.Value;
                app.origdata.Color=app.ColorDropDown.Value;
                app.origdata.Text=app.NameEditField.Value;
                app.origdata.State=app.origdata.State+1;
                updata_ModelInf(app.Callingapp,app.origdata);
                delete(app);
            else
                text='You had not built any model';
                app.diglogapp=Error(app,text);
            end


        end

        % Value changed function: LayerSpinner
        function LayerSpinnerValueChanged(app, event)
            value = app.LayerSpinner.Value;
            flag=0;
            switch app.buildprofile
                case 1
                    if value>app.Nz||value<1
                        flag=1;
                        scope=['It should be between 1',' and ',num2str(app.Nz),'.'];
                        text=['The layer you entered is out of range,',scope];
                        app.diglogapp=Error(app,text);
                    end
                case 2
                    if value>app.Nx||value<1
                        flag=1;
                        scope=['It should be between 1',' and ',num2str(app.Nx),'.'];
                        text=['The layer you entered is out of range',scope];
                        app.diglogapp=Error(app,text);
                    end
                case 3
                    if value>app.Ny||value<1
                        flag=1;
                        scope=['It should be between 1',' and ',num2str(app.Ny),'.'];
                        text=['The layer you entered is out of range,',scope];
                        app.diglogapp=Error(app,text);
                    end
            end

            %绘制上一层轮廓
            if flag==0
                if isempty(app.stop)==0
                    if app.buildmethod==3
                        for i=1:size(app.stop,1)-1
                            Clickpoint(1,1:2)=app.stop(i,1:2);
                            Clickpoint(2,1:2)=app.stop(i+1,1:2);
                            line(app.UIAxes,Clickpoint(:,1)',Clickpoint(:,2)','LineWidth',1,'color','r')
                            hold(app.UIAxes,"on");
                        end
                        Clickpoint(1,1:2)=app.stop(end,1:2);
                        Clickpoint(2,1:2)=app.stop(1,1:2);
                        line(app.UIAxes,Clickpoint(:,1)',Clickpoint(:,2)','LineWidth',1,'color','r');
                        hold(app.UIAxes,"on");
                    elseif app.buildmethod==1
                        radius=sqrt((app.space(1,7)*app.stop(2,1)-app.space(1,7)*app.stop(1,1))^2+(app.space(1,7)*app.stop(2,2)-app.space(1,7)*app.stop(1,2))^2);
                        rectangle(app.UIAxes,'Position',[app.stop(1,1)-radius,app.stop(1,2)-radius,2*radius,2*radius],'Curvature',[1 1],"EdgeColor",'r',"LineWidth",1);
                    elseif app.buildmethod==2
                        a=app.stop(1,1);
                        b=app.stop(2,1);
                        c=app.stop(1,2)-app.stop(1,1);
                        d=app.stop(2,2)-app.stop(2,1);
                        rectangle(app.UIAxes,"Position",[a,b,c,d],"EdgeColor",'r',"LineWidth",1);
                    end
                end
                count=0;
                switch app.buildprofile
                    case 1
                        for ix=1:app.Nx
                            for iy=1:app.Ny
                                if app.Position3D(ix,iy,value)~=0
                                    count=count+1;
                                    app.ProfilePos(count,1:2)=[ix-1,iy-1];
                                end
                            end
                        end
                        app.Position3D(:,:,value)=0;
                    case 2
                        for ix=1:app.Ny
                            for iy=1:app.Nz
                                if app.Position3D(value,ix,iy)~=0
                                    count=count+1;
                                    app.ProfilePos(count,1:2)=[ix-1,iy-1];
                                end
                            end
                        end
                        app.Position3D(value,:,:)=0;
                    case 3
                        for ix=1:app.Nx
                            for iy=1:app.Nz
                                if app.Position3D(ix,value,iy)~=0
                                    count=count+1;
                                    app.ProfilePos(count,1:2)=[ix-1,iy-1];
                                end
                            end
                        end
                        app.Position3D(:,value,:)=0;
                end
                for i=1:size(app.ProfilePos,1)
                    rectangle(app.UIAxes,"Position",[app.ProfilePos(i,1),app.ProfilePos(i,2),1,1],"FaceColor",'b');
                    hold(app.UIAxes,"on")
                end
            end
        end

        % Button pushed function: PasteButton
        function PasteButtonPushed(app, event)
            app.ProfilePos=[app.ProfilePos;app.profilecahe];
            cla(app.UIAxes);
            for i=1:size(app.ProfilePos,1)
                rectangle(app.UIAxes,"Position",[app.ProfilePos(i,1),app.ProfilePos(i,2),1,1],"FaceColor",'b');
                hold(app.UIAxes,"on")
            end
        end

        % Callback function
        function RefrashMenuSelected(app, event)
            cla(app.UIAxes2);
            facet=[1 2 4 3;1 3 7 5;1 2 6 5;2 4 8 6;3 4 8 7;5 6 8 7;];
            for i=1:size(app.data,1)
                modeldata=CenterAndSize2Fixed(app.data(i,8:10),[1 1 1]);
                patch(app.UIAxes2,"Faces",facet,"Vertices",modeldata,'facecolor','b');
                hold(app.UIAxes2,"on");
            end
            Box_Axis(app.UIAxes2,app.space(1,1:2),app.space(1,3:4),app.space(1,5:6));
        end

        % Close request function: LayerBuildingUIFigure
        function LayerBuildingUIFigureCloseRequest(app, event)
          try
            updata_ModelInf(app.Callingapp,app.origdata);
            delete(app)
          catch
              delete(app)
          end
        end

        % Menu selected function: RefreshMenu
        function RefreshMenuSelected(app, event)
            cla(app.UIAxes2)

            Box_Axis(app.UIAxes2,[0 app.Nx],[0 app.Ny],[0 app.Nz]);
            Plot_Irregular(app.UIAxes2,app.Position3D,app.Position3D,app.Nx,app.Ny,app.Nz,app.drawsco,app.ColorDropDown.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LayerBuildingUIFigure = uifigure('Visible', 'off');
            app.LayerBuildingUIFigure.Name = 'LayerBuilding';
            app.LayerBuildingUIFigure.Icon = 'Irregular.png';
            app.LayerBuildingUIFigure.CloseRequestFcn = createCallbackFcn(app, @LayerBuildingUIFigureCloseRequest, true);
            app.LayerBuildingUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @LayerBuildingUIFigureWindowButtonDown, true);
            app.LayerBuildingUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @LayerBuildingUIFigureWindowButtonUp, true);
            app.LayerBuildingUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @LayerBuildingUIFigureWindowButtonMotion, true);
            app.UIAxes = uiaxes(app.LayerBuildingUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes2 = uiaxes(app.LayerBuildingUIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel = uipanel(app.LayerBuildingUIFigure);
            app.ProfileDropDownLabel = uilabel(app.Panel);
            app.ProfileDropDownLabel.Text = 'Profile';
            app.ProfileDropDown = uidropdown(app.Panel);
            app.ProfileDropDown.Items = {'Z Profile', 'X Profile', 'Y Profile'};
            app.ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileDropDownValueChanged, true);
            app.ProfileDropDown.Value = 'Z Profile';
            app.AddModeDropDownLabel = uilabel(app.Panel);
            app.AddModeDropDownLabel.Text = 'Add Mode';
            app.AddModeDropDown = uidropdown(app.Panel);
            app.AddModeDropDown.Items = {'Circular area', 'Rectangle area', 'Freehand', 'Single Point'};
            app.AddModeDropDown.ValueChangedFcn = createCallbackFcn(app, @AddModeDropDownValueChanged, true);
            app.AddModeDropDown.Value = 'Single Point';
            app.LayerSpinnerLabel = uilabel(app.Panel);
            app.LayerSpinnerLabel.Text = 'Layer';
            app.LayerSpinner = uispinner(app.Panel);
            app.LayerSpinner.Limits = [1 Inf];
            app.LayerSpinner.ValueDisplayFormat = '%.0f';
            app.LayerSpinner.ValueChangedFcn = createCallbackFcn(app, @LayerSpinnerValueChanged, true);
            app.LayerSpinner.Value = 1;
            app.ColorDropDownLabel = uilabel(app.Panel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.Panel);
            app.ColorDropDown.Items = {'red', 'green', 'blue', 'yellow', 'magenta', 'white', 'cyan', 'black'};
            app.ColorDropDown.Value = 'red';
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.LayerBuildingUIFigure);
            app.MagneticInclinationLabel = uilabel(app.Panel_2);
            app.MagneticInclinationLabel.Text = 'Magnetic Inclination（°）';
            app.MagneticInclinationEditField = uieditfield(app.Panel_2, 'numeric');
            app.MagneticDeclinationLabel = uilabel(app.Panel_2);
            app.MagneticDeclinationLabel.Text = 'Magnetic Declination（°）';
            app.MagneticDeclinationEditField = uieditfield(app.Panel_2, 'numeric');
            app.gcmLabel = uilabel(app.Panel_2);
            app.gcmLabel.Text = 'g/cm³';
            app.AmLabel = uilabel(app.Panel_2);
            app.AmLabel.Text = 'A/m';
            app.ResidualDensityLabel = uilabel(app.Panel_2);
            app.ResidualDensityLabel.Text = 'Residual Density';
            app.ResidualDensityEditField = uieditfield(app.Panel_2, 'numeric');
            app.MagnetizationEditFieldLabel = uilabel(app.Panel_2);
            app.MagnetizationEditFieldLabel.Text = 'Magnetization';
            app.MagnetizationEditField = uieditfield(app.Panel_2, 'numeric');
            app.CancelButton = uibutton(app.Panel_2, 'push');
            app.CancelButton.Text = 'Cancel';
            app.OkButton = uibutton(app.Panel_2, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.SaveButton = uibutton(app.LayerBuildingUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ClearButton = uibutton(app.LayerBuildingUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CurrentStateEditField = uieditfield(app.LayerBuildingUIFigure, 'text');
            app.CurrentStateLabel = uilabel(app.LayerBuildingUIFigure);
            app.CurrentStateLabel.Text = 'Current State';
            app.PasteButton = uibutton(app.LayerBuildingUIFigure, 'push');
            app.PasteButton.ButtonPushedFcn = createCallbackFcn(app, @PasteButtonPushed, true);
            app.PasteButton.Text = 'Paste';
            app.ContextMenu = uicontextmenu(app.LayerBuildingUIFigure);
            app.RefreshMenu = uimenu(app.ContextMenu);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            % Assign app.ContextMenu
            app.UIAxes2.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.LayerBuildingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LayerBuilding(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LayerBuildingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LayerBuildingUIFigure)
        end
    end
end