classdef MagFor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MagFWDUIFigure                matlab.ui.Figure
        MagInclinationEditField       matlab.ui.control.NumericEditField
        MagInclinationEditFieldLabel  matlab.ui.control.Label
        MagDeclinationEditField       matlab.ui.control.NumericEditField
        MagDeclinationEditFieldLabel  matlab.ui.control.Label
        GaussianNoiseEditField        matlab.ui.control.NumericEditField
        GaussianNoiseEditFieldLabel   matlab.ui.control.Label
        ObservationHeightEditField    matlab.ui.control.NumericEditField
        ObservationHeightEditFieldLabel  matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        TzCheckBox                    matlab.ui.control.CheckBox
        TyCheckBox                    matlab.ui.control.CheckBox
        TxCheckBox                    matlab.ui.control.CheckBox
        GradientCheckBox              matlab.ui.control.CheckBox
        ByyCheckBox_2                 matlab.ui.control.CheckBox
        BxxCheckBox_2                 matlab.ui.control.CheckBox
        BzzCheckBox_2                 matlab.ui.control.CheckBox
        AllCheckBox                   matlab.ui.control.CheckBox
        SelectCategoryLabel           matlab.ui.control.Label
        HayCheckBox                   matlab.ui.control.CheckBox
        BxyCheckBox                   matlab.ui.control.CheckBox
        BxzCheckBox                   matlab.ui.control.CheckBox
        ByzCheckBox                   matlab.ui.control.CheckBox
        HaxCheckBox                   matlab.ui.control.CheckBox
        ZaCheckBox                    matlab.ui.control.CheckBox
        TensorCheckBox                matlab.ui.control.CheckBox
        TCheckBox                     matlab.ui.control.CheckBox
        CancelButton                  matlab.ui.control.Button
        OkButton                      matlab.ui.control.Button
    end


    properties (Access = private)
        Callingapp; % Description
        dialogapp; % Description
        Items;
    end

    methods (Access = private)

        function CheckBoxChanged(app)
            if sum(app.Items)==13
                app.AllCheckBox.Value=1;
            end
            if sum(app.Items(2:4))==3
                app.GradientCheckBox.Value=1;
            end
            if sum(app.Items(5:13))==9
                app.TensorCheckBox.Value=1;
            end
            app.TCheckBox.Value=app.Items(1);
            app.TxCheckBox.Value=app.Items(2);
            app.TyCheckBox.Value=app.Items(3);
            app.TzCheckBox.Value=app.Items(4);
            app.HaxCheckBox.Value=app.Items(5);
            app.HayCheckBox.Value=app.Items(6);
            app.ZaCheckBox.Value=app.Items(7);
            app.BxxCheckBox_2.Value=app.Items(8);
            app.ByyCheckBox_2.Value=app.Items(9);
            app.BzzCheckBox_2.Value=app.Items(10);
            app.BxyCheckBox.Value=app.Items(11);
            app.BxzCheckBox.Value=app.Items(12);
            app.ByzCheckBox.Value=app.Items(13);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingapp, items, magdec, maginc, height, gauss)
            MoveAppCenter(app.MagFWDUIFigure);
            app.ObservationHeightEditField.Value=height;
            app.GaussianNoiseEditField.Value=gauss;
            app.MagDeclinationEditField.Value=magdec;
            app.MagInclinationEditField.Value=maginc;
            app.Items=items;
            app.Callingapp=callingapp;
            CheckBoxChanged(app)
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            GetMagFWDPara(app.Callingapp,app.Items,app.ObservationHeightEditField.Value,app.GaussianNoiseEditField.Value,app.MagDeclinationEditField.Value,app.MagInclinationEditField.Value,1)
            delete(app)
        end

        % Value changed function: AllCheckBox, BxxCheckBox_2, 
        % BxyCheckBox, BxzCheckBox, ByyCheckBox_2, ByzCheckBox, 
        % BzzCheckBox_2, GradientCheckBox, HaxCheckBox, 
        % HayCheckBox, TCheckBox, TensorCheckBox, TxCheckBox, 
        % TyCheckBox, TzCheckBox, ZaCheckBox
        function CheckBoxValueChanged(app, event)

            switch event.Source.Text
                case 'All'
                    if event.Source.Value
                        app.Items=ones(13);
                    else
                        app.Items=zeros(13);
                    end
                    CheckBoxChanged(app);
                case '∆T'
                    if event.Source.Value
                        app.Items(1)=1;
                        app.Items(5:7)=1;
                    else
                        app.Items(1)=0;
                    end
                case 'Gradient'
                    if event.Source.Value
                        app.Items(2:4)=[1 1 1];
                        app.Items(8:13)=[1 1 1 1 1 1];
                    else
                        app.Items(2:4)=[0 0 0];
                    end
                    CheckBoxChanged(app);
                case '∆Tx'
                    if event.Source.Value
                        app.Items(2)=1;
                        app.Items(8)=1;
                        app.Items(11)=1;
                        app.Items(12)=1;
                    else
                        app.Items(2)=0;
                    end
                case '∆Ty'
                    if event.Source.Value
                        app.Items(3)=1;
                        app.Items(9)=1;
                        app.Items(11)=1;
                        app.Items(13)=1;
                    else
                        app.Items(3)=0;
                    end
                case '∆Tz'
                    if event.Source.Value
                        app.Items(4)=1;
                        app.Items(12:13)=1;
                        app.Items(10)=1;
                    else
                        app.Items(4)=0;
                    end
                case 'Tensor'
                    if event.Source.Value
                        app.Items(5:13)=1;
                    else
                        app.Items(5:13)=0;
                    end
                    CheckBoxChanged(app);
                case 'Hax'
                    app.Items(5)=event.Source.Value;
                case 'Hay'
                    app.Items(6)=event.Source.Value;
                case 'Za'
                    app.Items(7)=event.Source.Value;
                case 'Bxx'
                    app.Items(8)=event.Source.Value;
                case 'Byy'
                    app.Items(9)=event.Source.Value;
                case 'Bzz'
                    app.Items(10)=event.Source.Value;
                case 'Bxy'
                    app.Items(11)=event.Source.Value;
                case 'Bxz'
                    app.Items(12)=event.Source.Value;
                case 'Byz'
                    app.Items(13)=event.Source.Value;
            end
        end

        % Close request function: MagFWDUIFigure
        function MagFWDUIFigureCloseRequest(app, event)
            try
                GetFWDPara(app.Callingapp,0,0,0,0,0,0);
                delete(app)
            catch
                delete(app)
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            GetFWDPara(app.Callingapp,0,0,0,0,0,0);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MagFWDUIFigure = uifigure('Visible', 'off');
            app.MagFWDUIFigure.Name = 'Mag FWD';
            app.MagFWDUIFigure.Icon = 'Setting.png';
            app.MagFWDUIFigure.CloseRequestFcn = createCallbackFcn(app, @MagFWDUIFigureCloseRequest, true);
            app.OkButton = uibutton(app.MagFWDUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.MagFWDUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.MagFWDUIFigure);
            app.TCheckBox = uicheckbox(app.Panel);
            app.TCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.TCheckBox.Text = '∆T';
            app.TensorCheckBox = uicheckbox(app.Panel);
            app.TensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.TensorCheckBox.Text = 'Tensor';
            app.ZaCheckBox = uicheckbox(app.Panel);
            app.ZaCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.ZaCheckBox.Text = 'Za';
            app.HaxCheckBox = uicheckbox(app.Panel);
            app.HaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.HaxCheckBox.Text = 'Hax';
            app.ByzCheckBox = uicheckbox(app.Panel);
            app.ByzCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.ByzCheckBox.Text = 'Byz';
            app.BxzCheckBox = uicheckbox(app.Panel);
            app.BxzCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.BxzCheckBox.Text = 'Bxz';
            app.BxyCheckBox = uicheckbox(app.Panel);
            app.BxyCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.BxyCheckBox.Text = 'Bxy';
            app.HayCheckBox = uicheckbox(app.Panel);
            app.HayCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.HayCheckBox.Text = 'Hay';
            app.SelectCategoryLabel = uilabel(app.Panel);
            app.SelectCategoryLabel.Text = 'Select Category:';
            app.AllCheckBox = uicheckbox(app.Panel);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.AllCheckBox.Text = 'All';
            app.BzzCheckBox_2 = uicheckbox(app.Panel);
            app.BzzCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.BzzCheckBox_2.Text = 'Bzz';
            app.BxxCheckBox_2 = uicheckbox(app.Panel);
            app.BxxCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.BxxCheckBox_2.Text = 'Bxx';
            app.ByyCheckBox_2 = uicheckbox(app.Panel);
            app.ByyCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.ByyCheckBox_2.Text = 'Byy';
            app.GradientCheckBox = uicheckbox(app.Panel);
            app.GradientCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.GradientCheckBox.Text = 'Gradient';
            app.TxCheckBox = uicheckbox(app.Panel);
            app.TxCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.TxCheckBox.Text = '∆Tx';
            app.TyCheckBox = uicheckbox(app.Panel);
            app.TyCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.TyCheckBox.Text = '∆Ty';
            app.TzCheckBox = uicheckbox(app.Panel);
            app.TzCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.TzCheckBox.Text = '∆Tz';
            app.ObservationHeightEditFieldLabel = uilabel(app.MagFWDUIFigure);
            app.ObservationHeightEditFieldLabel.Text = 'Observation Height';
            app.ObservationHeightEditField = uieditfield(app.MagFWDUIFigure, 'numeric');
            app.GaussianNoiseEditFieldLabel = uilabel(app.MagFWDUIFigure);
            app.GaussianNoiseEditFieldLabel.Text = 'Gaussian Noise';
            app.GaussianNoiseEditField = uieditfield(app.MagFWDUIFigure, 'numeric');
            app.MagDeclinationEditFieldLabel = uilabel(app.MagFWDUIFigure);
            app.MagDeclinationEditFieldLabel.Text = 'Mag Declination';
            app.MagDeclinationEditField = uieditfield(app.MagFWDUIFigure, 'numeric');
            app.MagInclinationEditFieldLabel = uilabel(app.MagFWDUIFigure);
            app.MagInclinationEditFieldLabel.Text = 'Mag Inclination';
            app.MagInclinationEditField = uieditfield(app.MagFWDUIFigure, 'numeric');
            % Show the figure after all components are created
            app.MagFWDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagFor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MagFWDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MagFWDUIFigure)
        end
    end
end