classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GM3DUIFigure        matlab.ui.Figure
        WelcometoGM3DLabel  matlab.ui.control.Label
        ForwardModelButton  matlab.ui.control.Button
        CreateaModelButton  matlab.ui.control.Button
    end


    properties (Access = private)
        Dialogapp; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            MoveAppCenter(app.GM3DUIFigure);
        end

        % Button pushed function: CreateaModelButton
        function CreateaModelButtonPushed(app, event)
            app.CreateaModelButton.BackgroundColor=[0.07 0.62 1.00];
            CreateModel;
            pause(2);
            app.CreateaModelButton.BackgroundColor=[0.80,0.80,0.80];
        end

        % Button pushed function: ForwardModelButton
        function ForwardModelButtonPushed(app, event)
            app.ForwardModelButton.BackgroundColor=[0.07 0.62 1.00];
            app.ForwardModelButton.Text='Opening';
            [Fnameh,Pnameh]=uigetfile('*.mat');
            filepath=strcat(Pnameh,Fnameh);   
            load(filepath,"Scope","nx","ny","nz","Dx","Dy","Dz","Unit","irregularNum","irregularModel","cylinderNum","cylinderModel","cubeNum","cubeModel","sphereNum","sphereModel");
            app.Dialogapp=Modelforwarding(app,Scope, Dx, Dy, Dz, nx, ny, nz, Unit, irregularNum, irregularModel, cylinderNum, cylinderModel, cubeNum, cubeModel, sphereNum, sphereModel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GM3DUIFigure = uifigure('Visible', 'off');
            app.GM3DUIFigure.Name = 'G&M3D';
            app.GM3DUIFigure.Icon = 'mainicon.png';
            app.CreateaModelButton = uibutton(app.GM3DUIFigure, 'push');
            app.CreateaModelButton.ButtonPushedFcn = createCallbackFcn(app, @CreateaModelButtonPushed, true);
            app.CreateaModelButton.Icon = 'main-newmodel.png';
            app.CreateaModelButton.Text = 'Create a Model';
            app.ForwardModelButton = uibutton(app.GM3DUIFigure, 'push');
            app.ForwardModelButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardModelButtonPushed, true);
            app.ForwardModelButton.Icon = 'main-inputfile.png';
            app.ForwardModelButton.Text = ' Forward Model';
            app.WelcometoGM3DLabel = uilabel(app.GM3DUIFigure);
            app.WelcometoGM3DLabel.Text = 'Welcome to G&M3D';
            % Show the figure after all components are created
            app.GM3DUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GM3DUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GM3DUIFigure)
        end
    end
end