classdef Modelforwarding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModelForwardingUIFigure  matlab.ui.Figure
        Tree                     matlab.ui.container.CheckBoxTree
        SphereNode               matlab.ui.container.TreeNode
        CubeNode                 matlab.ui.container.TreeNode
        CylinderNode             matlab.ui.container.TreeNode
        IrregularNode            matlab.ui.container.TreeNode
        Panel_2                  matlab.ui.container.Panel
        NumLabel                 matlab.ui.control.Label
        OKButton                 matlab.ui.control.Button
        LayerEditField           matlab.ui.control.EditField
        ProfileDropDown          matlab.ui.control.DropDown
        DataTypeDropDown         matlab.ui.control.DropDown
        Panel                    matlab.ui.container.Panel
        DrawButton               matlab.ui.control.Button
        ViewButton               matlab.ui.control.Button
        ViewDropDown             matlab.ui.control.DropDown
        MagFwdDropDown           matlab.ui.control.DropDown
        GraFwdDropDown           matlab.ui.control.DropDown
        DataButton               matlab.ui.control.Button
        MagneticForwardButton    matlab.ui.control.Button
        GravityForwardButton     matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        ContextMenu              matlab.ui.container.ContextMenu
        propertiesMenu           matlab.ui.container.Menu
    end


    properties (Access = private)
        Callingapp;
        Dialogapp;
        ScopeData;
        GaussianNoise=0;
        Height=0;
        Nx;
        Ny;
        Nz;
        Dx;
        Dy;
        Dz;
        Unit;
        operation=1;

        %模型数据
        SphereNum=0;
        CubeNum=0;
        IrregularNum=0;
        CylinderNum=0;
        SphereModel;
        IrregularModel;
        CubeModel;
        CylinderModel;

        %三维密度/磁化率分布
        %Density=[];
        %Magnetic=[];

        %需要正演的项目
        GraList=zeros(1,7);
        MagList=zeros(1,13);

        %正演计算相关数据
        %提前计算,在计算核矩阵时复用
        GraVectorX;
        GraVectorY;
        GraVectorX2;
        GraVectorY2;

        MagVectorX;
        MagVectorY;
        MagVectorX2;
        MagVectorY2;
        %正演结果数组
        CurrentGAns=[];
        CurrentGxzAns=[];
        CurrentGyzAns=[];
        CurrentGzzAns=[];
        CurrentGxxAns=[];
        CurrentGxyAns=[];
        CurrentGyyAns=[];

        CurrentBxzAns=[];
        CurrentByzAns=[];
        CurrentBzzAns=[];
        CurrentBxxAns=[];
        CurrentBxyAns=[];
        CurrentByyAns=[];
        CurrentTAns=[];
        CurrentHaxAns=[];
        CurrentHayAns=[];
        CurrentZaAns=[];
        CurrentTxAns=[];
        CurrentTyAns=[];
        CurrentTzAns=[];
        DrawProperties;

        MagDec;
        MagInc;
        displayIrregular;
    end

    methods (Access = private)
        function DrawCheckModel(app)
            %刷新坐标区并绘制全部模型
            cla(app.UIAxes);
            Box_Axis(app.UIAxes,app.UIAxes.XLim,app.UIAxes.YLim,app.UIAxes.ZLim);
            CheckModel=app.Tree.CheckedNodes;

            for n=1:size(CheckModel,1)
                switch CheckModel(n).Tag
                    case "Sphere"
                        i=CheckModel(n).NodeData;
                        [xs,ys,zs]=Plot_Sphere(app.SphereModel(i).Position(1),app.SphereModel(i).Position(2),app.SphereModel(i).Position(3),app.SphereModel(i).Radius);
                        surf(app.UIAxes,xs,ys,zs,"facecolor",app.SphereModel(i).Color)
                        hold(app.UIAxes,"on");
                    case "Cube"
                        i=CheckModel(n).NodeData;
                        fixed=[app.CubeModel(i).Position(1,:);app.CubeModel(i).Position(2,:);app.CubeModel(i).Position(3,:);app.CubeModel(i).Position(4,:);app.CubeModel(i).Position(5,:);app.CubeModel(i).Position(6,:);app.CubeModel(i).Position(7,:);app.CubeModel(i).Position(8,:)];
                        facet=[1 2 4 3;1 3 7 5;1 2 6 5;2 4 8 6;3 4 8 7;5 6 8 7;];
                        patch(app.UIAxes,'Faces',facet,"Vertices",fixed,'facecolor',app.CubeModel(i).Color);
                        hold(app.UIAxes,"on");
                    case "Cylinder"
                        i=CheckModel(n).NodeData;
                        switch app.CylinderModel(i).Trend
                            case 'X'
                                [cx,cy,cz]=Plot_Cylinder(app.CylinderModel(i).Extension(2)-app.CylinderModel(i).Extension(1),[app.CylinderModel(i).Extension(1),app.CylinderModel(i).Position(1),app.CylinderModel(i).Position(2)],app.CylinderModel(i).Radius,0);
                            case 'Y'
                                [cx,cy,cz]=Plot_Cylinder(app.CylinderModel(i).Extension(2)-app.CylinderModel(i).Extension(1),[app.CylinderModel(i).Position(1),app.CylinderModel(i).Extension(1),app.CylinderModel(i).Position(2)],app.CylinderModel(i).Radius,1);
                            case 'Z'
                                [cx,cy,cz]=Plot_Cylinder(app.CylinderModel(i).Extension(2)-app.CylinderModel(i).Extension(1),[app.CylinderModel(i).Position(1),app.CylinderModel(i).Position(2),app.CylinderModel(i).Extension(1)],app.CylinderModel(i).Radius,2);
                        end
                        surf(app.UIAxes,cx,cy,cz,'FaceColor',app.CylinderModel(i).Color);
                        hold(app.UIAxes,"on");
                        fill3(app.UIAxes,cx(1,:),cy(1,:),cz(1,:),app.CylinderModel(i).Color);
                        hold(app.UIAxes,"on")
                        fill3(app.UIAxes,cx(2,:),cy(2,:),cz(2,:),app.CylinderModel(i).Color);
                        hold(app.UIAxes,"on")
                    case "Irregular"
                        if app.displayIrregular==1
                            i=CheckModel(n).NodeData;
                            Plot_Irregular(app.UIAxes,app.IrregularModel(i).Position,app.IrregularModel(i).Position,app.Nx,app.Ny,app.Nz,[app.ScopeData,app.Dx,app.Dy,app.Dz],app.IrregularModel(i).Color)
                        end
                end
            end


        end
        function Ans=AddGuassianNoise(app,data,proportion)
            noise=max(max(data))*proportion*randn(app.Ny,app.Nx);
            Ans=data+noise;
        end
        function Vector=MatrixToVector(app,matrix)
            Vector=zeros(app.Nx*app.Ny,1);
            count=0;
            for ix=1:app.Nx
                for iy=1:app.Ny
                    count=count+1;
                    Vector(count,1)=matrix(iy,ix);
                end
            end
        end

        function Ans=MatrixRecreat(app,f_ext,t)
            T=zeros(2*app.Nx,2*app.Ny);
            T(2:end,2:end)=t;
            t11=T(1:app.Nx,1:app.Ny);
            t12=T(1:app.Nx,app.Ny+1:2*app.Ny);
            t21=T(app.Nx+1:2*app.Nx,1:app.Ny);
            t22=T(app.Nx+1:2*app.Nx,app.Ny+1:2*app.Ny);
            e_ext=[t22 t21;t12 t11];
            tem=fft2(e_ext).*fft2(f_ext);
            res=ifft2(tem);
            Ans=res(1:app.Nx,1:app.Ny);
        end
        function GravityForward(app)
            app.CurrentGAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentGxxAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentGxyAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentGxzAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentGyyAns=zeros(app.Nx,app.Ny,"single");
            app.CurrentGyzAns=zeros(app.Nx,app.Ny,"single");
            app.CurrentGzzAns=zeros(app.Nx,app.Ny,'single');
            d=zeros(app.Nx,app.Ny,app.Nz,'single');
            CheckModel=app.Tree.CheckedNodes;
            for n=1:size(CheckModel,1)
                switch CheckModel(n).Tag
                    case "Sphere"
                        i=CheckModel(n).NodeData;
                        if app.SphereModel(i).ResidualDensity~=0
                            d=d+app.SphereModel(i).DenDivision;
                        end
                    case "Cube"
                        i=CheckModel(n).NodeData;
                        if app.CubeModel(i).ResidualDensity~=0
                            d=d+app.CubeModel(i).DenDivision;
                        end
                    case "Cylinder"
                        i=CheckModel(n).NodeData;
                        if app.CylinderModel(i).ResidualDensity~=0
                            d=d+app.CylinderModel(i).DenDivision;
                        end
                    case "Irregular"
                        i=CheckModel(n).NodeData;
                        size(app.IrregularModel(i).DenDivision)
                        if app.IrregularModel(i).ResidualDensity~=0
                            d=d+app.IrregularModel(i).DenDivision;
                        end
                end
            end
            GravityBCE(app,d);
            DrawCheckAnomaly(app,app.CurrentGAns,'mGal');
        end
        function MagneticForward(app)
            app.CurrentTAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentTxAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentTyAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentTzAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentByyAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentByzAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentBzzAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentBxxAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentBxyAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentBxzAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentHaxAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentHayAns=zeros(app.Nx,app.Ny,'single');
            app.CurrentZaAns=zeros(app.Nx,app.Ny,'single');
            CheckModel=app.Tree.CheckedNodes;
            %单独计算模型
            for n=1:size(CheckModel,1)
                switch CheckModel(n).Tag
                    case "Sphere"
                        i=CheckModel(n).NodeData;
                        if app.SphereModel(i).Magnetization~=0
                            %判断是否为垂直磁化
                            if app.SphereModel(i).Inclination==90
                                VerMagneticBCE(app,app.SphereModel(i).MagDivision)
                            else
                                inc=app.SphereModel(i).Inclination/180*pi;
                                dec=app.SphereModel(i).Declination/180*pi;
                                k1=cos(inc)*cos(dec);
                                k2=cos(inc)*sin(dec);
                                k3=sin(inc);
                                NonVerMagneticBCE(app,app.SphereModel(i).MagDivision,k1,k2,k3);
                            end
                        end
                    case "Cube"
                        i=CheckModel(n).NodeData;
                        if app.CubeModel(i).Magnetization~=0
                            %判断是否为垂直磁化
                            if app.CubeModel(i).Inclination==90
                                VerMagneticBCE(app,app.CubeModel(i).MagDivision)
                            else

                                inc=app.CubeModel(i).Inclination/180*pi;
                                dec=app.CubeModel(i).Declination/180*pi;
                                k1=cos(inc)*cos(dec);
                                k2=cos(inc)*sin(dec);
                                k3=sin(inc);
                                NonVerMagneticBCE(app,app.CubeModel(i).MagDivision,k1,k2,k3);
                            end
                        end
                    case "Cylinder"
                        i=CheckModel(n).NodeData;
                        if app.CylinderModel(i).Magnetization~=0
                            %判断是否为垂直磁化
                            if app.CylinderModel(i).Inclination==90
                                VerMagneticBCE(app,app.CylinderModel(i).MagDivision)
                            else
                                inc=app.CylinderModel(i).Inclination/180*pi;
                                dec=app.CylinderModel(i).Declination/180*pi;
                                k1=cos(inc)*cos(dec);
                                k2=cos(inc)*sin(dec);
                                k3=sin(inc);
                                NonVerMagneticBCE(app,app.CylinderModel(i).MagDivision,k1,k2,k3);
                            end
                        end
                    case "Irregular"
                        i=CheckModel(n).NodeData;
                        if app.IrregularModel(i).Magnetization~=0
                            %判断是否是垂直磁化
                            if app.IrregularModel(i).Inclination==90
                                VerMagneticBCE(app,app.IrregularModel(i).MagDivision)
                            else
                                inc=app.IrregularModel(i).Inclination/180*pi;
                                dec=app.IrregularModel(i).Declination/180*pi;
                                k1=cos(inc)*cos(dec);
                                k2=cos(inc)*sin(dec);
                                k3=sin(inc);
                                NonVerMagneticBCE(app,app.IrregularModel(i).MagDivision,k1,k2,k3);
                            end
                        end
                end
            end
            I=app.MagInc/180*pi;%倾
            D=app.MagDec/180*pi;%偏
            if app.MagList(1)
                app.CurrentTAns=app.CurrentZaAns*sin(I)+app.CurrentHaxAns*cos(I)*cos(D)+app.CurrentHayAns*cos(I)*cos(D);
            end
            if app.MagList(2)
                app.CurrentTxAns=app.CurrentBxxAns*cos(D)*cos(I)+app.CurrentBxyAns*cos(I)*sin(D)+app.CurrentBxzAns*sin(I);
            end
            if app.MagList(3)
                app.CurrentTyAns=app.CurrentBxyAns*cos(D)*cos(I)+app.CurrentByyAns*cos(I)*sin(D)+app.CurrentByzAns*sin(I);
            end
            if app.MagList(4)
                app.CurrentTzAns=app.CurrentBxzAns*cos(D)*cos(I)+app.CurrentByzAns*cos(I)*sin(D)+app.CurrentBzzAns*sin(I);
            end

            DrawCheckAnomaly(app,app.CurrentTAns,'nT');
        end
        function NonVerMagneticBCE(app,m,k1,k2,k3)
            for iz=1:app.Nz
                f=m(:,:,iz);
                if isempty(find(f~=0,1))
                else
                    f_ext=zeros(2*app.Nx,2*app.Ny);
                    f_ext(1:app.Nx,1:app.Ny)=f;
                    [bx,by,bz,bxx,bxy,bxz,byy,byz,bzz]=NonVerMagneticKernelOfz(app,iz,k1,k2,k3);
                    if app.MagList(5)
                        app.CurrentHaxAns=app.CurrentHaxAns+MatrixRecreat(app,f_ext,bx);
                    end

                    if app.MagList(6)
                        app.CurrentHayAns=app.CurrentHayAns+MatrixRecreat(app,f_ext,by);
                    end

                    if app.MagList(7)
                        app.CurrentZaAns=app.CurrentZaAns+MatrixRecreat(app,f_ext,bz);
                    end

                    if app.MagList(8)
                        app.CurrentBxxAns=app.CurrentBxxAns+MatrixRecreat(app,f_ext,bxx);
                    end

                    if app.MagList(9)
                        app.CurrentByyAns=app.CurrentByyAns+MatrixRecreat(app,f_ext,byy);
                    end

                    if app.MagList(10)
                        app.CurrentBzzAns=app.CurrentBzzAns+MatrixRecreat(app,f_ext,bzz);
                    end

                    if app.MagList(11)
                        app.CurrentBxyAns=app.CurrentBxyAns+MatrixRecreat(app,f_ext,bxy);
                    end

                    if app.MagList(12)
                        app.CurrentBxzAns=app.CurrentBxzAns+MatrixRecreat(app,f_ext,bxz);
                    end

                    if app.MagList(13)
                        app.CurrentByzAns=app.CurrentByzAns+MatrixRecreat(app,f_ext,byz);
                    end

                end
            end
            app.CurrentBxxAns=100*SwapXandY(app,app.CurrentBxxAns);
            app.CurrentByyAns=100*SwapXandY(app,app.CurrentByyAns);
            app.CurrentBxzAns=100*SwapXandY(app,app.CurrentBxzAns);
            app.CurrentByzAns=100*SwapXandY(app,app.CurrentByzAns);
            app.CurrentBzzAns=100*SwapXandY(app,app.CurrentBzzAns);
            app.CurrentBxyAns=100*SwapXandY(app,app.CurrentBxyAns);
            app.CurrentHaxAns=100*SwapXandY(app,app.CurrentHaxAns);
            app.CurrentHayAns=100*SwapXandY(app,app.CurrentHayAns);
            app.CurrentZaAns=100*SwapXandY(app,app.CurrentZaAns);
            if app.GaussianNoise~=0
                app.CurrentBxxAns=AddGuassianNoise(app,app.CurrentBxxns,app.GaussianNoise/100);
                app.CurrentByyAns=AddGuassianNoise(app,app.CurrentByyAns,app.GaussianNoise/100);
                app.CurrentBzzAns=AddGuassianNoise(app,app.CurrentBzzAns,app.GaussianNoise/100);
                app.CurrentBxzAns=AddGuassianNoise(app,app.CurrentBxzAns,app.GaussianNoise/100);
                app.CurrentByzAns=AddGuassianNoise(app,app.CurrentByzAns,app.GaussianNoise/100);
                app.CurrentBxyAns=AddGuassianNoise(app,app.CurrentBxyAns,app.GaussianNoise/100);
                app.CurrentHaxAns=AddGuassianNoise(app,app.CurrentHaxAns,app.GaussianNoise/100);
                app.CurrentHayAns=AddGuassianNoise(app,app.CurrentHayAns,app.GaussianNoise/100);
                app.CurrentZaAns=AddGuassianNoise(app,app.CurrentZaAns,app.GaussianNoise/100);
            end
        end

        function VerMagneticBCE(app,m)
            %计算当前m的磁正演数据
            %针对垂磁化

            for iz=1:app.Nz
                f=m(:,:,iz);
                if isempty(find(f~=0, 1))
                    %如该层密度值全部为0，则直接跳过
                else
                    f_ext=zeros(2*app.Nx,2*app.Ny);
                    f_ext(1:app.Nx,1:app.Ny)=f;
                    [bx,by,bz,bxx,bxy,bxz,byy,byz,bzz]=VerMagneticKernelOfz(app,iz);
                    if app.MagList(5)
                        t_ext_bx=zeros(2*app.Nx,2*app.Ny);
                        t_ext_bx(1:app.Nx,1:app.Ny)=bx;
                        t_ext_bx(app.Nx+2:2*app.Nx,:)=-flipud(t_ext_bx(2:app.Nx,:));
                        t_ext_bx(:,app.Ny+2:2*app.Ny)=fliplr(t_ext_bx(:,2:app.Ny));
                        app.CurrentHaxAns=app.CurrentHaxAns+FourierTran(app,f_ext,t_ext_bx);
                    end
                    if app.MagList(6)
                        t_ext_by=zeros(2*app.Nx,2*app.Ny);
                        t_ext_by(1:app.Nx,1:app.Ny)=by;
                        t_ext_by(app.Nx+2:2*app.Nx,:)=flipud(t_ext_by(2:app.Nx,:));
                        t_ext_by(:,app.Ny+2:2*app.Ny)=-fliplr(t_ext_by(:,2:app.Ny));
                        app.CurrentHayAns=app.CurrentHayAns+FourierTran(app,f_ext,t_ext_by);
                    end
                    if app.MagList(7)
                        t_ext_bz=zeros(2*app.Nx,2*app.Ny);
                        t_ext_bz(1:app.Nx,1:app.Ny)=bz;
                        t_ext_bz(app.Nx+2:2*app.Nx,:)=flipud(t_ext_bz(2:app.Nx,:));
                        t_ext_bz(:,app.Ny+2:2*app.Ny)=fliplr(t_ext_bz(:,2:app.Ny));
                        app.CurrentZaAns=app.CurrentZaAns+FourierTran(app,f_ext,t_ext_bz);
                    end
                    if app.MagList(8)
                        t_ext_bxx=zeros(2*app.Nx,2*app.Ny);
                        t_ext_bxx(1:app.Nx,1:app.Ny)=bxx;
                        t_ext_bxx(app.Nx+2:2*app.Nx,:)=flipud(t_ext_bxx(2:app.Nx,:));
                        t_ext_bxx(:,app.Ny+2:2*app.Ny)=fliplr(t_ext_bxx(:,2:app.Ny));
                        app.CurrentBxxAns=app.CurrentBxxAns+FourierTran(app,f_ext,t_ext_bxx);
                    end
                    if app.MagList(9)
                        t_ext_byy=zeros(2*app.Nx,2*app.Ny);
                        t_ext_byy(1:app.Nx,1:app.Ny)=byy;
                        t_ext_byy(app.Nx+2:2*app.Nx,:)=flipud(t_ext_byy(2:app.Nx,:));
                        t_ext_byy(:,app.Ny+2:2*app.Ny)=fliplr(t_ext_byy(:,2:app.Ny));
                        app.CurrentByyAns=app.CurrentByyAns+FourierTran(app,f_ext,t_ext_byy);
                    end
                    if app.MagList(11)
                        t_ext_bxy=zeros(2*app.Nx,2*app.Ny);
                        t_ext_bxy(1:app.Nx,1:app.Ny)=bxy;
                        t_ext_bxy(app.Nx+2:2*app.Nx,:)=-flipud(t_ext_bxy(2:app.Nx,:));
                        t_ext_bxy(:,app.Ny+2:2*app.Ny)=-fliplr(t_ext_bxy(:,2:app.Ny));
                        app.CurrentBxyAns=app.CurrentBxyAns+FourierTran(app,f_ext,t_ext_bxy);
                    end
                    if app.MagList(13)
                        t_ext_byz=zeros(2*app.Nx,2*app.Ny);
                        t_ext_byz(1:app.Nx,1:app.Ny)=byz;
                        t_ext_byz(app.Nx+2:2*app.Nx,:)=flipud(t_ext_byz(2:app.Nx,:));
                        t_ext_byz(:,app.Ny+2:2*app.Ny)=-fliplr(t_ext_byz(:,2:app.Ny));
                        app.CurrentByzAns=app.CurrentByzAns+FourierTran(app,f_ext,t_ext_byz);
                    end
                    if app.MagList(12)
                        t_ext_bxz=zeros(2*app.Nx,2*app.Ny);
                        t_ext_bxz(1:app.Nx,1:app.Ny)=bxz;
                        t_ext_bxz(app.Nx+2:2*app.Nx,:)=-flipud(t_ext_bxz(2:app.Nx,:));
                        t_ext_bxz(:,app.Ny+2:2*app.Ny)=fliplr(t_ext_bxz(:,2:app.Ny));
                        app.CurrentBxzAns=app.CurrentBxzAns+FourierTran(app,f_ext,t_ext_bxz);
                    end
                    if app.MagList(10)
                        t_ext_bzz=zeros(2*app.Nx,2*app.Ny);
                        t_ext_bzz(1:app.Nx,1:app.Ny)=bzz;
                        t_ext_bzz(app.Nx+2:2*app.Nx,:)=flipud(t_ext_bzz(2:app.Nx,:));
                        t_ext_bzz(:,app.Ny+2:2*app.Ny)=fliplr(t_ext_bzz(:,2:app.Ny));
                        app.CurrentBzzAns=app.CurrentBzzAns+FourierTran(app,f_ext,t_ext_bzz);
                    end
                end
            end
            %交换行列
            app.CurrentBxxAns=100*SwapXandY(app,app.CurrentBxxAns);
            app.CurrentByyAns=100*SwapXandY(app,app.CurrentByyAns);
            app.CurrentBxzAns=100*SwapXandY(app,app.CurrentBxzAns);
            app.CurrentByzAns=100*SwapXandY(app,app.CurrentByzAns);
            app.CurrentBzzAns=100*SwapXandY(app,app.CurrentBzzAns);
            app.CurrentBxyAns=100*SwapXandY(app,app.CurrentBxyAns);
            app.CurrentHaxAns=100*SwapXandY(app,app.CurrentHaxAns);
            app.CurrentHayAns=100*SwapXandY(app,app.CurrentHayAns);
            app.CurrentZaAns=100*SwapXandY(app,app.CurrentZaAns);
            if app.GaussianNoise~=0
                app.CurrentBxxAns=AddGuassianNoise(app,app.CurrentBxxns,app.GaussianNoise/100);
                app.CurrentByyAns=AddGuassianNoise(app,app.CurrentByyAns,app.GaussianNoise/100);
                app.CurrentBzzAns=AddGuassianNoise(app,app.CurrentBzzAns,app.GaussianNoise/100);
                app.CurrentBxzAns=AddGuassianNoise(app,app.CurrentBxzAns,app.GaussianNoise/100);
                app.CurrentByzAns=AddGuassianNoise(app,app.CurrentByzAns,app.GaussianNoise/100);
                app.CurrentBxyAns=AddGuassianNoise(app,app.CurrentBxyAns,app.GaussianNoise/100);
                app.CurrentHaxAns=AddGuassianNoise(app,app.CurrentHaxAns,app.GaussianNoise/100);
                app.CurrentHayAns=AddGuassianNoise(app,app.CurrentHayAns,app.GaussianNoise/100);
                app.CurrentZaAns=AddGuassianNoise(app,app.CurrentZaAns,app.GaussianNoise/100);
            end
        end
        function [bx,by,bz,bxx,bxy,bxz,byy,byz,bzz]=VerMagneticKernelOfz(app,iz)
            %用于计算垂直磁化下的核矩阵计算
            bx=0;
            by=0;
            bz=0;
            bxx=0;
            bxy=0;
            bxz=0;
            byy=0;
            bzz=0;
            byz=0;
            z=zeros(1,2);
            if app.Unit==1
                z(1,1)=(app.Height-(iz-1)*app.Dz)*1000;
                z(1,2)=(app.Height-(iz)*app.Dz)*1000;
            elseif app.Unit==0
                z(1,1)=(app.Height-(iz-1)*app.Dz);
                z(1,2)=(app.Height-(iz)*app.Dz);
            elseif app.Unit==2
                z(1,1)=(app.Height-(iz-1)*app.Dz)*100;
                z(1,2)=(app.Height-(iz)*app.Dz)*100;
            end
            for i=1:2
                for j=1:2
                    for k=1:2
                        uijk=(-1)^i*(-1)^j*(-1)^k;
                        Rijk2=app.GraVectorX2(:,:,i)+app.GraVectorY2(:,:,j)+z(:,k).^2;
                        Rijk=sqrt(Rijk2);
                        %bx
                        if app.MagList(5)
                            arg_logYplusR=log(app.GraVectorY(:,:,j)+Rijk);
                            bx=bx-uijk*arg_logYplusR;
                        end
                        %by
                        if app.MagList(6)
                            arg_logXplusR=log(app.GraVectorX(:,:,i)+Rijk);
                            by=by-uijk*arg_logXplusR;
                        end
                        %bz
                        if app.MagList(7)
                            bz=bz+uijk*atan2(app.GraVectorX(:,:,i).*app.GraVectorY(:,:,j),z(:,k).*Rijk);
                        end
                        %bxx
                        if app.MagList(8)
                            bxx=bxx-uijk*(app.GraVectorX(:,:,i)./(Rijk.*(Rijk+app.GraVectorY(:,:,j))));
                        end
                        %byy
                        if app.MagList(9)
                            byy=byy-uijk*(app.GraVectorY(:,:,j)./(Rijk.*(Rijk+app.GraVectorX(:,:,i))));
                        end
                        %bzz
                        if app.MagList(10)
                            bzz=bzz-uijk*(app.GraVectorX(:,:,i).*app.GraVectorY(:,:,j).*(Rijk.^2+z(:,k).^2)./((app.GraVectorY(:,:,j).^2+z(:,k).^2).*(app.GraVectorX(:,:,i).^2+z(:,k).^2).*Rijk));
                        end
                        %bxy
                        if app.MagList(11)
                            bxy=bxy-uijk./Rijk;
                        end
                        %bxz
                        if app.MagList(12)
                            bxz=bxz-uijk*(z(:,k)./(Rijk.*(Rijk+app.GraVectorY(:,:,j))));
                        end
                        %byz
                        if app.MagList(13)
                            byz=byz-uijk*(z(:,k)./(Rijk.*(Rijk+app.GraVectorX(:,:,i))));
                        end
                    end
                end
            end

        end
        function DrawCheckAnomaly(app,data,unit)
            %绘制选择的异常图像
            if isempty(data)
                %选中的为未正演的图像
                text="The component you selected has not been calculated";
                app.Dialogapp=Error(app,text);
            else
                %重新绘制选中的模型
                DrawCheckModel(app);
                %选择绘图色码类型
                colormap(app.UIAxes,app.DrawProperties.colormap);
                %如果需要色标卡、和标注数值二选一
                if strcmp(app.DrawProperties.colorbar,"on")
                    peishu=abs((app.ScopeData(1,5)-1))/max(max(data))*100000;
                    [~,fig]=contourf(app.UIAxes,app.ScopeData(1,1)+app.Dx/2:app.Dx:app.ScopeData(1,2)-app.Dx/2,app.ScopeData(1,3)+app.Dy/2:app.Dy:app.ScopeData(1,4)-app.Dy/2,peishu*data,app.DrawProperties.level);
                    set(fig,"ShowText","off","Linewidth",app.DrawProperties.linewidth,"LineColor",app.DrawProperties.linecolor,"LineStyle",app.DrawProperties.linestyle);
                    ctitle=unit;
                    c=colorbar(app.UIAxes,"eastoutside");
                    ctick=c.Ticks/peishu;
                    for k=1:length(ctick)
                        ctext{k}=num2str(ctick(k));
                    end
                    c.TickLabels=ctext;
                    title(c,ctitle);
                else
                    [~,fig]=contourf(app.UIAxes,app.ScopeData(1,1)+app.Nx/2:app.Nx:app.ScopeData(1,2)-app.Nx/2,app.ScopeData(1,1)+app.Nx/2:app.Nx:app.ScopeData(1,2)-app.Nx/2,data,app.DrawProperties.level);
                    set(fig,"ShowText",app.DrawProperties.showtext,"Linewidth",app.DrawProperties.linewidth,"LineColor",app.DrawProperties.linecolor,"LineStyle",app.DrawProperties.linestyle);
                end
            end
        end

        function GravityBCE(app,d)

            for iz=1:app.Nz
                %第iz层的密度值
                f=d(:,:,iz);
                if isempty(find(f~=0, 1))
                    %如该层密度值全部为0，则直接跳过
                else
                    f_ext=zeros(2*app.Nx,2*app.Ny);
                    f_ext(1:app.Nx,1:app.Ny)=f;
                    [g,gxx,gxy,gxz,gyy,gyz,gzz]=GravityKernelOfiz(app,iz);
                    if app.GraList(1)
                        t_ext_g=zeros(2*app.Nx,2*app.Ny);
                        t_ext_g(1:app.Nx,1:app.Ny)=g;
                        t_ext_g(app.Nx+2:2*app.Nx,:)=flipud(t_ext_g(2:app.Nx,:));
                        t_ext_g(:,app.Ny+2:2*app.Ny)=fliplr(t_ext_g(:,2:app.Ny));
                        app.CurrentGAns=app.CurrentGAns+FourierTran(app,f_ext,t_ext_g);
                    end
                    if app.GraList(2)
                        t_ext_gxx=zeros(2*app.Nx,2*app.Ny);
                        t_ext_gxx(1:app.Nx,1:app.Ny)=gxx;
                        t_ext_gxx(app.Nx+2:2*app.Nx,:)=flipud(t_ext_gxx(2:app.Nx,:));
                        t_ext_gxx(:,app.Ny+2:2*app.Ny)=fliplr(t_ext_gxx(:,2:app.Ny));
                        app.CurrentGxxAns=app.CurrentGxxAns+FourierTran(app,f_ext,t_ext_gxx);
                    end
                    if app.GraList(3)
                        t_ext_gxy=zeros(2*app.Nx,2*app.Ny);
                        t_ext_gxy(1:app.Nx,1:app.Ny)=gxy;
                        t_ext_gxy(app.Nx+2:2*app.Nx,:)=-flipud(t_ext_gxy(2:app.Nx,:));
                        t_ext_gxy(:,app.Ny+2:2*app.Ny)=-fliplr(t_ext_gxy(:,2:app.Ny));
                        app.CurrentGxyAns=app.CurrentGxyAns+FourierTran(app,f_ext,t_ext_gxy);
                    end
                    if app.GraList(4)
                        t_ext_gxz=zeros(2*app.Nx,2*app.Ny);
                        t_ext_gxz(1:app.Nx,1:app.Ny)=gxz;
                        t_ext_gxz(app.Nx+2:2*app.Nx,:)=-flipud(t_ext_gxz(2:app.Nx,:));
                        t_ext_gxz(:,app.Ny+2:2*app.Ny)=fliplr(t_ext_gxz(:,2:app.Ny));
                        app.CurrentGxzAns=app.CurrentGxzAns+FourierTran(app,f_ext,t_ext_gxz);
                    end
                    if app.GraList(5)
                        t_ext_gyy=zeros(2*app.Nx,2*app.Ny);
                        t_ext_gyy(1:app.Nx,1:app.Ny)=gyy;
                        t_ext_gyy(app.Nx+2:2*app.Nx,:)=flipud(t_ext_gyy(2:app.Nx,:));
                        t_ext_gyy(:,app.Ny+2:2*app.Ny)=fliplr(t_ext_gyy(:,2:app.Ny));
                        app.CurrentGyyAns=app.CurrentGyyAns+FourierTran(app,f_ext,t_ext_gyy);
                    end
                    if app.GraList(6)
                        t_ext_gyz=zeros(2*app.Nx,2*app.Ny);
                        t_ext_gyz(1:app.Nx,1:app.Ny)=gyz;
                        t_ext_gyz(app.Nx+2:2*app.Nx,:)=flipud(t_ext_gyz(2:app.Nx,:));
                        t_ext_gyz(:,app.Ny+2:2*app.Ny)=-fliplr(t_ext_gyz(:,2:app.Ny));
                        app.CurrentGyzAns=app.CurrentGyzAns+FourierTran(app,f_ext,t_ext_gyz);
                    end
                    if app.GraList(7)
                        t_ext_gzz=zeros(2*app.Nx,2*app.Ny);
                        t_ext_gzz(1:app.Nx,1:app.Ny)=gzz;
                        t_ext_gzz(app.Nx+2:2*app.Nx,:)=flipud(t_ext_gzz(2:app.Nx,:));
                        t_ext_gzz(:,app.Ny+2:2*app.Ny)=fliplr(t_ext_gzz(:,2:app.Ny));
                        app.CurrentGzzAns=app.CurrentGzzAns+FourierTran(app,f_ext,t_ext_gzz);
                    end
                end
            end
            app.CurrentGzzAns=SwapXandY(app,app.CurrentGzzAns);
            app.CurrentGxzAns=SwapXandY(app,app.CurrentGxzAns);
            app.CurrentGyzAns=SwapXandY(app,app.CurrentGyzAns);
            app.CurrentGxxAns=SwapXandY(app,app.CurrentGxxAns);
            app.CurrentGyyAns=SwapXandY(app,app.CurrentGyyAns);
            app.CurrentGxyAns=SwapXandY(app,app.CurrentGxyAns);
            app.CurrentGAns=SwapXandY(app,app.CurrentGAns);
            if app.GaussianNoise~=0
                app.CurrentGAns=AddGuassianNoise(app,app.CurrentGAns,app.GaussianNoise/100);
                app.CurrentGxxAns=AddGuassianNoise(app,app.CurrentGxxAns,app.GaussianNoise/100);
                app.CurrentGyyAns=AddGuassianNoise(app,app.CurrentGyyAns,app.GaussianNoise/100);
                app.CurrentGzzAns=AddGuassianNoise(app,app.CurrentGzzAns,app.GaussianNoise/100);
                app.CurrentGxzAns=AddGuassianNoise(app,app.CurrentGxzAns,app.GaussianNoise/100);
                app.CurrentGyzAns=AddGuassianNoise(app,app.CurrentGyzAns,app.GaussianNoise/100);
                app.CurrentGxyAns=AddGuassianNoise(app,app.CurrentGxyAns,app.GaussianNoise/100);
            end

        end
        function h=FourierTran(app,f,g)
            ff=fft2(f);
            gf=fft2(g);
            h_ext=ff.*gf;
            hr=ifft2(h_ext);
            h=hr(1:app.Nx,1:app.Ny);
        end
        function ht = SwapXandY(app,h)
            ht=zeros(app.Ny,app.Ny);
            for ix= 1:app.Ny
                for iy=1:app.Nx
                    ht(ix,iy)=h(iy,ix);
                end
            end
        end
        function [bx,by,bz,bxx,bxy,bxz,byy,byz,bzz]=NonVerMagneticKernelOfz(app,iz,k1,k2,k3)
            bx=0;
            by=0;
            bz=0;
            bxx=0;
            byy=0;
            bzz=0;
            bxy=0;
            byz=0;
            bxz=0;
            LogRPlusX=0;
            LogRPlusY=0;
            LogRPlusZ=0;
            YDRMRPlusX=0;
            YDPMRPlusZ=0;
            XDPMRPlusY=0;
            XDPMRPlusZ=0;
            ZDPMRPlusX=0;
            ZDPMRPlusY=0;
            z=zeros(1,2);
            if app.Unit==1
                z(1,1)=(app.Height-(iz-1)*app.Dz)*1000;
                z(1,2)=(app.Height-(iz)*app.Dz)*1000;
            elseif app.Unit==0
                z(1,1)=(app.Height-(iz-1)*app.Dz);
                z(1,2)=(app.Height-(iz)*app.Dz);
            elseif app.Unit==2
                z(1,1)=(app.Height-(iz-1)*app.Dz)*100;
                z(1,2)=(app.Height-(iz)*app.Dz)*100;
            end
            for i=1:2
                for j=1:2
                    for k=1:2
                        uijk=(-1)^i*(-1)^j*(-1)^k;
                        Rijk2=app.MagVectorX2(:,:,i)+app.MagVectorY2(:,:,j)+z(:,k).^2;
                        Rijk=sqrt(Rijk2);

                        %如果需要计算二阶导数，OneDR需要计算
                        if sum(app.MagList(8:13))
                            OneDR=1./Rijk;
                        end

                        %同上,一阶导数
                        if app.MagList(5)||app.MagList(6)
                            LogRPlusZ=log(Rijk+z(:,k));
                        end

                        if app.MagList(5)||app.MagList(7)
                            LogRPlusY=log(Rijk+app.MagVectorY(:,:,j));
                        end

                        if app.MagList(6)||app.MagList(7)
                            LogRPlusX=log(Rijk+app.MagVectorX(:,:,i));
                        end

                        %二阶
                        if app.MagList(8)||app.MagList(11)
                            XDPMRPlusZ=app.MagVectorX(:,:,i)./(Rijk.*(Rijk+z(:,k)));
                        end
                        if app.MagList(8)||app.MagList(12)
                            XDPMRPlusY=app.MagVectorX(:,:,i)./(Rijk.*(Rijk+app.MagVectorY(:,:,j)));
                        end

                        if app.MagList(9)||app.MagList(13)
                            YDRMRPlusX=app.MagVectorY(:,:,j)./(Rijk.*(Rijk+app.MagVectorX(:,:,i)));
                        end

                        if app.MagList(9)||app.MagList(13)
                            YDRMRPlusZ=app.MagVectorY(:,:,j)./(Rijk.*(Rijk+z(:,k)));
                        end

                        if app.MagList(10)||app.MagList(12)
                            ZDPMRPlusY=z(:,k)./(Rijk.*(Rijk+app.MagVectorY(:,:,j)));
                        end

                        if app.MagList(10)||app.MagList(13)
                            ZDPMRPlusX=z(:,k)./(Rijk.*(Rijk+app.MagVectorX(:,:,i)));
                        end

                        %磁张量
                        %Bx
                        if app.MagList(5)==1
                            bx=bx+uijk*(k1*atan2(app.MagVectorY(:,:,j).*z(:,k),app.MagVectorX(:,:,i).*Rijk)-k2*LogRPlusZ-k3*LogRPlusY);
                        end
                        %By
                        if app.MagList(6)==1
                            by=by+uijk*(-k1*LogRPlusZ+k2*atan2(app.MagVectorX(:,:,i).*z(:,k),app.MagVectorY(:,:,j).*Rijk)-k3*LogRPlusX);
                        end
                        %Bz
                        if app.MagList(7)==1
                            bz=bz+uijk*(-k1*LogRPlusY-k2*LogRPlusX+k3*atan2(app.MagVectorX(:,:,i).*app.MagVectorY(:,:,j),z(:,k).*Rijk));
                        end
                        %Bxx
                        if app.MagList(8)==1
                            arg=(app.MagVectorY(:,:,j).*z(:,k).*(Rijk2+app.MagVectorX2(:,:,i)))./((app.MagVectorX2(:,:,i)+z(:,k).^2).*(app.MagVectorX2(:,:,i)+app.MagVectorY2(:,:,j)).*Rijk);
                            bxx=bxx+uijk*(-k1*arg-k2*XDPMRPlusZ-k3*XDPMRPlusY);
                        end
                        %Byy
                        if app.MagList(9)==1
                            arg=(app.MagVectorX(:,:,i).*z(:,k).*(Rijk2+app.MagVectorY2(:,:,j)))./((app.MagVectorY2(:,:,j)+z(:,k).^2).*(app.MagVectorX2(:,:,i)+app.MagVectorY2(:,:,j)).*Rijk);
                            byy=byy+uijk*(-k1*YDPMRPlusZ-k2*arg-k3*YDRMRPlusX);
                        end
                        %Bzz
                        if app.MagList(10)==1
                            arg=(app.MagVectorY(:,:,j).*app.MagVectorX(:,:,i).*(Rijk2+z(:,k).^2))./((app.MagVectorX2(:,:,i)+z(:,k).^2).*(app.MagVectorY2(:,:,j)+z(:,k).^2).*Rijk);
                            bzz=bzz+uijk*(-k1*ZDPMRPlusY-k2*ZDPMRPlusX-k3*arg);
                        end
                        %Bxy
                        if app.MagList(11)==1
                            bxy=bxy+uijk*(-k1*XDPMRPlusZ-k2*YDRMRPlusZ-k3*OneDR);
                        end
                        %Bxz
                        if app.MagList(12)==1
                            bxz=bxz+uijk*(-k1*XDPMRPlusY-k2*OneDR-k3*ZDPMRPlusY);
                        end
                        %Byz
                        if app.MagList(13)==1
                            byz=byz+uijk*(-k1*OneDR-k2*YDRMRPlusX-k3*ZDPMRPlusX);
                        end
                    end
                end
            end


        end

        function [g,gxx,gxy,gxz,gyy,gyz,gzz]=GravityKernelOfiz(app,iz)
            % 用于计算第iz层的核矩阵
            g=0;
            gxx=0;
            gxy=0;
            gxz=0;
            gyy=0;
            gyz=0;
            gzz=0;
            %可复用元素
            arg_logYplusR=0;
            arg_logXplusR=0;
            arg_atanZRdivisionXY=0;

            z=zeros(1,2);
            if app.Unit==1
                z(1,1)=(app.Height-(iz-1)*app.Dz)*1000;
                z(1,2)=(app.Height-(iz)*app.Dz)*1000;
            elseif app.Unit==0
                z(1,1)=(app.Height-(iz-1)*app.Dz);
                z(1,2)=(app.Height-(iz)*app.Dz);
            elseif app.Unit==2
                z(1,1)=(app.Height-(iz-1)*app.Dz)*100;
                z(1,2)=(app.Height-(iz)*app.Dz)*100;
            end

            for i=1:2
                for j=1:2
                    for k=1:2
                        %符号系数
                        uijk=(-1)^i*(-1)^j*(-1)^k;
                        Rijk2=app.GraVectorX2(:,:,i)+app.GraVectorY2(:,:,j)+z(:,k).^2;
                        Rijk=sqrt(Rijk2);
                        % 重力异常
                        if app.GraList(1)==1
                            arg_logXplusR=log(app.GraVectorX(:,:,i)+Rijk);
                            arg_logYplusR=log(app.GraVectorY(:,:,j)+Rijk);
                            arg_atanZRdivisionXY=atan2(z(:,k).*Rijk,app.GraVectorX(:,:,i).*app.GraVectorY(:,:,j));
                            g=g-uijk*(app.GraVectorX(:,:,i).*arg_logYplusR+app.GraVectorY(:,:,j).*arg_logXplusR+z(:,k).*arg_atanZRdivisionXY);
                        end

                        %重力梯度
                        %Gxx
                        if app.GraList(2)==1
                            gxx=gxx-uijk*(atan2(app.GraVectorX(:,:,i).*Rijk,app.GraVectorY(:,:,j).*z(:,k)));
                        end
                        %Gxy
                        if app.GraList(3)==1
                            gxy=gxy+uijk*log(z(:,k)+Rijk);
                        end
                        %Gxz
                        if app.GraList(4)==1
                            if size(arg_logYplusR,1)==1
                                gxz=gxz-uijk*log(app.GraVectorY(:,:,j)+Rijk);
                            else
                                gxz=gxz-uijk*arg_logYplusR;
                            end
                        end
                        %Gyy
                        if app.GraList(5)==1
                            gyy=gyy+uijk*atan2(app.GraVectorX(:,:,i).*z(:,k),app.GraVectorY(:,:,j).*Rijk);
                        end
                        %Gyz
                        if app.GraList(6)==1
                            if size(arg_logXplusR,1)==1
                                gyz=gyz-uijk*log(app.GraVectorX(:,:,j)+Rijk);
                            else
                                gyz=gyz-uijk*arg_logXplusR;
                            end
                        end
                        %Gzz
                        if app.GraList(7)==1
                            if size(arg_atanZRdivisionXY,1)==1
                                gzz=gzz-uijk*atan2(z(:,k).*Rijk,app.GraVectorX(:,:,i).*app.GraVectorY(:,:,j));
                            else
                                gzz=gzz-uijk*arg_atanZRdivisionXY;
                            end
                        end
                    end
                end
            end
            para1=6.67e-3;
            para2=66.7;
            g=g*para1;
            gxz=gxz*para2;
            gyz=para2*gyz;
            gzz=para2*gzz;
            gxx=para2*gxx;
            gxy=para2*gxy;
            gyy=para2*gyy;
        end

    end


    methods (Access = public)
        function GetGraFWDPara(app,List,height,GaussianNoise,flag)
            %接收正演参数
            %flag==1 成功设置正演参数

            if flag==1
                app.operation=1;
                app.Height=height;
                app.GaussianNoise=GaussianNoise;
                app.GraList=List;
                %进入重力正演
                GravityForward(app)
            else
                app.operation=1;
            end
        end
        function GetMagFWDPara(app,List,height,GaussianNoise,dec,inc,flag)

            if flag==1
                app.operation=1;
                app.Height=height;
                app.GaussianNoise=GaussianNoise;
                app.MagList=List;
                app.MagDec=dec;
                app.MagInc=inc;
                %进入重力正演
                MagneticForward(app)
            else
                app.operation=1;
            end
        end
        function GetDrawSetting(app,DrawProperties,type)

            if type==1
                app.operation=1;
                app.DrawProperties=DrawProperties;
                clear DrawProperties
            else
                app.operation=1;
            end
        end

        function GetDisplay(app,flag)
            if flag==1
                app.displayIrregular=1;
                allposition=zeros(app.Nx,app.Ny,app.Nz);
                for i=1:app.IrregularNum
                    if app.IrregularModel(i).State==2
                        allposition=allposition+app.IrregularModel(i).Position;
                    end
                end
                %依旧展示不规则模型
                for i=1:app.IrregularNum
                    Plot_Irregular(app.UIAxes,app.IrregularModel(i).Position,allposition,app.Nx,app.Ny,app.Nz,[app.ScopeData,app.Dx,app.Dy,app.Dz],app.IrregularModel(i).Color);
                end

            else
                app.displayIrregular=0;
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingapp, Scope, Dx, Dy, Dz, Nx, Ny, Nz, Unit, irregularNum, irregularModel, cylinderNum, cylinderModel, cubeNum, cubeModel, sphereNum, sphereModel)

            MoveAppCenter(app.ModelForwardingUIFigure);
            app.ScopeData=Scope;
            app.Nx=Nx;
            app.Ny=Ny;
            app.Nz=Nz;
            app.Dz=Dz;
            app.Dx=Dx;
            app.Dy=Dy;
            Unit
            app.Unit=Unit;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.SphereModel.Text='';
            app.SphereModel.Position=[0,0,0];
            app.SphereModel.Radius=0;
            app.SphereModel.ResidualDensity=0;
            app.SphereModel.Color='';
            app.SphereModel.Magnetization=0;
            app.SphereModel.Inclination=0;
            app.SphereModel.Declination=0;
            app.SphereModel.Tag=0;
            app.SphereModel.State=0;
            app.SphereModel.Number=0;
            app.SphereModel.DenDivision=0;
            app.SphereModel.MagDivision=0;

            app.CubeModel.Text='';
            app.CubeModel.Position=zeros(8,3);
            app.CubeModel.ResidualDensity=0;
            app.CubeModel.Color='';
            app.CubeModel.Magnetization=0;
            app.CubeModel.Inclination=0;
            app.CubeModel.Declination=0;
            app.CubeModel.Tag=1;
            app.CubeModel.State=0;
            app.CubeModel.Number=0;
            app.CubeModel.DenDivision=0;
            app.CubeModel.MagDivision=0;

            app.CylinderModel.Radius=0;
            app.CylinderModel.Position=[0 0];
            app.CylinderModel.Trend='';
            app.CylinderModel.Extension=[0 0];
            app.CylinderModel.ResidualDensity=0;
            app.CylinderModel.Color='';
            app.CylinderModel.Magnetization=0;
            app.CylinderModel.Inclination=0;
            app.CylinderModel.Declination=0;
            app.CylinderModel.Tag=2;
            app.CylinderModel.State=0;
            app.CylinderModel.Number=0;
            app.CylinderModel.DenDivision=0;
            app.CylinderModel.MagDivision=0;
            app.CylinderModel.Text='';

            app.IrregularModel.Position=[0 0 0];
            app.IrregularModel.Text='';
            app.IrregularModel.ResidualDensity=0;
            app.IrregularModel.Color='';
            app.IrregularModel.Magnetization=0;
            app.IrregularModel.Inclination=0;
            app.IrregularModel.Declination=0;
            app.IrregularModel.Tag=3;
            app.IrregularModel.State=0;
            app.IrregularModel.Number=0;
            app.IrregularModel.DenDivision=0;
            app.IrregularModel.MagDivision=0;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            xlim(app.UIAxes,app.ScopeData(1,1:2));
            ylim(app.UIAxes,app.ScopeData(1,3:4));
            zlim(app.UIAxes,app.ScopeData(1,5:6));
            xt=app.ScopeData(1,1):10:app.ScopeData(1,2);
            yt=app.ScopeData(1,3):10:app.ScopeData(1,4);
            zt=app.ScopeData(1,5):10:app.ScopeData(1,6);
            xticks(app.UIAxes,xt);
            yticks(app.UIAxes,yt);
            zticks(app.UIAxes,zt);
            %设置坐标轴
            if Unit==0
                xlabel(app.UIAxes,'X/m  (North)')
                ylabel(app.UIAxes,'Y/m  (East)')
                zlabel(app.UIAxes,'Depth/m')
            elseif Unit==1
                xlabel(app.UIAxes,'X/km  (North)')
                ylabel(app.UIAxes,'Y/km  (East)')
                zlabel(app.UIAxes,'Depth/km')
            elseif Unit==2
                xlabel(app.UIAxes,'X/hm  (North)')
                ylabel(app.UIAxes,'Y/hm  (East)')
                zlabel(app.UIAxes,'Depth/hm')
            end
            Box_Axis(app.UIAxes,app.UIAxes.XLim,app.UIAxes.YLim,app.UIAxes.ZLim);
            set(app.UIAxes,'ZDir','reverse')
            view(app.UIAxes,3)
            grid(app.UIAxes,"on")
            hold(app.UIAxes,"on");
            for i=1:sphereNum
                if sphereModel(i).State==2
                    app.SphereNum=app.SphereNum+1;
                    sphereModel(i).Number=app.SphereNum;
                    app.SphereModel(app.SphereNum)=sphereModel(i);
                    [xs,ys,zs]=Plot_Sphere(sphereModel(i).Position(1),sphereModel(i).Position(2),sphereModel(i).Position(3),sphereModel(i).Radius);
                    surf(app.UIAxes,xs,ys,zs,"facecolor",app.SphereModel(i).Color)

                    %在模型列表中新建模型节点
                    uitreenode(app.SphereNode,"Text",sphereModel(i).Text,"NodeData",app.SphereNum,"Tag",'Sphere',"ContextMenu",app.ContextMenu);
                end
            end
            for i=1:cubeNum
                if cubeModel(i).State==2
                    app.CubeNum=app.CubeNum+1;
                    cubeModel(i).Number=app.CubeNum;
                    app.CubeModel(app.CubeNum)=cubeModel(i);
                    fixed=[cubeModel(i).Position(1,:);cubeModel(i).Position(2,:);cubeModel(i).Position(3,:);cubeModel(i).Position(4,:);cubeModel(i).Position(5,:);cubeModel(i).Position(6,:);cubeModel(i).Position(7,:);cubeModel(i).Position(8,:)];
                    facet=[1 2 4 3;1 3 7 5;1 2 6 5;2 4 8 6;3 4 8 7;5 6 8 7;];
                    patch(app.UIAxes,'Faces',facet,"Vertices",fixed,'facecolor',cubeModel(i).Color);
                    uitreenode(app.CubeNode,"Text",cubeModel(i).Text,"NodeData",app.CubeNum,"Tag",'Cube',"ContextMenu",app.ContextMenu);
                end
            end
            for i=1:cylinderNum
                if cylinderModel(i).State==2
                    app.CylinderNum=app.CylinderNum+1;
                    cylinderModel(i).Number=app.CylinderNum;
                    app.CylinderModel(app.CylinderNum)=cylinderModel(i);
                    switch cylinderModel(i).Trend
                        case 'X'
                            [cx,cy,cz]=Plot_Cylinder(cylinderModel(i).Extension(2)-cylinderModel(i).Extension(1),[cylinderModel(i).Extension(1),cylinderModel(i).Position(1),cylinderModel(i).Position(2)],cylinderModel(i).Radius,0);
                        case 'Y'
                            [cx,cy,cz]=Plot_Cylinder(cylinderModel(i).Extension(2)-cylinderModel(i).Extension(1),[cylinderModel(i).Position(1),cylinderModel(i).Extension(1),cylinderModel(i).Position(2)],cylinderModel(i).Radius,1);
                        case 'Z'
                            [cx,cy,cz]=Plot_Cylinder(cylinderModel(i).Extension(2)-cylinderModel(i).Extension(1),[cylinderModel(i).Position(1),cylinderModel(i).Position(2),cylinderModel(i).Extension(1)],cylinderModel(i).Radius,2);
                    end
                    surf(app.UIAxes,cx,cy,cz,'FaceColor',cylinderModel(i).Color);
                    fill3(app.UIAxes,cx(1,:),cy(1,:),cz(1,:),cylinderModel(i).Color);
                    fill3(app.UIAxes,cx(2,:),cy(2,:),cz(2,:),cylinderModel(i).Color);
                    uitreenode(app.CylinderNode,"Text",cylinderModel(i).Text,"NodeData",app.CylinderNum,"Tag",'Cylinder',"ContextMenu",app.ContextMenu);
                end
            end
            %询问是否需要展示不规则模型
            if irregularNum>0
                app.Dialogapp=displayIrre(app);
            end
            %不规则模型列表
            for i=1:irregularNum
                if irregularModel(i).State==2
                    app.IrregularNum=app.IrregularNum+1;
                    irregularModel(i).Number=app.IrregularNum;
                    app.IrregularModel(app.IrregularNum)=irregularModel(i);

                    uitreenode(app.IrregularNode,"Text",irregularModel(i).Text,"NodeData",app.IrregularNum,"Tag",'Irregular',"ContextMenu",app.ContextMenu);
                end
            end


            expand(app.Tree);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %用于计算重力正演和垂直磁化
            for i=1:Nx
                app.GraVectorX(i,:,1)=zeros(Ny,1)+i*Dx-Dx/2;
            end
            for i=1:Ny
                app.GraVectorY(:,i,1)=zeros(1,Nx)+i*Dy-Dy/2;
            end
            app.GraVectorX(:,:,2)=app.GraVectorX(:,:,1)-Dx;
            app.GraVectorY(:,:,2)=app.GraVectorY(:,:,1)-Dy;

            %用于计算斜磁化
            for i=1:2*Nx-1
                app.MagVectorX(i,:,1)=zeros(2*Ny-1,1)+(i+1-Nx)*Dx-Dx/2;
            end
            for i=1:2*Ny-1
                app.MagVectorY(:,i,1)=zeros(2*Nx-1,1)+(i+1-Nx)*Dy-Dy/2;
            end
            app.MagVectorX(:,:,2)=app.MagVectorX(:,:,1)-Dx;
            app.MagVectorY(:,:,2)=app.MagVectorY(:,:,1)-Dy;

            %单位换算成m
            if Unit==1
                app.GraVectorX=app.GraVectorX*1000;
                app.GraVectorY=app.GraVectorY*1000;
                app.MagVectorX=app.MagVectorX*1000;
                app.MagVectorY=app.MagVectorY*1000;
            elseif Unit==0
                app.GraVectorX=app.GraVectorX;
                app.GraVectorY=app.GraVectorY;
                app.MagVectorX=app.MagVectorX;
                app.MagVectorY=app.MagVectorY;
            elseif Unit==2
                app.GraVectorX=app.GraVectorX*100;
                app.GraVectorY=app.GraVectorY*100;
                app.MagVectorX=app.MagVectorX*100;
                app.MagVectorY=app.MagVectorY*100;
            end

            %储存平方
            app.GraVectorX2=app.GraVectorX.^2;
            app.GraVectorY2=app.GraVectorY.^2;
            app.MagVectorX2=app.MagVectorX.^2;
            app.MagVectorY2=app.MagVectorY.^2;

            %绘图设置
            app.DrawProperties.linecolor='black';
            app.DrawProperties.linestyle='-';
            app.DrawProperties.colormap='jet';
            app.DrawProperties.showtext="off";
            app.DrawProperties.level=12;
            app.DrawProperties.linewidth=0.5;
            app.DrawProperties.colorbar="on";
            %地磁倾角、偏角
            app.MagDec=0; %偏角
            app.MagInc=90;%倾角
        end

        % Button pushed function: DataButton
        function DataButtonPushed(app, event)

            x=zeros(app.Nx*app.Ny,1);
            y=zeros(app.Nx*app.Ny,1);
            count=0;
            for ix=1:app.Nx
                for iy=1:app.Ny
                    count=count+1;
                    x(count,1)=(ix-0.5)*app.Dx;
                    y(count,1)=(iy-0.5)*app.Dy;
                end
            end
            data=[x+app.ScopeData(1,1),y+app.ScopeData(1,3)];
            size(data)
            dataname=["x","y"];
            if any(any(app.CurrentGAns))
                data=[data,MatrixToVector(app,app.CurrentGAns);];
                dataname=[dataname,"g"];
            end
            if any(any(app.CurrentGxxAns))
                data=[data,MatrixToVector(app,app.CurrentGxxAns)];
                dataname=[dataname,'gxx'];
            end
            if any(any(app.CurrentGyyAns))

                data=[data,MatrixToVector(app,app.CurrentGyyAns)];
                dataname=[dataname,'gyy'];
            end
            if any(any(app.CurrentGzzAns))
                data=[data,MatrixToVector(app,app.CurrentGzzAns)];
                dataname=[dataname,'gzz'];
            end
            if any(any(app.CurrentGxyAns))

                data=[data,MatrixToVector(app,app.CurrentGxyAns)];
                dataname=[dataname,'gxy'];
            end
            if any(any(app.CurrentGxzAns))
                data=[data,MatrixToVector(app,app.CurrentGxzAns)];
                dataname=[dataname,'gxz'];
            end
            if any(any(app.CurrentGyzAns))
                data=[data,MatrixToVector(app,app.CurrentGyzAns)];
                dataname=[dataname,'gyz'];
            end
            if any(any(app.CurrentTAns))

                data=[data,MatrixToVector(app,app.CurrentTAns)];
                dataname=[dataname,'ΔT'];
            end
            if any(any(app.CurrentTxAns))

                data=[data,MatrixToVector(app,app.CurrentTxAns)];
                dataname=[dataname,'ΔTx'];
            end
            if any(any(app.CurrentTyAns))

                data=[data,MatrixToVector(app,app.CurrentTyAns)];
                dataname=[dataname,'ΔTy'];
            end
            if any(any(app.CurrentTzAns))

                data=[data,MatrixToVector(app,app.CurrentTzAns)];
                dataname=[dataname,'ΔTz'];
            end
            if any(any(app.CurrentHaxAns))

                data=[data,MatrixToVector(app,app.CurrentHaxAns)];
                dataname=[dataname,'hax'];
            end
            if any(any(app.CurrentHayAns))
                data=[data,MatrixToVector(app,app.CurrentHayAns)];
                dataname=[dataname,'hay'];
            end
            if any(any(app.CurrentZaAns))
                data=[data,MatrixToVector(app,app.CurrentZaAns)];
                dataname=[dataname,'za'];
            end
            if any(any(app.CurrentBxxAns))
                data=[data,MatrixToVector(app,app.CurrentBxxAns)];
                dataname=[dataname,'bxx'];
            end
            if any(any(app.CurrentByyAns))
                data=[data,MatrixToVector(app,app.CurrentByyAns)];
                dataname=[dataname,'byy'];
            end

            if any(any(app.CurrentBzzAns))
                data=[data,MatrixToVector(app,app.CurrentBzzAns)];
                dataname=[dataname,'bzz'];
            end

            if any(any(app.CurrentBxyAns))
                data=[data,MatrixToVector(app,app.CurrentBxyAns)];
                dataname=[dataname,'bxy'];
            end

            if any(any(app.CurrentBxxAns))
                data=[data,MatrixToVector(app,app.CurrentBxzAns)];
                dataname=[dataname,'bxz'];
            end

            if any(any(app.CurrentBxxAns))
                data=[data,MatrixToVector(app,app.CurrentByzAns)];
                dataname=[dataname,'byz'];
            end
            ForwardData(dataname,data);
        end

        % Button pushed function: GravityForwardButton
        function GravityForwardButtonPushed(app, event)
            %首先获取需要正演的模型
            if app.operation==1
                app.operation=0;
                %设置正演参数
                app.Dialogapp=GraFor(app,app.Height,app.GaussianNoise,app.GraList);
            end
        end

        % Button pushed function: MagneticForwardButton
        function MagneticForwardButtonPushed(app, event)
            %首先获取需要正演的模型
            if app.operation==1
                app.operation=0;
                app.Dialogapp=MagFor(app,app.MagList,app.MagDec,app.MagInc,app.Height,app.GaussianNoise);
            end
        end

        % Value changed function: GraFwdDropDown
        function GraFwdDropDownValueChanged(app, event)
            value = app.GraFwdDropDown.Value;
            switch value
                case '∆g'
                    DrawCheckAnomaly(app,app.CurrentGAns,'mGal');
                case 'Vzz'
                    DrawCheckAnomaly(app,app.CurrentGzzAns,'E');
                case 'Vxz'
                    DrawCheckAnomaly(app,app.CurrentGxzAns,'E');
                case 'Vyz'
                    DrawCheckAnomaly(app,app.CurrentGyzAns,'E');
                case 'Vyy'
                    DrawCheckAnomaly(app,app.CurrentGyyAns,'E');
                case 'Vxx'
                    DrawCheckAnomaly(app,app.CurrentGxxAns,'E');
                case 'Vxy'
                    DrawCheckAnomaly(app,app.CurrentGxyAns,'mGal');

            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)

            cla(app.UIAxes2);
            switch app.DataTypeDropDown.Value
                case '∆g'
                    data=app.CurrentGAns;

                    ytext='mGal';
                case 'Vzz'
                    data=app.CurrentGzzAns;
                    ytext='E';
                case 'Vxz'
                    data=app.CurrentGxzAns;
                    ytext='E';
                case 'Vyz'
                    data=app.CurrentGyzAns;
                    ytext='E';
                case 'Vyy'
                    data=app.CurrentGyyAns;
                    ytext='E';
                case 'Vxx'
                    data=app.CurrentGxxAns;
                    ytext='E';
                case 'Vxy'
                    data=app.CurrentGxyAns;
                    ytext='E';
                case '∆T'
                    data=app.CurrentTAns;
                    ytext='nT';
                case 'Hay'
                    data=app.CurrentHayAns;
                    ytext='nT';
                case 'Hax'
                    data=app.CurrentHaxAns;
                    ytext='nT';
                case 'Za'
                    data=app.CurrentZaAns;
                    ytext='nT';
                case 'Tx'
                    data=app.CurrentTxAns;
                    ytext='nT/m';
                case 'Ty'
                    data=app.CurrentTyAns;
                    ytext='nT/m';
                case 'Tz'
                    data=app.CurrentTzAns;
                    ytext='nT/m';
                case 'Bxx'
                    data=app.CurrentBxxAns;
                    ytext='nT/m';
                case 'Byy'
                    data=app.CurrentByyAns;
                    ytext='nT/m';
                case 'Bzz'
                    data=app.CurrentBzzAns;
                    ytext='nT/m';
                case 'Bxy'
                    data=app.CurrentBxyAns;
                    ytext='nT/m';
                case 'Bxz'
                    data=app.CurrentBxzAns;
                    ytext='nT/m';
                case 'Byz'
                    data=app.CurrentByzAns;
                    ytext='nT/m';
            end
            any(any(data))
            if any(any(data))

                switch app.ProfileDropDown.Value
                    case 'X profile'
                        Num=str2num(app.LayerEditField.Value);

                        if min(Num)>app.ScopeData(1,1)&&max(Num)<app.ScopeData(1,2)
                            num=(Num-app.ScopeData(1,1))/app.Dx;
                            pdata=data(:,num);
                            xlabel(app.UIAxes2,'X');
                            xgrid=(app.ScopeData(1,4)-app.ScopeData(1,3))/5;
                            xticks(app.UIAxes2,app.ScopeData(1,3):xgrid:app.ScopeData(1,4));
                            xlim(app.UIAxes2,app.ScopeData(1,3:4))
                            for i=1:size(pdata,2)
                                Leg{i}=['X=',num2str(Num(1,i))];
                                plot(app.UIAxes2,pdata(:,i)','LineWidth',1);
                                hold(app.UIAxes2,"on");
                            end
                        else
                            text="The queried section is out of range.";
                            app.Dialogapp=Error(app,text);
                        end
                    case 'Y Profile'
                        Num=str2num(app.LayerEditField.Value);
                        if min(Num)>app.ScopeData(1,3)&&max(Num)<app.ScopeData(1,4)
                            num=(Num-app.ScopeData(1,3))/app.Dy;
                            pdata=data(num,:);
                            xgrid=(app.ScopeData(1,2)-app.ScopeData(1,1))/5;
                            xticks(app.UIAxes2,app.ScopeData(1,1):xgrid:app.ScopeData(1,2));
                            xlim(app.UIAxes2,app.ScopeData(1,1:2))
                            for i=1:size(pdata,1)
                                Leg{i}=['Y=',num2str(Num(1,i))];
                                plot(app.UIAxes2, pdata(i,:),'LineWidth',1);
                                hold(app.UIAxes2,"on");
                            end
                            xlabel(app.UIAxes2,'Y');
                        else
                            text="The queried section is out of range.";
                            app.Dialogapp=Error(app,text);
                        end
                end
                ylabel(app.UIAxes2,ytext);
                legend(app.UIAxes2,Leg);
            else
                text="You have not calculated the selected items.";
                app.Dialogapp=Error(app,text);
            end
        end

        % Value changed function: MagFwdDropDown
        function MagFwdDropDownValueChanged(app, event)
            value = app.MagFwdDropDown.Value;
            switch value
                case '∆T'
                    DrawCheckAnomaly(app,app.CurrentTAns,'nT')
                case 'Hax'
                    DrawCheckAnomaly(app,app.CurrentHaxAns,'nT')
                case 'Hay'
                    DrawCheckAnomaly(app,app.CurrentHayAns,'nT')
                case 'Za'
                    DrawCheckAnomaly(app,app.CurrentZaAns,'nT')
                case 'Tx'
                    DrawCheckAnomaly(app,app.CurrentTxAns,'nT/m')
                case 'Ty'
                    DrawCheckAnomaly(app,app.CurrentTyAns,'nT/m')
                case 'Tz'
                    DrawCheckAnomaly(app,app.CurrentTzAns,'nT/m')
                case 'Bxx'
                    DrawCheckAnomaly(app,app.CurrentBxxAns,'nT/m')
                case 'Bxy'
                    DrawCheckAnomaly(app,app.CurrentBxyAns,'nT/m')
                case 'Byy'
                    DrawCheckAnomaly(app,app.CurrentByyAns,'nT/m')
                case 'Bxz'
                    DrawCheckAnomaly(app,app.CurrentBxzAns,'nT/m')
                case 'Byz'
                    DrawCheckAnomaly(app,app.CurrentByzAns,'nT/m')
                case 'Bzz'
                    DrawCheckAnomaly(app,app.CurrentBzzAns,'nT/m')
            end
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            value = app.ViewDropDown.Value;
            switch value
                case "X Profile"
                    view(app.UIAxes,[0 0]);
                case "Y Profile"
                    view(app.UIAxes,[90 0]);
                case "Z Profile"
                    view(app.UIAxes,2);
                case "Space"
                    view(app.UIAxes,3);
            end

        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)

            if app.operation==1
                app.operation=0;
                app.Dialogapp=DrewSet(app,app.DrawProperties);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModelForwardingUIFigure = uifigure('Visible', 'off');
            app.ModelForwardingUIFigure.Name = 'Model Forwarding';
            app.ModelForwardingUIFigure.Icon = 'ModelForward.png';
            app.UIAxes = uiaxes(app.ModelForwardingUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.ZLim = [0 100];
            app.UIAxes2 = uiaxes(app.ModelForwardingUIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ZLim = [0 100];
            app.UIAxes2.XAxisLocation = 'origin';
            app.UIAxes2.YAxisLocation = 'origin';
            app.Panel = uipanel(app.ModelForwardingUIFigure);
            app.GravityForwardButton = uibutton(app.Panel, 'push');
            app.GravityForwardButton.ButtonPushedFcn = createCallbackFcn(app, @GravityForwardButtonPushed, true);
            app.GravityForwardButton.Text = 'Gravity Forward';
            app.MagneticForwardButton = uibutton(app.Panel, 'push');
            app.MagneticForwardButton.ButtonPushedFcn = createCallbackFcn(app, @MagneticForwardButtonPushed, true);
            app.MagneticForwardButton.Text = 'Magnetic Forward';
            app.DataButton = uibutton(app.Panel, 'push');
            app.DataButton.ButtonPushedFcn = createCallbackFcn(app, @DataButtonPushed, true);
            app.DataButton.Text = 'Data';
            app.GraFwdDropDown = uidropdown(app.Panel);
            app.GraFwdDropDown.Items = {'∆g', 'Vxx', 'Vxy', 'Vxz', 'Vyy', 'Vyz', 'Vzz'};
            app.GraFwdDropDown.ValueChangedFcn = createCallbackFcn(app, @GraFwdDropDownValueChanged, true);
            app.GraFwdDropDown.Value = '∆g';
            app.MagFwdDropDown = uidropdown(app.Panel);
            app.MagFwdDropDown.Items = {'∆T', 'Hax', 'Hay', 'Za', 'Tx', 'Ty', 'Tz', 'Bxx', 'Byy', 'Bzz', 'Bxy', 'Bxz', 'Byz'};
            app.MagFwdDropDown.ValueChangedFcn = createCallbackFcn(app, @MagFwdDropDownValueChanged, true);
            app.MagFwdDropDown.Value = '∆T';
            app.ViewDropDown = uidropdown(app.Panel);
            app.ViewDropDown.Items = {'Space', 'X Profile', 'Y Profile', 'Z Profile'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'X Profile';
            app.ViewButton = uibutton(app.Panel, 'push');
            app.ViewButton.Icon = 'View.png';
            app.ViewButton.Text = 'View';
            app.DrawButton = uibutton(app.Panel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.Panel_2 = uipanel(app.ModelForwardingUIFigure);
            app.DataTypeDropDown = uidropdown(app.Panel_2);
            app.DataTypeDropDown.Items = {'∆g', 'Vzz', 'Vxz', 'Vyz', '∆T', 'Hay', 'Hax', 'Za', 'Tx', 'Ty', 'Tz'};
            app.DataTypeDropDown.Value = '∆g';
            app.ProfileDropDown = uidropdown(app.Panel_2);
            app.ProfileDropDown.Items = {'X profile', 'Y Profile'};
            app.ProfileDropDown.Value = 'X profile';
            app.LayerEditField = uieditfield(app.Panel_2, 'text');
            app.OKButton = uibutton(app.Panel_2, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.NumLabel = uilabel(app.Panel_2);
            app.NumLabel.Text = 'Num';
            app.Tree = uitree(app.ModelForwardingUIFigure, 'checkbox');
            app.SphereNode = uitreenode(app.Tree);
            app.SphereNode.Tag = 'parent';
            app.SphereNode.Text = 'Sphere';
            app.CubeNode = uitreenode(app.Tree);
            app.CubeNode.Tag = 'parent';
            app.CubeNode.Text = 'Cube';
            app.CylinderNode = uitreenode(app.Tree);
            app.CylinderNode.Tag = 'parent';
            app.CylinderNode.Text = 'Cylinder';
            app.IrregularNode = uitreenode(app.Tree);
            app.IrregularNode.Tag = 'parent';
            app.IrregularNode.Text = 'Irregular';
            app.ContextMenu = uicontextmenu(app.ModelForwardingUIFigure);
            app.propertiesMenu = uimenu(app.ContextMenu);
            app.propertiesMenu.Text = 'properties';
            % Show the figure after all components are created
            app.ModelForwardingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Modelforwarding(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModelForwardingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModelForwardingUIFigure)
        end
    end
end