classdef ModelingRange < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SourceSettingUIFigure  matlab.ui.Figure
        UnitLabel              matlab.ui.control.Label
        YEditField             matlab.ui.control.NumericEditField
        YLabel                 matlab.ui.control.Label
        ZEditField             matlab.ui.control.NumericEditField
        ZLabel                 matlab.ui.control.Label
        XEditField             matlab.ui.control.NumericEditField
        XLabel                 matlab.ui.control.Label
        MeshSizeLabel          matlab.ui.control.Label
        ZRangeEditField        matlab.ui.control.EditField
        ZRangeEditFieldLabel   matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        YRangeEditField        matlab.ui.control.EditField
        YRangeEditFieldLabel   matlab.ui.control.Label
        XRangeEditField        matlab.ui.control.EditField
        XRangeEditFieldLabel   matlab.ui.control.Label
        CancelButton           matlab.ui.control.Button
        OkButton               matlab.ui.control.Button
    end


    properties (Access = private)
        Callingapp; 
        dialogapp; 
        sdata;
        odata; 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingapp, data)
            %将窗体居中
            MoveAppCenter(app.SourceSettingUIFigure);
            %将初始建模范围展示给用户
            app.XRangeEditField.Value=['[',num2str(data(1,1:2)),']'];
            app.YRangeEditField.Value=['[',num2str(data(1,3:4)),']'];
            app.ZRangeEditField.Value=['[',num2str(data(1,5:6)),']'];
            app.XEditField.Value=data(1,7);
            app.YEditField.Value=data(1,8);
            app.ZEditField.Value=data(1,9);
            if data(1,10)==1
                app.DropDown.Value='km';
            elseif data(1,10)==0
                app.DropDown.Value='m';
            elseif data(1,10)==2
                app.DropDown.Value='hm';
            end
            app.Callingapp=callingapp;
            %app.sdata用于储存初始模型范围，app.odata作为副本
            app.sdata=data;
            app.odata=data;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            %将建模范围返回给建模模块
            switch app.DropDown.Value
                case 'm'
                    app.sdata(1,10)=0;
                case 'km'
                    app.sdata(1,10)=1;
                case 'hm'
                    app.sdata(1,10)=2;
            end
            updata_Scope(app.Callingapp,app.sdata);
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %用户点击Cancel按钮取消操作，将原始建模范围参数返还给建模模块
            updata_Scope(app.Callingapp,app.odata);
            delete(app);
        end

        % Close request function: SourceSettingUIFigure
        function SourceSettingUIFigureCloseRequest(app, event)
            %直接关闭窗体，作用同点击Cancel按钮
            try
            updata_Scope(app.Callingapp,app.odata);
            delete(app)
            catch
                delete(app)
            end
        end

        % Value changed function: XRangeEditField
        function XRangeEditFieldValueChanged(app, event)
            try
                %提取X轴范围
                value = str2num(app.XRangeEditField.Value);
                if value(1)>=value(2)
                    %范围输入格式应为[xmin,xmax]
                    text="Wrong Input! You should input the range of X axes like [xmin xmax].";
                    app.dialogapp=Error(app,text);
                else
                    %将模型范围存入数组app.sdata
                    app.sdata(1,1:2)=value;
                end
            catch
                text="Wrong Input! You should input the range of X axes like [xmin xmax].";
                app.dialogapp=Error(app,text);
            end


        end

        % Value changed function: YRangeEditField
        function YRangeEditFieldValueChanged(app, event)
            %作用同XRangeEditFieldValueChanged(app, event)类似，对Y轴范围进行操作
            try
                value = str2num(app.YRangeEditField.Value);
                if value(1)>=value(2)
                    text="Wrong Input! You should input the range of Y axes like [ymin ymax].";
                    app.dialogapp=Error(app,text);
                else
                    app.sdata(1,3:4)=value;
                end
            catch
                text="Wrong Input! You should input the range of Y axes like [ymin ymax].";
                app.dialogapp=Error(app,text);
            end


        end

        % Value changed function: ZRangeEditField
        function ZRangeEditFieldValueChanged(app, event)
            %作用同XRangeEditFieldValueChanged(app, event)类似，对Z轴范围进行操作
            %这里Z轴向下为负值
            try
                value = str2num(app.ZRangeEditField.Value);
                if value(1)>=value(2)
                    text="Wrong Input! You should input the range of Z axes like [zmin zmax].";
                    app.dialogapp=Error(app,text);
                else
                    app.sdata(1,5:6)=value;
                end
            catch
                text="Wrong Input! You should input the range of Z axes like [zmin zmax].";
                app.dialogapp=Error(app,text);
            end


        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)

            try
                %获取x方向剖分长度
                value = app.XEditField.Value;
                %剖分长度需要能被X轴长度整除
                if rem(app.sdata(1,2)-app.sdata(1,1),value)

                    text=strcat("The Length of X axes cannot be divided by ",num2str(value));
                    text=strcat(text,"!");
                    app.dialogapp=Error(app,text);
                else
                    %储存x方向剖分长度
                    app.sdata(1,7)=value;
                end
            catch
                text="Wrong Input!";
                app.dialogapp=Error(app,text);
            end

        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            %作用同XEditFieldValueChanged(app, event)，对y方向剖分长度进行操作
            try
                value = app.YEditField.Value;
                if rem(app.sdata(1,3)-app.sdata(1,4),value)
                    text=strcat("The Length of Y axes cannot be divided by ",num2str(value));
                    text=strcat(text,"!");
                    app.dialogapp=Error(app,text);

                else
                    app.sdata(1,8)=value;
                end
            catch
                text="Wrong Input!";
                app.dialogapp=Error(app,text);
            end

        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            %作用同XEditFieldValueChanged(app, event)，对z方向剖分长度进行操作
            try
                value = app.ZEditField.Value;
                if rem(app.sdata(1,2)-app.sdata(1,1),value)
                    text=strcat("The Length of Z axes cannot be divided by ",num2str(value));
                    text=strcat(text,"!");
                    app.dialogapp=Error(app,text);
                else
                    app.sdata(1,9)=value;
                end
            catch
                text="Wrong Input!";
                app.dialogapp=Error(app,text);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SourceSettingUIFigure = uifigure('Visible', 'off');
            app.SourceSettingUIFigure.Name = 'Source Setting';
            app.SourceSettingUIFigure.Icon = 'Setting.png';
            app.SourceSettingUIFigure.CloseRequestFcn = createCallbackFcn(app, @SourceSettingUIFigureCloseRequest, true);
            app.OkButton = uibutton(app.SourceSettingUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.SourceSettingUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.XRangeEditFieldLabel = uilabel(app.SourceSettingUIFigure);
            app.XRangeEditFieldLabel.Text = 'X-Range';
            app.XRangeEditField = uieditfield(app.SourceSettingUIFigure, 'text');
            app.XRangeEditField.ValueChangedFcn = createCallbackFcn(app, @XRangeEditFieldValueChanged, true);
            app.XRangeEditField.Value = '[0    N]';
            app.YRangeEditFieldLabel = uilabel(app.SourceSettingUIFigure);
            app.YRangeEditFieldLabel.Text = 'Y-Range';
            app.YRangeEditField = uieditfield(app.SourceSettingUIFigure, 'text');
            app.YRangeEditField.ValueChangedFcn = createCallbackFcn(app, @YRangeEditFieldValueChanged, true);
            app.YRangeEditField.Value = '[0    M]';
            app.DropDown = uidropdown(app.SourceSettingUIFigure);
            app.DropDown.Items = {'m', 'hm', 'km'};
            app.DropDown.Value = 'm';
            app.ZRangeEditFieldLabel = uilabel(app.SourceSettingUIFigure);
            app.ZRangeEditFieldLabel.Text = 'Z-Range';
            app.ZRangeEditField = uieditfield(app.SourceSettingUIFigure, 'text');
            app.ZRangeEditField.ValueChangedFcn = createCallbackFcn(app, @ZRangeEditFieldValueChanged, true);
            app.ZRangeEditField.Value = '[0    L]';
            app.MeshSizeLabel = uilabel(app.SourceSettingUIFigure);
            app.MeshSizeLabel.Text = 'Mesh Size';
            app.XLabel = uilabel(app.SourceSettingUIFigure);
            app.XLabel.Text = '∆X';
            app.XEditField = uieditfield(app.SourceSettingUIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Value = 1;
            app.ZLabel = uilabel(app.SourceSettingUIFigure);
            app.ZLabel.Text = '∆Z';
            app.ZEditField = uieditfield(app.SourceSettingUIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Value = 1;
            app.YLabel = uilabel(app.SourceSettingUIFigure);
            app.YLabel.Text = '∆Y';
            app.YEditField = uieditfield(app.SourceSettingUIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Value = 1;
            app.UnitLabel = uilabel(app.SourceSettingUIFigure);
            app.UnitLabel.Text = 'Unit';
            % Show the figure after all components are created
            app.SourceSettingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelingRange(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SourceSettingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SourceSettingUIFigure)
        end
    end
end