classdef SphereParameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddSphereUIFigure              matlab.ui.Figure
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        ColorDropDown                  matlab.ui.control.DropDown
        ColorLabel                     matlab.ui.control.Label
        MagneticInclinationEditField   matlab.ui.control.NumericEditField
        MagneticInclinationEditFieldLabel  matlab.ui.control.Label
        MagneticDeclinationEditField   matlab.ui.control.NumericEditField
        MagneticDeclinationEditFieldLabel  matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        OkButton                       matlab.ui.control.Button
        AmLabel                        matlab.ui.control.Label
        gcmLabel                       matlab.ui.control.Label
        CenterPositionEditField        matlab.ui.control.EditField
        CenterPositionEditFieldLabel   matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        ResidualDensityEditField       matlab.ui.control.NumericEditField
        ResidualDensityEditFieldLabel  matlab.ui.control.Label
        MagnetizationEditField         matlab.ui.control.NumericEditField
        MagnetizationEditFieldLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        Callingapp;
        odata
        dialogapp;
        scope;
        SphereModel;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingapp, data, scope)
            %将窗体屏幕居中
            MoveAppCenter(app.AddSphereUIFigure);
            %保存建模范围信息
            app.scope=scope;
            app.Callingapp=callingapp;
            %保留初始信息副本，存入app.odata数组中
            app.odata=data;
            app.SphereModel=data;
            %将模型原始信息展现给用户
            app.CenterPositionEditField.Value=['[',num2str(data.Position),']'];
            app.RadiusEditField.Value=data.Radius;
            app.ResidualDensityEditField.Value=data.ResidualDensity;
            app.MagnetizationEditField.Value=data.Magnetization;
            app.MagneticDeclinationEditField.Value=data.Declination;
            app.MagneticInclinationEditField.Value=data.Inclination;
            app.NameEditField.Value=data.Text;
            app.ColorDropDown.Value=data.Color;
            app.SphereModel.State=data.State+1;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            %用户点击ok按钮，将模型信息返回给建模模块

            if app.ResidualDensityEditField.Value||app.MagnetizationEditField.Value
                %至少输入剩余密度或者磁化强度中的一项，否则所建模型没有意义
                %录入物理参数
                app.SphereModel.Inclination=app.MagneticInclinationEditField.Value;
                app.SphereModel.Declination=app.MagneticDeclinationEditField.Value;
                app.SphereModel.Magnetization=app.MagnetizationEditField.Value;
                app.SphereModel.ResidualDensity=app.ResidualDensityEditField.Value;
                app.SphereModel.Color=app.ColorDropDown.Value;
                app.SphereModel.Text=app.NameEditField.Value;
                Nx=(app.scope(1,2)-app.scope(1,1))/app.scope(1,7);
                Ny=(app.scope(1,4)-app.scope(1,3))/app.scope(1,8);
                Nz=(app.scope(1,6)-app.scope(1,5))/app.scope(1,9);
                %将模型剖分
                if app.SphereModel.Magnetization~=0
                    app.SphereModel.MagDivision=SphereSubdivision(app.SphereModel.Position(1),app.SphereModel.Position(2),app.SphereModel.Position(3),app.SphereModel.Radius,app.scope(1,7),app.scope(1,8),app.scope(1,9),Nx,Ny,Nz,app.SphereModel.Magnetization);
                end
                if app.SphereModel.ResidualDensity~=0
                    app.SphereModel.DenDivision=SphereSubdivision(app.SphereModel.Position(1),app.SphereModel.Position(2),app.SphereModel.Position(3),app.SphereModel.Radius,app.scope(1,7),app.scope(1,8),app.scope(1,9),Nx,Ny,Nz,app.SphereModel.ResidualDensity);
                end
                %信息传输
                updata_ModelInf(app.Callingapp,app.SphereModel);
                delete(app);
            else
                text="You have not set the Residual Density or Magnetic Magnetization.";
                app.dialogapp=Error(app,text);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %用户取消操作时，将初始信息返还给建模模块
            updata_ModelInf(app.Callingapp,app.odata);
            delete(app);
        end

        % Close request function: AddSphereUIFigure
        function AddSphereUIFigureCloseRequest(app, event)
            %用户直接关闭窗口，作用同点击取消按钮
            try
                updata_ModelInf(app.Callingapp,app.odata);
                delete(app)
            catch
                delete(app)
            end
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            %读取用户输入的半径值
            value = app.RadiusEditField.Value;
            if value<=0
                %半径不能小于或者等于0
                text='The radius can not be equal to or less than 0';
                app.dialogapp=Error(app,text);
            elseif (value<app.scope(1,2)-app.SphereModel.Position(1))&&(value< app.SphereModel.Position(1)-app.scope(1,1))&&(value<app.scope(1,4)-app.SphereModel.Position(2))&&(value<app.SphereModel.Position(2)-app.scope(1,3))&&(value<app.scope(1,6)-app.SphereModel.Position(3))&&(value<app.SphereModel.Position(3)-app.scope(1,5))
                %将半径储存
                app.SphereModel.Radius=value;
            else
                %球体范围不能超出建模范围，判断方法：建模在X\Y\Z轴的范围与球心坐标的差值大于半径
                text='The size of the model out of the range';
                app.dialogapp=Error(app,text);
            end

        end

        % Value changed function: CenterPositionEditField
        function CenterPositionEditFieldValueChanged(app, event)
            %读取用户输入的球心坐标，str2num：将字符串串改写坐标向量
            value = str2num(app.CenterPositionEditField.Value);
            if value(1,1)>app.scope(1,1)&&value(1,1)<app.scope(1,2)&&value(1,2)<app.scope(1,4)&&value(1,3)<app.scope(1,6)&&value(1,2)>app.scope(1,3)&&value(1,3)>app.scope(1,5)
                %球心不能在建模范围外
                %将球心坐标储存
                app.SphereModel.Position=value;
            else
                text='The Center Position of the model out of the range';
                app.dialogapp=Error(app,text);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddSphereUIFigure = uifigure('Visible', 'off');
            app.AddSphereUIFigure.Name = 'Add Sphere';
            app.AddSphereUIFigure.Icon = 'sphere.png';
            app.AddSphereUIFigure.CloseRequestFcn = createCallbackFcn(app, @AddSphereUIFigureCloseRequest, true);
            app.MagnetizationEditFieldLabel = uilabel(app.AddSphereUIFigure);
            app.MagnetizationEditFieldLabel.Text = 'Magnetization';
            app.MagnetizationEditField = uieditfield(app.AddSphereUIFigure, 'numeric');
            app.ResidualDensityEditFieldLabel = uilabel(app.AddSphereUIFigure);
            app.ResidualDensityEditFieldLabel.Text = 'ResidualDensity';
            app.ResidualDensityEditField = uieditfield(app.AddSphereUIFigure, 'numeric');
            app.RadiusEditFieldLabel = uilabel(app.AddSphereUIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.AddSphereUIFigure, 'numeric');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.CenterPositionEditFieldLabel = uilabel(app.AddSphereUIFigure);
            app.CenterPositionEditFieldLabel.Text = 'Center Position';
            app.CenterPositionEditField = uieditfield(app.AddSphereUIFigure, 'text');
            app.CenterPositionEditField.ValueChangedFcn = createCallbackFcn(app, @CenterPositionEditFieldValueChanged, true);
            app.CenterPositionEditField.Value = '[0 0 0]';
            app.gcmLabel = uilabel(app.AddSphereUIFigure);
            app.gcmLabel.Text = 'g/cm³';
            app.AmLabel = uilabel(app.AddSphereUIFigure);
            app.AmLabel.Text = 'A/m';
            app.OkButton = uibutton(app.AddSphereUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.AddSphereUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.MagneticDeclinationEditFieldLabel = uilabel(app.AddSphereUIFigure);
            app.MagneticDeclinationEditFieldLabel.Text = 'Magnetic Declination（°）';
            app.MagneticDeclinationEditField = uieditfield(app.AddSphereUIFigure, 'numeric');
            app.MagneticInclinationEditFieldLabel = uilabel(app.AddSphereUIFigure);
            app.MagneticInclinationEditFieldLabel.Text = 'Magnetic Inclination（°）';
            app.MagneticInclinationEditField = uieditfield(app.AddSphereUIFigure, 'numeric');
            app.ColorLabel = uilabel(app.AddSphereUIFigure);
            app.ColorLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.AddSphereUIFigure);
            app.ColorDropDown.Items = {'red', 'green', 'blue', 'yellow', 'magenta', 'white', 'cyan', 'black'};
            app.ColorDropDown.Value = 'red';
            app.NameEditFieldLabel = uilabel(app.AddSphereUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.AddSphereUIFigure, 'text');
            % Show the figure after all components are created
            app.AddSphereUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SphereParameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddSphereUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddSphereUIFigure)
        end
    end
end